classdef app_escala_auto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        pxuLabel               matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        path_carpeta_outLabel  matlab.ui.control.Label
        length_unitLabel       matlab.ui.control.Label
        PixelsperlengthLabel   matlab.ui.control.Label
        LengthunitLabel        matlab.ui.control.Label
        scale_valueLabel       matlab.ui.control.Label
        ScalevalueLabel        matlab.ui.control.Label
        ScalerulevalueButton   matlab.ui.control.Button
        distancia_pixelsLabel  matlab.ui.control.Label
        DoneButton             matlab.ui.control.Button
        Image3                 matlab.ui.control.Image
        ExitButton             matlab.ui.control.Button
        Image2                 matlab.ui.control.Image
        ZoomreturnButton       matlab.ui.control.Button
        SelectbarButton        matlab.ui.control.Button
        ZoomButton             matlab.ui.control.Button
        Image                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imatge_input, carpeta_out_file)
            % StartupFcn

            % Variables
            % imatge_input - initial image.
            % carpeta_out_file - final folder.
            

            % Definition of the main image
            app.Image.ImageSource = imatge_input;
            % imshow(imatge_input, 'Parent', app.UIAxes);
            
            % Definition of the original image
            app.Image2.ImageSource = imatge_input;
            
            app.ZoomreturnButton.Visible = 'off';
            
            app.DoneButton.Visible = 'off';
            
            app.scale_valueLabel.Text = "";
            app.length_unitLabel.Text = "";
            app.distancia_pixelsLabel.Text = "";
            
            % Definition of the folder variable
            app.path_carpeta_outLabel.Text = carpeta_out_file;
            
            % Definition of the starting message
            app.Label.Text = "Make zoom to the scale bar";
            app.SelectbarButton.Visible = 'off';
            app.ScalerulevalueButton.Visible = 'off';


            % Center the GUI
            % ..............
            center_the_app_GUI(app)
            
            
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            % Magnifying glass button, where you zoom in on the image of a specific region.
            app_escala_auto_boto_zoom(app)
           
        end

        % Button pushed function: ZoomreturnButton
        function ZoomreturnButtonPushed(app, event)
            % Zoom return to last image
            app_escala_auto_boto_zoomreturn(app)            

        end

        % Button pushed function: SelectbarButton
        function SelectbarButtonPushed(app, event)
            % Select barline button
            app_escala_auto_boto_selectbar(app)            
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'escape'
                    % Tancar tot
                    [answer_msgebox] = quadre_decisio_modif("Do you really you want to exit?", "Exit box", "Yes", "No");
                    
                    if answer_msgebox
                        close all force
                    end

            end
                    
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Exit program
            close all force
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Botó Done
            app_escala_auto_boto_done(app)
            
        end

        % Button pushed function: ScalerulevalueButton
        function ScalerulevalueButtonPushed(app, event)
            % Entrada valor llargada escala
            app_escala_auto_boto_entervalue(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.ZoomButton = uibutton(app.UIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Icon = 'loupe.png';
            app.ZoomButton.Text = 'Zoom';
            app.SelectbarButton = uibutton(app.UIFigure, 'push');
            app.SelectbarButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbarButtonPushed, true);
            app.SelectbarButton.Icon = 'auto_sel.png';
            app.SelectbarButton.Text = {'Select'; 'bar'};
            app.ZoomreturnButton = uibutton(app.UIFigure, 'push');
            app.ZoomreturnButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomreturnButtonPushed, true);
            app.ZoomreturnButton.Icon = 'loupe_feltcha_bo.png';
            app.ZoomreturnButton.Text = {'Zoom'; 'return'};
            app.Image2 = uiimage(app.UIFigure);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'icon_out.png';
            app.ExitButton.Visible = 'off';
            app.ExitButton.Text = 'Exit';
            app.Image3 = uiimage(app.UIFigure);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.distancia_pixelsLabel = uilabel(app.UIFigure);
            app.distancia_pixelsLabel.Text = 'distancia_pixels';
            app.ScalerulevalueButton = uibutton(app.UIFigure, 'push');
            app.ScalerulevalueButton.ButtonPushedFcn = createCallbackFcn(app, @ScalerulevalueButtonPushed, true);
            app.ScalerulevalueButton.Icon = 'micrometer.png';
            app.ScalerulevalueButton.Text = {'Scale'; 'value'};
            app.ScalevalueLabel = uilabel(app.UIFigure);
            app.ScalevalueLabel.Visible = 'off';
            app.ScalevalueLabel.Text = 'Scale value: ';
            app.scale_valueLabel = uilabel(app.UIFigure);
            app.scale_valueLabel.Visible = 'off';
            app.scale_valueLabel.Text = 'scale_value';
            app.LengthunitLabel = uilabel(app.UIFigure);
            app.LengthunitLabel.Text = 'Length unit:';
            app.PixelsperlengthLabel = uilabel(app.UIFigure);
            app.PixelsperlengthLabel.Text = 'Pixels per length:';
            app.length_unitLabel = uilabel(app.UIFigure);
            app.length_unitLabel.Text = 'length_unit';
            app.path_carpeta_outLabel = uilabel(app.UIFigure);
            app.path_carpeta_outLabel.Text = 'path_carpeta_out';
            app.Label = uilabel(app.UIFigure);
            app.pxuLabel = uilabel(app.UIFigure);
            app.pxuLabel.Visible = 'off';
            app.pxuLabel.Text = 'px/u';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_escala_auto(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end