classdef app_image_processment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        InfoIcon_imadjust              matlab.ui.control.Button
        InfoIcon                       matlab.ui.control.Button
        returnButton                   matlab.ui.control.Button
        FiltersLabel                   matlab.ui.control.Label
        BinarizebyvalueButton          matlab.ui.control.Button
        BinarizeSlider                 matlab.ui.control.Slider
        flipButton                     matlab.ui.control.Button
        imadjust_selectButton          matlab.ui.control.Button
        Spinner_3                      matlab.ui.control.Spinner
        CloseButton                    matlab.ui.control.Button
        Spinner_2                      matlab.ui.control.Spinner
        OpenButton                     matlab.ui.control.Button
        Spinner                        matlab.ui.control.Spinner
        imfillButton                   matlab.ui.control.Button
        imclearborderButton            matlab.ui.control.Button
        AreaOpenButton                 matlab.ui.control.Button
        BinarizeButton                 matlab.ui.control.Button
        imadjustButton                 matlab.ui.control.Button
        Im2greyButton                  matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        ImageprocessingpanelLabel      matlab.ui.control.Label
        RequirmentAnalysisText         matlab.ui.control.Label
        AnalyseButton                  matlab.ui.control.Button
        Button_11                      matlab.ui.control.Button
        Button_12                      matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        ReturnLupa                     matlab.ui.control.Button
        ModifiedButton                 matlab.ui.control.StateButton
        MaskedButton                   matlab.ui.control.StateButton
        OriginalButton                 matlab.ui.control.StateButton
        EsqueletonitzarButton          matlab.ui.control.StateButton
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ZoomButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        ButtonVideoTutorial            matlab.ui.control.Button
        ProcessingsLabel               matlab.ui.control.Label
        GuardarmodificaciButton        matlab.ui.control.Button
        RenameButton_2                 matlab.ui.control.Button
        TutorialButton                 matlab.ui.control.StateButton
        ImportButton                   matlab.ui.control.Button
        DelAll                         matlab.ui.control.Button
        ImagepropertiesLabel           matlab.ui.control.Label
        ImageLabel                     matlab.ui.control.Label
        num_objectes_binarisLabel      matlab.ui.control.Label
        NobjectsLabel                  matlab.ui.control.Label
        carpeta_imatges_textLabel      matlab.ui.control.Label
        FolderLabel                    matlab.ui.control.Label
        RenameButton                   matlab.ui.control.Button
        NovamodificaciButton           matlab.ui.control.Button
        ModificacionstemporalsListBox  matlab.ui.control.ListBox
        TemporalLabel                  matlab.ui.control.Label
        ModificacionsguardadesListBox  matlab.ui.control.ListBox
        SavedmodificationsLabel        matlab.ui.control.Label
        ImatgeLabel                    matlab.ui.control.Label
        ImagenLabel                    matlab.ui.control.Label
        Button_14                      matlab.ui.control.Button
        ModificarButton                matlab.ui.control.Button
        contador_numimLabel            matlab.ui.control.Label
        Button_13                      matlab.ui.control.Button
        Modificacions_actuals          matlab.ui.control.TextArea
        ModificationsLabel             matlab.ui.control.Label
        Spinner_4                      matlab.ui.control.Spinner
        regionimopenButton             matlab.ui.control.Button
        edgeapproxcannyButton          matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    % Global publi variables (saved in the app). Note: public doesn't mean var are global.     
    properties (Access = public)

        % _StartupFcn variables (app's input variables)_
        theFiles_arxiu = []; % theFiles file (image data structured) from the initial variable.
        vapp_carpeta_output = []; % Results folder

        % _Local app variables_
        vapp_ruta_img_origin = []; % Original Ruta de la imatge original (cambiant)
        vapp_tancament_loop = []; % Variable per tancar app (aquesta és sempre en loop per poder re-dimensionar)

        % Note: this variables are called from functions as app.variable_name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, theFiles, carpeta_output)
            % Start of the app (startupFcn)
            
            % _VARIABLES_
            %   theFiles : Data structure of the images (name, folder, date, ...). Utility for obtaining the images.
            %
            %   carpeta_output : path to the results folder.
            %                     Saved in:
            %              e.g. (using MATLAB):  "C:\Users\jlliure\Desktop\Length determination v2_15\Results_out\20230124_1625_C_48"
            %              e.g. (compiled in Windows): 'C:\Users\Ramon TOSHIBA\Documents\WorMe_Length_Results Results_out\20230407_1813_control'


            % __Types of image processing__
            %
            % RGB to Grey
            % ----------
            % "Im2gray"
            % 
            % Grey Modification
            % ----------------
            % "imadjust"
            % 
            % Grey to Binary
            % --------------
            % "Imbinarize_adaptative_Foreground_dark"
            %  
            % Binary Modification
            % -------------------
            % "bwareaopen(value)"
            % "Imclearborder"
            % "imfill"
            % "open_disc(value)"
            % "close_disc(value)"


            % ____Saving in app the input variables from function____ 
            % Folder of the images
            carpeta_imatges_t = split(theFiles(1).folder, filesep);
            app.carpeta_imatges_textLabel.Text = carpeta_imatges_t{end};
            
            % Results folder
            app.vapp_carpeta_output = carpeta_output;

            % theFiles file:
            app.theFiles_arxiu = theFiles;
            % theFiles file with the data structure, coming from
            % the selection of the images themselves.


            % ___StartupFcn___
            % ................            
            app_proc_startupFcn(app)


            % Center the GUI
            % ..............
            center_the_app_GUI(app)

           
            % Re-scale the image
            % ---------------------
            app_proc_rescale_image(app)

            
            close force
            %  Don't put code form here:
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {541, 541, 541};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {541, 541};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {195, '1x', 254};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: Im2greyButton
        function Im2greyButtonPushed(app, event)
            % im2gray button
            app_proc_button_im2gray(app)
            
        end

        % Button pushed function: DelAll
        function DelAllPushed(app, event)
            % Delete all temporal modifications
            app_proc_button_delete_all_temp_modifs(app)

        end

        % Button pushed function: NovamodificaciButton
        function NovamodificaciButtonPushed(app, event)
            % Add a new modification 
            app_proc_button_add_modif(app)

        end

        % Value changed function: ModificacionstemporalsListBox
        function ModificacionstemporalsListBoxValueChanged(app, event)
            % Value changed of temporal modifications
            app_proc_valuechanged_modif_temp(app)

        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            % Delete temporal modification button
            app_proc_button_delete_modif_temp(app)
            
        end

        % Button pushed function: GuardarmodificaciButton
        function GuardarmodificaciButtonPushed(app, event)
            % Save the temporal modification
            app_proc_button_save_temp_modif(app)
            
        end

        % Value changed function: ModificacionsguardadesListBox
        function ModificacionsguardadesListBoxValueChanged(app, event)
            % Modificacions guardades
            app_proc_valuechanged_modif_saved(app)
                
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % When click in the main image
            app_proc_click_main_image(app)

        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            % Button to the right image
            app_proc_button_to_right_img(app)
            
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            % Button to the left image
            app_proc_button_to_left_img(app)

        end

        % Button pushed function: imadjustButton
        function imadjustButtonPushed(app, event)
            % imadjust modification button
            app_proc_button_imadjust(app)

        end

        % Button pushed function: BinarizeButton
        function BinarizeButtonPushed(app, event)
            % Binarize button "Imbinarize_adaptative_Foreground_dark"
            app_proc_button_binarize(app)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Shortkeys
            key = event.Key;
            
            switch key
                case 'escape'
                    % Close all
                    % close force
                    
                case 'return'
                    % Press the analyse button
                    app.AnalyseButtonPushed

            end
            
        end

        % Button pushed function: AreaOpenButton
        function AreaOpenButtonPushed(app, event)
            % BWareaopen button
            app_proc_button_bwareaopen(app)

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Button Open
            app_proc_button_open(app)

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % Button Close
            app_proc_button_close(app)
                        
        end

        % Button pushed function: imfillButton
        function imfillButtonPushed(app, event)
            % Button imfill
            app_proc_button_imfill(app)
            
        end

        % Button pushed function: imclearborderButton
        function imclearborderButtonPushed(app, event)
            % Button imclearborder
            app_proc_button_imclearborder(app)

        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Import modification button
            app_proc_button_import_modif(app)

        end

        % Button pushed function: ButtonVideoTutorial
        function ButtonVideoTutorialPushed(app, event)
            % Botó app video tutorial
            app_tutorial_alfarel()
    
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            % Modify processment button
            app_proc_button_modify_proc(app)
            
        end

        % Button pushed function: ReturnLupa
        function ReturnLupaPushed(app, event)
            % Button of return the loupe (return to the original img)
            app_proc_button_loupe_return(app)
            
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            % Delete temporal modifications
            app_proc_button_del_all_temp_modifs(app)
            
        end

        % Button pushed function: returnButton
        function returnButtonPushed(app, event)
            % Return of the modification button
            app_proc_button_return_modif(app)
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            % Deleted button

        end

        % Button pushed function: imadjust_selectButton
        function imadjust_selectButtonPushed(app, event)
            % Imadjust select (select by area) button pushed
            app_proc_button_imadjust_select(app)
            
        end

        % Button pushed function: flipButton
        function flipButtonPushed(app, event)
            % Flip button
            app_proc_button_flip(app)

        end

        % Button pushed function: edgeapproxcannyButton
        function edgeapproxcannyButtonPushed(app, event)
            % Edge approxcannin button (Note: currently not working)
            app_proc_button_edgecanny_aprox(app)
            
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            % Analyse button
            app_proc_button_analyse(app)
            
        end

        % Button pushed function: regionimopenButton
        function regionimopenButtonPushed(app, event)
            % Region imopen (Note: currently not working)
            app_proc_button_region_imopen(app)
                    
        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)
            % Rename button
            app_proc_button_rename(app)
            
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            % Botó Zoom
            app_proc_button_zoom(app)
            
        end

        % Button pushed function: BinarizebyvalueButton
        function BinarizebyvalueButtonPushed(app, event)
            % Binarize by value Button
            app_proc_button_binarise_by_value(app)
            
        end

        % Value changed function: EsqueletonitzarButton
        function EsqueletonitzarButtonValueChanged(app, event)
            % Skeletonize changing button
            app_proc_button_skeletonize_change(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close of the GUI App
            close force

        end

        % Value changed function: OriginalButton
        function OriginalButtonValueChanged(app, event)
            % Original image button
            app_proc_button_original_img(app)
            
        end

        % Value changed function: MaskedButton
        function MaskedButtonValueChanged(app, event)
            % Masked (binary) image button
            app_proc_button_masked_image(app)
            
        end

        % Value changed function: ModifiedButton
        function ModifiedButtonValueChanged(app, event)
            % Modified visualization button
            app_proc_button_modified_img(app)

        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            % Little image clicked
            app_proc_click_image_little(app)
            
        end

        % Value changed function: TutorialButton
        function TutorialButtonValueChanged(app, event)
            % App tutorial button
            app_proc_button_tutorial(app)
      
        end

        % Button pushed function: InfoIcon
        function InfoIconButtonPushed(app, event)
            % Info button of im2grey
            app_proc_button_tutorial_im2grey(app)

        end

        % Button pushed function: InfoIcon_imadjust
        function InfoIcon_imadjustButtonPushed(app, event)
            % Info button of imadjust
            app_proc_button_tutorial_imadjust(app)

        end

        % Button pushed function: RenameButton_2
        function RenameButton_2Pushed(app, event)
            % Rename button 2
            app.RenameButtonPushed;
            % Same as Rename button: it push the Rename button.

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Im2greyButton = uibutton(app.LeftPanel, 'push');
            app.Im2greyButton.ButtonPushedFcn = createCallbackFcn(app, @Im2greyButtonPushed, true);
            app.Im2greyButton.Tooltip = {'Convert RGB image to grayscale'};
            app.Im2greyButton.Text = 'Im2grey';
            app.imadjustButton = uibutton(app.LeftPanel, 'push');
            app.imadjustButton.ButtonPushedFcn = createCallbackFcn(app, @imadjustButtonPushed, true);
            app.imadjustButton.Tooltip = {'Adjust image intensity values or colormap'};
            app.imadjustButton.Text = 'imadjust';
            app.BinarizeButton = uibutton(app.LeftPanel, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Tooltip = {'Binarize grayscale image'};
            app.BinarizeButton.Text = 'Binarize';
            app.AreaOpenButton = uibutton(app.LeftPanel, 'push');
            app.AreaOpenButton.ButtonPushedFcn = createCallbackFcn(app, @AreaOpenButtonPushed, true);
            app.AreaOpenButton.Tooltip = {'Remove small objects from binary image'};
            app.AreaOpenButton.Text = 'AreaOpen';
            app.imclearborderButton = uibutton(app.LeftPanel, 'push');
            app.imclearborderButton.ButtonPushedFcn = createCallbackFcn(app, @imclearborderButtonPushed, true);
            app.imclearborderButton.Tooltip = {'Suppress light structures connected to image border'};
            app.imclearborderButton.Text = 'imclearborder';
            app.imfillButton = uibutton(app.LeftPanel, 'push');
            app.imfillButton.ButtonPushedFcn = createCallbackFcn(app, @imfillButtonPushed, true);
            app.imfillButton.Tooltip = {'Fill image regions and holes'};
            app.imfillButton.Text = 'imfill';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Step = 0.5;
            app.Spinner.Limits = [0.5 Inf];
            app.Spinner.Value = 0.5;
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Morphologically open image'};
            app.OpenButton.Text = 'Open';
            app.Spinner_2 = uispinner(app.LeftPanel);
            app.Spinner_2.Limits = [1 Inf];
            app.Spinner_2.Value = 1;
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Morphologically close image'};
            app.CloseButton.Text = 'Close';
            app.Spinner_3 = uispinner(app.LeftPanel);
            app.Spinner_3.Limits = [1 Inf];
            app.Spinner_3.Value = 1;
            app.imadjust_selectButton = uibutton(app.LeftPanel, 'push');
            app.imadjust_selectButton.ButtonPushedFcn = createCallbackFcn(app, @imadjust_selectButtonPushed, true);
            app.imadjust_selectButton.Tooltip = {'Adjust image intensity values by mean and sd selected region'};
            app.imadjust_selectButton.Text = 'imadjust_select';
            app.flipButton = uibutton(app.LeftPanel, 'push');
            app.flipButton.ButtonPushedFcn = createCallbackFcn(app, @flipButtonPushed, true);
            app.flipButton.Tooltip = {'Flip the logical values of the binary image'};
            app.flipButton.Text = 'flip ~';
            app.BinarizeSlider = uislider(app.LeftPanel);
            app.BinarizeSlider.Limits = [0 255];
            app.BinarizeSlider.Value = 255;
            app.BinarizebyvalueButton = uibutton(app.LeftPanel, 'push');
            app.BinarizebyvalueButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizebyvalueButtonPushed, true);
            app.BinarizebyvalueButton.Tooltip = {'Binarize the grayscale by a threshold value'};
            app.BinarizebyvalueButton.Text = {'Binarize'; 'by value'};
            app.FiltersLabel = uilabel(app.LeftPanel);
            app.FiltersLabel.Text = 'Filters';
            app.returnButton = uibutton(app.LeftPanel, 'push');
            app.returnButton.ButtonPushedFcn = createCallbackFcn(app, @returnButtonPushed, true);
            app.returnButton.Icon = 'return_yellow.png';
            app.returnButton.Text = 'undo filter';
            app.InfoIcon = uibutton(app.LeftPanel, 'push');
            app.InfoIcon.ButtonPushedFcn = createCallbackFcn(app, @InfoIconButtonPushed, true);
            app.InfoIcon.Icon = 'info_icon.png';
            app.InfoIcon.Visible = 'off';
            app.InfoIcon.Text = '';
            app.InfoIcon_imadjust = uibutton(app.LeftPanel, 'push');
            app.InfoIcon_imadjust.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_imadjustButtonPushed, true);
            app.InfoIcon_imadjust.Icon = 'info_icon.png';
            app.InfoIcon_imadjust.Visible = 'off';
            app.InfoIcon_imadjust.Text = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ZoomButton = uibutton(app.CenterPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Icon = 'loupe.png';
            app.ZoomButton.Tooltip = {'Zoom to the image'};
            app.ZoomButton.Text = '';
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image2 = uiimage(app.CenterPanel);
            app.Image2.ImageSource = 'reloj_arena.jpg';
            app.EsqueletonitzarButton = uibutton(app.CenterPanel, 'state');
            app.EsqueletonitzarButton.ValueChangedFcn = createCallbackFcn(app, @EsqueletonitzarButtonValueChanged, true);
            app.EsqueletonitzarButton.Tooltip = {'Draw the skeleton of the binary images'};
            app.EsqueletonitzarButton.Text = 'Skeletonize';
            app.OriginalButton = uibutton(app.CenterPanel, 'state');
            app.OriginalButton.ValueChangedFcn = createCallbackFcn(app, @OriginalButtonValueChanged, true);
            app.OriginalButton.Tooltip = {'Show the original images'};
            app.OriginalButton.Text = 'Original';
            app.MaskedButton = uibutton(app.CenterPanel, 'state');
            app.MaskedButton.ValueChangedFcn = createCallbackFcn(app, @MaskedButtonValueChanged, true);
            app.MaskedButton.Tooltip = {'Show the mask of the images'};
            app.MaskedButton.Text = 'Masked';
            app.ModifiedButton = uibutton(app.CenterPanel, 'state');
            app.ModifiedButton.ValueChangedFcn = createCallbackFcn(app, @ModifiedButtonValueChanged, true);
            app.ModifiedButton.Tooltip = {'Show the modifyied images'};
            app.ModifiedButton.Text = 'Modified';
            app.ModifiedButton.Value = true;
            app.ReturnLupa = uibutton(app.CenterPanel, 'push');
            app.ReturnLupa.ButtonPushedFcn = createCallbackFcn(app, @ReturnLupaPushed, true);
            app.ReturnLupa.Icon = 'loupe_feltcha_bo.png';
            app.ReturnLupa.Visible = 'off';
            app.ReturnLupa.Text = '';
            app.Image3 = uiimage(app.CenterPanel);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Button_12 = uibutton(app.CenterPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '<';
            app.Button_11 = uibutton(app.CenterPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '>';
            app.AnalyseButton = uibutton(app.CenterPanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Tooltip = {'Run the image analysis!'};
            app.AnalyseButton.Text = 'Analyse';
            app.RequirmentAnalysisText = uilabel(app.CenterPanel);
            app.RequirmentAnalysisText.Text = {'Image must be binarized'; 'to execute the analysis'};
            app.ImageprocessingpanelLabel = uilabel(app.CenterPanel);
            app.ImageprocessingpanelLabel.Text = 'Image processing panel';
            app.RightPanel = uipanel(app.GridLayout);
            app.ModificationsLabel = uilabel(app.RightPanel);
            app.ModificationsLabel.Text = 'Modifications';
            app.Modificacions_actuals = uitextarea(app.RightPanel);
            app.Modificacions_actuals.Editable = 'off';
            app.Modificacions_actuals.Enable = 'off';
            app.Button_13 = uibutton(app.RightPanel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Icon = 'delete_icon.png';
            app.Button_13.Text = '';
            app.contador_numimLabel = uilabel(app.RightPanel);
            app.contador_numimLabel.Text = 'contador_numim';
            app.ModificarButton = uibutton(app.RightPanel, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Tooltip = {'Modify the saved configuration'};
            app.ModificarButton.Text = 'Modify';
            app.Button_14 = uibutton(app.RightPanel, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Icon = 'delete_icon.png';
            app.Button_14.Text = '';
            app.ImagenLabel = uilabel(app.RightPanel);
            app.ImagenLabel.Text = 'Image nº:';
            app.ImatgeLabel = uilabel(app.RightPanel);
            app.ImatgeLabel.Text = 'Imatge';
            app.SavedmodificationsLabel = uilabel(app.RightPanel);
            app.SavedmodificationsLabel.Text = 'Saved';
            app.ModificacionsguardadesListBox = uilistbox(app.RightPanel);
            app.ModificacionsguardadesListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionsguardadesListBoxValueChanged, true);
            app.TemporalLabel = uilabel(app.RightPanel);
            app.TemporalLabel.Text = 'Temporal';
            app.ModificacionstemporalsListBox = uilistbox(app.RightPanel);
            app.ModificacionstemporalsListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionstemporalsListBoxValueChanged, true);
            app.NovamodificaciButton = uibutton(app.RightPanel, 'push');
            app.NovamodificaciButton.ButtonPushedFcn = createCallbackFcn(app, @NovamodificaciButtonPushed, true);
            app.NovamodificaciButton.Tooltip = {'Creates a new image processing configuration'};
            app.NovamodificaciButton.Text = {'New'; 'processment'};
            app.RenameButton = uibutton(app.RightPanel, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Tooltip = {'Rename the selected configuration'};
            app.RenameButton.Text = 'Rename';
            app.FolderLabel = uilabel(app.RightPanel);
            app.FolderLabel.Text = 'Folder:';
            app.carpeta_imatges_textLabel = uilabel(app.RightPanel);
            app.carpeta_imatges_textLabel.Text = 'carpeta_imatges_text';
            app.NobjectsLabel = uilabel(app.RightPanel);
            app.NobjectsLabel.Text = 'Nª objects:';
            app.num_objectes_binarisLabel = uilabel(app.RightPanel);
            app.num_objectes_binarisLabel.Text = 'num_objectes_binaris';
            app.ImageLabel = uilabel(app.RightPanel);
            app.ImageLabel.Text = 'Image:';
            app.ImagepropertiesLabel = uilabel(app.RightPanel);
            app.ImagepropertiesLabel.Text = 'Image properties';
            app.DelAll = uibutton(app.RightPanel, 'push');
            app.DelAll.ButtonPushedFcn = createCallbackFcn(app, @DelAllPushed, true);
            app.DelAll.Icon = 'delete_icon.png';
            app.DelAll.Text = 'All';
            app.ImportButton = uibutton(app.RightPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.TutorialButton = uibutton(app.RightPanel, 'state');
            app.TutorialButton.ValueChangedFcn = createCallbackFcn(app, @TutorialButtonValueChanged, true);
            app.TutorialButton.Text = 'Tutorial';
            app.RenameButton_2 = uibutton(app.RightPanel, 'push');
            app.RenameButton_2.ButtonPushedFcn = createCallbackFcn(app, @RenameButton_2Pushed, true);
            app.RenameButton_2.Tooltip = {'Rename the selected configuration'};
            app.RenameButton_2.Text = 'Rename';
            app.GuardarmodificaciButton = uibutton(app.RightPanel, 'push');
            app.GuardarmodificaciButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarmodificaciButtonPushed, true);
            app.GuardarmodificaciButton.Tooltip = {'Save the current configuration to the saved modifications'};
            app.GuardarmodificaciButton.Text = 'Save';
            app.ProcessingsLabel = uilabel(app.RightPanel);
            app.ProcessingsLabel.Text = 'Processings';
            app.ButtonVideoTutorial = uibutton(app.RightPanel, 'push');
            app.ButtonVideoTutorial.ButtonPushedFcn = createCallbackFcn(app, @ButtonVideoTutorialPushed, true);
            app.ButtonVideoTutorial.Text = 'Video Tutorial';
            app.edgeapproxcannyButton = uibutton(app.UIFigure, 'push');
            app.edgeapproxcannyButton.ButtonPushedFcn = createCallbackFcn(app, @edgeapproxcannyButtonPushed, true);
            app.edgeapproxcannyButton.Text = 'edge approxcanny';
            app.regionimopenButton = uibutton(app.UIFigure, 'push');
            app.regionimopenButton.ButtonPushedFcn = createCallbackFcn(app, @regionimopenButtonPushed, true);
            app.regionimopenButton.Text = 'region imopen';
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.Limits = [1 Inf];
            app.Spinner_4.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_image_processment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end