classdef app_iniciacio_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FixButton                       matlab.ui.control.Button
        DateTimeLabel                   matlab.ui.control.Label
        DateLabel                       matlab.ui.control.Label
        NewanalysisButton               matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        NotesLabel                      matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        AnalysisLabel                   matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        SelectimagesButton              matlab.ui.control.Button
        current_folderLabel             matlab.ui.control.Label
        FolderLabel                     matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        TotalresolutiontypesLabel       matlab.ui.control.Label
        resol_typesLabel                matlab.ui.control.Label
        file_typesLabel                 matlab.ui.control.Label
        TotalfiletypesLabel             matlab.ui.control.Label
        img_nameLabel                   matlab.ui.control.Label
        n_img_acutLabel                 matlab.ui.control.Label
        left_imageButton                matlab.ui.control.Button
        right_imageButton               matlab.ui.control.Button
        dir_internal_code_filesLabel    matlab.ui.control.Label
        extendButton                    matlab.ui.control.Button
        ExtendimagegraficationCheckBox  matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.NumericEditField
        SavedprocessmentsLabel          matlab.ui.control.Label
        ModificacionsListBox            matlab.ui.control.ListBox
        image_type_fileLabel            matlab.ui.control.Label
        ImageprocessmentconfigurationButton  matlab.ui.control.Button
        AnalyseButton                   matlab.ui.control.Button
        DeterminescaleButton            matlab.ui.control.Button
        valure_resolutionLabel          matlab.ui.control.Label
        ImageresolutionLabel            matlab.ui.control.Label
        ScaleLabel                      matlab.ui.control.Label
        n_value_imagesLabel             matlab.ui.control.Label
        ImagefiletypeLabel              matlab.ui.control.Label
        NofimagesLabel                  matlab.ui.control.Label
        SelectimagefolderButton         matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        %___Definició variables___
        
        % _Inicials_
        % Carpeta actual localització prorgama
        dir_current = [];


        % _Definides_
        dir_img_current = []; % Carpeta actual imatges

        img_main = []; % Imatge actual mostrada

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dir_current)
            % INICIACIÓ PROGRAMA (startupFcn)
            
            % Que hi hagi pocs comandaments. Que no sigui algo que de
            % primeres sigui un lio.
            
            % Que les imatges processades i sense processar es vagin
            % canviant a mesura que passa el temps.
            
            % Que es pugui anar canviant les imatges per a veure si el
            % processament és bo.


            % FER
            % Botó guardar, que crei copia de les dades a la carpeta,
            % que mostri si s'ha guardat o no les dades, i la data.


            % Arxius de resultats
            % theFiles.txt : Noms imatges - dir imatges (tipu taula)
            % notes_analysis.txt - notes
            % date_analysis.txt - data
            % rate_scale.txt - ratio
            % \Modif_files - conté el txt de la modificació


            % Guardem variables d'entrada
            app.dir_current = dir_current;
                    % ex: 'C:\Users\jllobet\Desktop\Length determination v2_14'


            app.dir_internal_code_filesLabel.Text = string(dir_current);
            % dir_current : directori principal prorgama. ex:  'C:\Users\jllobet\Desktop\Length determination v2_10'
            
            % txt_initial_parameters = strcat(carpeta_internal_code_files, "\", "initial_parameters.txt")


            % _Desactivem botóns_
            app.UIAxes.Visible = 'off';
            app.UIAxes_2.Visible = 'off';
            

            % Actualitzem llistat carpetes resultats:
            dir_results_analisis = strcat(dir_current, "\Results_out\Analisis_results");
            [dir_carpetes_tot_sorted, ~, ~, ~] = obtenir_nom_carpetes(dir_results_analisis);

            % Actualitzem listBox
            app.ListBox.Items = dir_carpetes_tot_sorted;
            app.ListBox.Value = app.ListBox.Items{end};
            scroll(app.ListBox, "bottom")


            % ____Mirem les modificacions____
            % Definim la Box de Modificacions temporals, a partir dels
            % documents que es creen:
            
            % Automatització Drop Roll Modificacions temporals
            %myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\";
            patro_tipus_image = ".txt";
            [llistat_string_im_temp] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);          
            
            app.ModificacionsListBox.Items = llistat_string_im_temp;
            app.ModificacionsListBox.Value = {};
            app.ModificacionsListBox.Enable = 'off'; % Desactivem fins que hi hagi imatge.


            % Centrem la GUI
            % ..............
            UIFigure_position = app.UIFigure.Position; %100   100   602   389
                                                      % inici final amplada  llargada
            screensize_position = get(0, 'ScreenSize'); %         1           1        1920        1080
            [UIFigure_position_new] = centered_GUI_screen(UIFigure_position, screensize_position);
            app.UIFigure.Position = UIFigure_position_new;


        end

        % Button pushed function: SelectimagefolderButton
        function SelectimagefolderButtonPushed(app, event)
            % Folder selection button
            

            % _Seleccionem la carpeta_
            carpeta_input = uigetdir();

            % _Lectura de la carpeta d'imatges_
            theFiles = lectura_imatges_carpeta_estr(carpeta_input);

            if length(theFiles) <1 
                msgbox("Folder selected have no images")
            else
    
                % _Definim la carpeta_
                app.dir_img_current = carpeta_input;
    
    
                % ___Definició propietats imatges___
                % _Definim la carpeta actual_
                nom_img_dir_temp = split(carpeta_input, "\");
                nom_img_dir = nom_img_dir_temp{end}; % Funciona
                app.current_folderLabel.Text = nom_img_dir;
    
    
                dir_results_analisis = strcat(app.dir_current, "\Results_out\Analisis_results");
                carpeta_guardar_dades = strcat(dir_results_analisis, "\", app.ListBox.Value); % Carpeta guardat dades
                arxiu_txt_dir_img = strcat(carpeta_guardar_dades, "\", "theFiles.txt"); % Dir arxiu .txt guardar imatges 

                write_txt_enblanc(arxiu_txt_dir_img) % Fem arxiu en blanc (eliminem si hi ha anterior)


                % ___Guardar ruta imatges____
                appini_guardar_theFiles_txt(theFiles, arxiu_txt_dir_img);
    

                % ___Lectura i mostrar dades imatges___
                
                % Lectura arxiu theFiles (Operarem amb aquest, amb la taula)
                % ___Lectura imatges___
                file_txt_imgs = strcat(dir_results_analisis, "\", app.ListBox.Value, "\", "theFiles.txt");
                
                % Si existeix l'arxiu
                [S_textscan_table] = llegir_dades(file_txt_imgs, "%s", "$", ["Image_file"]);
    
                % Mostrar propietats imatge
                appini_mostrar_imatges_props(app, S_textscan_table);


                % Activem/Desactivem objectes
                app.ModificacionsListBox.Enable = 'on';
            end

        end

        % Button pushed function: DeterminescaleButton
        function DeterminescaleButtonPushed(app, event)
            % Determinar l'escala de la imatge
            
            % Nota: ficar en les imatges de la app en forma de giff.
            
            % Desseleccionar tots els botóns de la app.
            h_determin = findobj(app.UIFigure,'Type','uibutton');
            set(h_determin,'Enable','off')
            
            listbox_determin = findobj(app.UIFigure,'Type','uilistbox');
            set(listbox_determin,'Enable','off')
            
            waitfor(app_seleccio_escales)
            
            % Re-seleccionar tots els botóns de la app.
            h_determin = findobj(app.UIFigure,'Type','uibutton');
            set(h_determin,'Enable','on')

            listbox_determin = findobj(app.UIFigure,'Type','uilistbox');
            set(listbox_determin,'Enable','on')


        end

        % Button pushed function: extendButton
        function extendButtonPushed(app, event)
            % Per a extendre el GUI per a que es vegin més configuracións.
            
            posicio_actual = app.UIFigure.Position  % Exemple : 666   346   588   389 [posX, posY, amplada, llargada]
            
            % Posicio sense part inferior: 100,100,588,394
            % Posicio amb part inferior:  100,100,588,525
            
            
            %posicio_actual(2) = posicio_actual(2) + 150;            
            posicio_actual(4) = posicio_actual(4) + 150;

            posicio_actual
            
            
             app.UIFigure.Resize = 'off';            
             app.UIFigure.AutoResizeChildren = 'off';
            
            app.UIFigure.Position = posicio_actual;
            
%             app.UIFigure.Resize = 'on';            
%             app.UIFigure.AutoResizeChildren = 'on';
            
        end

        % Button pushed function: right_imageButton
        function right_imageButtonPushed(app, event)
            % Botó dreta
            % Nota: copiar tot de la dreta
            
            % Llegim contador numero imatges
            n_img_actual = str2double(app.n_img_acutLabel.Text);
            n_img_actual = n_img_actual +1;
            
            % Sumem 1 al contador numero imatges
            app.n_img_acutLabel.Text = string(n_img_actual);
            
            
            % Llegim contingut carpeta imatges
            carpeta_input = app.dir_img_current;
            theFiles = lectura_imatges_carpeta_estr(carpeta_input);
            
            % Mostrem imatge en la GUI
            fullFileName = fullfile(theFiles(n_img_actual).folder, theFiles(n_img_actual).name);
            imageArray_escala = imread(fullFileName); % Imatge inicial

            % Mostrat en axes
            imshow(imageArray_escala, 'Parent', app.UIAxes); 
            % Guardem la imatge
            app.img_main = imageArray_escala;


            % Mostrem altres apartats:
            app.img_nameLabel.Text = theFiles(n_img_actual).name; % Nom imatge
            im_act_iminfo = imfinfo(fullFileName);
            app.valure_resolutionLabel.Text = strcat(string(im_act_iminfo.Height), "x", string(im_act_iminfo.Width));
            app.image_type_fileLabel.Text = im_act_iminfo.Format;

            if n_img_actual > 1
                app.left_imageButton.Visible = 'on';
            else
                app.left_imageButton.Visible = 'off';
            end
            
            if n_img_actual == length(theFiles)
                app.right_imageButton.Visible = 'off';
            else
                app.right_imageButton.Visible = 'on';
            end
            %length(theFiles);
            
        end

        % Button pushed function: left_imageButton
        function left_imageButtonPushed(app, event)
            % Botó esquerra
            
            % Nota: copiar tot de botó de la dreta

            % Llegim contador numero imatges
            n_img_actual = str2double(app.n_img_acutLabel.Text);
            n_img_actual = n_img_actual -1;
            
            % Sumem 1 al contador numero imatges
            app.n_img_acutLabel.Text = string(n_img_actual);
            
            
            % Llegim contingut carpeta imatges
            carpeta_input = app.dir_img_current;
            theFiles = lectura_imatges_carpeta_estr(carpeta_input);
            
            % Mostrem imatge en la GUI
            fullFileName = fullfile(theFiles(n_img_actual).folder, theFiles(n_img_actual).name);
            imageArray_escala = imread(fullFileName); % Imatge inicial

            % Mostrat en axes
            imshow(imageArray_escala, 'Parent', app.UIAxes); 
            % Guardem la imatge
            app.img_main = imageArray_escala;


            % Mostrem altres apartats:
            app.img_nameLabel.Text = theFiles(n_img_actual).name; % Nom imatge
            im_act_iminfo = imfinfo(fullFileName);
            app.valure_resolutionLabel.Text = strcat(string(im_act_iminfo.Height), "x", string(im_act_iminfo.Width));
            app.image_type_fileLabel.Text = im_act_iminfo.Format;

            if n_img_actual > 1
                app.left_imageButton.Visible = 'on';
            else
                app.left_imageButton.Visible = 'off';
            end
            
            if n_img_actual == length(theFiles)
                app.right_imageButton.Visible = 'off';
            else
                app.right_imageButton.Visible = 'on';
            end
            %length(theFiles);


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Button Axes (wtf)
% 
%             disp("hiok")
% 
%             event
% 
%             properties(event)


        end

        % Value changed function: ModificacionsListBox
        function ModificacionsListBoxValueChanged(app, event)
            % Selecció i aplicació de modificació


            % VISUALITZACIÓ DE LES MODIFICACIONS
            % Agafem el text seleccionat del ListBox:
            value_listbox = app.ModificacionsListBox.Value;
            
            % Llegir arxiu i mostrar en Label Modificacions
            field_set = strcat("Results_out\Internal_code_files\Image_processing_settings\", string(value_listbox));

            % obtneim l'array de la lectura del document de text i el printem
            [array_sortida_modifs, ~] = llegir_text_delimitadors(field_set, ";");

            % Obtenim l'array de processaments:
            cell_cont = array_sortida_modifs(2:end, 2);
            array_processaments = cell2array_josep(cell_cont);

            % Modifiquem la imatge original i la mostrem:
            imatge_original = app.img_main;
            [BW_final, ~] = processament_imatge_llistat(imatge_original, array_processaments);

            %imshow(imatge_original, 'Parent', app.UIAxes);
            
            imshow(BW_final, 'Parent', app.UIAxes_2);
            

            app.UIAxes.Visible = 'on';


        end

        % Button pushed function: SelectimagesButton
        function SelectimagesButtonPushed(app, event)
            % Selec images button

            % Seleccionar una imatge o imatges com la base de l'anàlisi, en
            % compte de tota una carpeta.

            % __Tipologies d'imatges acceptades/excloses__'
            % Acceptades: *.jpg', '*.png', '*.jpeg', '*.tif', '*.tiff', '*.jfif', '*.bmp'
            % Excluded: '*.gif' i '*.webp'


            % Aplicar la funció:
            [theFiles] = lectura_imatges_seleccio();

            % ___Guardar imatges____
            dir_results_analisis = strcat(app.dir_current, "\Results_out\Analisis_results");
            carpeta_guardar_dades = strcat(dir_results_analisis, "\", app.ListBox.Value); % Carpeta guardat dades
            arxiu_txt_dir_img = strcat(carpeta_guardar_dades, "\", "theFiles.txt"); % Dir arxiu .txt guardar imatges 

            write_txt_enblanc(arxiu_txt_dir_img) % Fem arxiu en blanc (eliminem si hi ha anterior)
            
            % _Guardar imatges_
            appini_guardar_theFiles_txt(theFiles, arxiu_txt_dir_img);


            % ___Lectura i mostrar dades imatges___
            
            % Lectura arxiu theFiles (Operarem amb aquest, amb la taula)
            % ___Lectura imatges___
            file_txt_imgs = strcat(dir_results_analisis, "\", app.ListBox.Value, "\", "theFiles.txt");
            
            % Si existeix l'arxiu
            [S_textscan_table] = llegir_dades(file_txt_imgs, "%s", "$", ["Image_file"]);

            % Mostrar propietats imatge
            appini_mostrar_imatges_props(app, S_textscan_table);


            % Mostrar propietats imatge
%             appini_mostrar_imatges_props(app, S_textscan_table);


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            % Afegir nota al anàlisi

            app.TextArea.Editable = 'on';


        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;

            % Una vegada es canvia el text.

            figure(app.UIFigure)

        end

        % Callback function: TextArea
        function TextAreaValueChanging(app, event)
            changingValue = event.Value;
            
            % Text cambiant

            % changingValue

            % Posar el changing a un mateix 
            [sortida_cell] = juntar_str_cell(changingValue);


            % Write de les notes
            dir_result_carpeta_analisis = strcat(app.dir_current, "\Results_out\Analisis_results", "\", app.ListBox.Value);

            write_normal_unicstr_josep(strcat(dir_result_carpeta_analisis, "\notes_analysis.txt"), sortida_cell{1});

            % Lectura (comprovació)
            % [string_sortida] = llegir_text_delimitadors_normal(strcat(dir_result_carpeta_analisis, "\notes_analysis.txt"));
            % app.TextArea.Value = string_sortida;


        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            

            % Desseleccionar edició text:
            app.TextArea.Editable = 'off';

            dir_results_analisis = strcat(app.dir_current, "\Results_out\Analisis_results");

            % ___Lectura imatges___
            file_txt_imgs = strcat(dir_results_analisis, "\", app.ListBox.Value, "\", "theFiles.txt");

            % Si existeix l'arxiu
            if isfile(file_txt_imgs)
                [S_textscan_table] = llegir_dades(file_txt_imgs, "%s", "$", ["Image_file"]);

                % Mostrem imatge i propietats
                appini_mostrar_imatges_props(app, S_textscan_table);
    

                % Mostrem botóns
                appini_open_close_buttons(app, 'on')

                % __Lectura escala__
                file_txt_scale = strcat(dir_results_analisis, "\", app.ListBox.Value, "\", "rate_scale.txt");
                if isfile(file_txt_scale)
                    % ÑÑÑÑ
                    lectura_scale = llegir_text_delimitadors(file_txt_scale, ";");
                    app.EditField.Value = str2num(lectura_scale{2}); % Mostrem el valor
                    app.EditField.Editable = 'off';
                end


            else
                % Ocultem botóns
                appini_open_close_buttons(app, 'off')
                % imshow(imageArray_escala, 'Parent', app.UIAxes); 
                appini_open_close_buttons_imatge(app, "Enable", 'off')

                app.NofimagesLabel.Enable = 'off';
            end


            % Mirem si hi ha notes
            file_txt_notes = strcat(dir_results_analisis, "\", app.ListBox.Value, "\", "notes_analysis.txt");
            if isfile(file_txt_notes)
                [lectura_notes] = llegir_text_delimitadors_normal(file_txt_notes);
                app.TextArea.Value = lectura_notes;
            else
                app.TextArea.Value = "";
            end


        end

        % Button pushed function: NewanalysisButton
        function NewanalysisButtonPushed(app, event)
            % New analysis

            % Crea la carpeta i guarda la data actual.

            
            % Obrir cuadre dialeg per entrada nom arxiu
            prompt = "Nom de l'arxiu:";
            dlgtitle = 'Input';
            dims = [1 35];
            % Definim el input. El nom canvia si el valor és inferior a
            % 10 (01, 02, 03...) per a aconseguir un ordre correcte.
            answer_resposta = inputdlg(prompt,dlgtitle,dims,"New analysis");   

            % __Existencia nom dir__ %
            % Mirem si existeix el nom o no:
            porta_crear_dir = true;
            if ismember(answer_resposta, app.ListBox.Items)
                msgbox("The directory name already exist")
                porta_crear_dir = false;
            end

            if porta_crear_dir
                % Creem carpeta
                dir_results_analisis = strcat(app.dir_current, "\Results_out\Analisis_results");
                dir_result_carpeta_analisis = strcat(app.dir_current, "\Results_out\Analisis_results", "\", answer_resposta{1});
                mkdir(dir_result_carpeta_analisis)

                % Creem arxiu amb la data de l'anàlisi
                write_text_josep(strcat(dir_result_carpeta_analisis, "\date_analysis.txt"), ["date", datestr(datetime("now"))], ";");

                % __Actualitzar listbox carpetes analisis___ %
                % Actualitzem llistat carpetes resultats:
                [dir_carpetes_tot_sorted, ~, ~, ~] = obtenir_nom_carpetes(dir_results_analisis);
    
                % Actualitzem listBox
                app.ListBox.Items = dir_carpetes_tot_sorted;
                app.ListBox.Value = app.ListBox.Items{end};
                scroll(app.ListBox, "bottom")

                % Actualitzem la data:
                lectura_data = llegir_text_delimitadors(strcat(dir_result_carpeta_analisis, "\date_analysis.txt"), ";");
                app.DateTimeLabel.Text = lectura_data{2};

            end


        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
            % Canvi del valor de l'escala


            % Creem arxiu amb la data de l'anàlisi
            dir_result_carpeta_analisis = strcat(app.dir_current, "\Results_out\Analisis_results", "\", app.ListBox.Value);
            write_text_josep(strcat(dir_result_carpeta_analisis, "\rate_scale.txt"), ["rate_scale", value], ";");

            % lectura_data_t = llegir_text_delimitadors(strcat(dir_result_carpeta_analisis, "\rate_scale.txt"), ";");
            % lectura_data = lectura_data_t{2};


        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            % Analisis!


            app.ModificacionsListBox.Value

            % ___Copia arxiu modificació___

            % Guardar la modificació seleccionada.
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\";
            file_to_copy = strcat(app.dir_current, "\", myFolder_input, app.ModificacionsListBox.Value); % Fitxer a copiar

            % Creem carpeta guardar modificació
            dir_modifs_analysis = strcat(app.dir_current, "\Results_out\Analisis_results\", app.ListBox.Value, "\Modif_files");
            if ~isfolder(dir_modifs_analysis)
                mkdir(dir_modifs_analysis)
            end

            % Arxiu on copiar
            file_in_copy = strcat(dir_modifs_analysis, "\", app.ModificacionsListBox.Value); % Fitxer on copiar

            % Copiem l'arxiu
            copyfile(file_to_copy, file_in_copy)


        end

        % Button pushed function: FixButton
        function FixButtonPushed(app, event)
            % Fixar el valor de l'escala

            % Fer ineditable la introducció de dades de l'escala

            if app.FixButton.Text == "Fix"
                app.EditField.Editable = 'off';
                app.FixButton.Text = "UnFix";

            else
                app.EditField.Editable = 'on';
                app.FixButton.Text = "Fix";
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.SelectimagefolderButton = uibutton(app.UIFigure, 'push');
            app.SelectimagefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimagefolderButtonPushed, true);
            app.SelectimagefolderButton.Icon = 'img_folder-1.png';
            app.SelectimagefolderButton.Text = {'Select'; 'image'; 'folder'};
            app.NofimagesLabel = uilabel(app.UIFigure);
            app.NofimagesLabel.Visible = 'off';
            app.NofimagesLabel.Text = 'Nº of images:';
            app.ImagefiletypeLabel = uilabel(app.UIFigure);
            app.ImagefiletypeLabel.Visible = 'off';
            app.ImagefiletypeLabel.Text = 'Image file type:';
            app.n_value_imagesLabel = uilabel(app.UIFigure);
            app.n_value_imagesLabel.Visible = 'off';
            app.n_value_imagesLabel.Text = 'n_value_images';
            app.ScaleLabel = uilabel(app.UIFigure);
            app.ScaleLabel.Text = 'Scale: ';
            app.ImageresolutionLabel = uilabel(app.UIFigure);
            app.ImageresolutionLabel.Visible = 'off';
            app.ImageresolutionLabel.Text = 'Image resolution:';
            app.valure_resolutionLabel = uilabel(app.UIFigure);
            app.valure_resolutionLabel.Visible = 'off';
            app.valure_resolutionLabel.Text = 'valure_resolution';
            app.DeterminescaleButton = uibutton(app.UIFigure, 'push');
            app.DeterminescaleButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminescaleButtonPushed, true);
            app.DeterminescaleButton.Icon = 'barscale2.png';
            app.DeterminescaleButton.Text = 'Determine scale';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse!';
            app.ImageprocessmentconfigurationButton = uibutton(app.UIFigure, 'push');
            app.ImageprocessmentconfigurationButton.Text = {'Image'; 'processment'; 'configuration'};
            app.image_type_fileLabel = uilabel(app.UIFigure);
            app.image_type_fileLabel.Visible = 'off';
            app.image_type_fileLabel.Text = 'image_type_file';
            app.ModificacionsListBox = uilistbox(app.UIFigure);
            app.ModificacionsListBox.Items = {};
            app.ModificacionsListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionsListBoxValueChanged, true);
            app.ModificacionsListBox.Value = {};
            app.SavedprocessmentsLabel = uilabel(app.UIFigure);
            app.SavedprocessmentsLabel.Text = 'Saved processments';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ExtendimagegraficationCheckBox = uicheckbox(app.UIFigure);
            app.ExtendimagegraficationCheckBox.Text = 'Extend image grafication';
            app.extendButton = uibutton(app.UIFigure, 'push');
            app.extendButton.ButtonPushedFcn = createCallbackFcn(app, @extendButtonPushed, true);
            app.extendButton.Text = 'extend ↓';
            app.dir_internal_code_filesLabel = uilabel(app.UIFigure);
            app.dir_internal_code_filesLabel.Text = 'dir_internal_code_files';
            app.right_imageButton = uibutton(app.UIFigure, 'push');
            app.right_imageButton.ButtonPushedFcn = createCallbackFcn(app, @right_imageButtonPushed, true);
            app.right_imageButton.Text = '>';
            app.left_imageButton = uibutton(app.UIFigure, 'push');
            app.left_imageButton.ButtonPushedFcn = createCallbackFcn(app, @left_imageButtonPushed, true);
            app.left_imageButton.Visible = 'off';
            app.left_imageButton.Text = '<';
            app.n_img_acutLabel = uilabel(app.UIFigure);
            app.n_img_acutLabel.Visible = 'off';
            app.n_img_acutLabel.Text = 'n_img_acut';
            app.img_nameLabel = uilabel(app.UIFigure);
            app.img_nameLabel.Visible = 'off';
            app.img_nameLabel.Text = 'img_name';
            app.TotalfiletypesLabel = uilabel(app.UIFigure);
            app.TotalfiletypesLabel.Text = 'Total file types';
            app.file_typesLabel = uilabel(app.UIFigure);
            app.file_typesLabel.Text = 'file_types';
            app.resol_typesLabel = uilabel(app.UIFigure);
            app.resol_typesLabel.Text = 'resol_types';
            app.TotalresolutiontypesLabel = uilabel(app.UIFigure);
            app.TotalresolutiontypesLabel.Text = 'Total resolution types:';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Logo_WorMe_transp.png';
            app.FolderLabel = uilabel(app.UIFigure);
            app.FolderLabel.Visible = 'off';
            app.FolderLabel.Text = 'Folder:';
            app.current_folderLabel = uilabel(app.UIFigure);
            app.current_folderLabel.Visible = 'off';
            app.current_folderLabel.Text = 'current_folder';
            app.SelectimagesButton = uibutton(app.UIFigure, 'push');
            app.SelectimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimagesButtonPushed, true);
            app.SelectimagesButton.Icon = 'image-outline-filled.png';
            app.SelectimagesButton.Text = {'Select'; 'images'};
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.AnalysisLabel = uilabel(app.UIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.TextArea.Editable = 'off';
            app.NotesLabel = uilabel(app.UIFigure);
            app.NotesLabel.Text = 'Notes';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.NewanalysisButton = uibutton(app.UIFigure, 'push');
            app.NewanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @NewanalysisButtonPushed, true);
            app.NewanalysisButton.Text = 'New analysis';
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = 'Date:';
            app.DateTimeLabel = uilabel(app.UIFigure);
            app.DateTimeLabel.Text = 'DateLabel';
            app.FixButton = uibutton(app.UIFigure, 'push');
            app.FixButton.ButtonPushedFcn = createCallbackFcn(app, @FixButtonPushed, true);
            app.FixButton.Text = 'Fix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_iniciacio_program(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end