classdef app_seleccio_escales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DETERMINETHESCALELabel  matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Iniciació app (startupFcn)
            
            UIFigure_position = app.UIFigure.Position; %100   100   602   389
                                                      % inici final amplada  llargada

            screensize_position = get(0, 'ScreenSize'); %         1           1        1920        1080

            [UIFigure_position_new] = centered_GUI_screen(UIFigure_position, screensize_position);
            
            app.UIFigure.Position = UIFigure_position_new;

            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Drawing a line callback
            
            close force
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            % Manually automatic callback
            
            close force
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'DRAWING_A_LINE.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'MANUALLY_AUTOMATIC.png';
            app.DETERMINETHESCALELabel = uilabel(app.UIFigure);
            app.DETERMINETHESCALELabel.Text = 'DETERMINE THE SCALE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_seleccio_escales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end