classdef app_nova_interfaz_nova_funcions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DLabel                         matlab.ui.control.Label
        ALabel                         matlab.ui.control.Label
        Cross_Button_little            matlab.ui.control.Image
        Thick_Button_little            matlab.ui.control.Image
        n_noLabel2                     matlab.ui.control.Label
        n_yesLabel2                    matlab.ui.control.Label
        refusedLabel                   matlab.ui.control.Label
        acceptedLabel                  matlab.ui.control.Label
        n_noLabel                      matlab.ui.control.Label
        n_yesLabel                     matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        spaceLabel                     matlab.ui.control.Label
        ELabel                         matlab.ui.control.Label
        InfoIcon_3                     matlab.ui.control.Button
        InfoIcon_2                     matlab.ui.control.Button
        ExportnoncorrectedmanualerrordataCheckBox  matlab.ui.control.CheckBox
        ImageLabel_2                   matlab.ui.control.Label
        ExportIAdataButton             matlab.ui.control.Button
        InfoIcon_Brush                 matlab.ui.control.Button
        InfoIcon_Draw                  matlab.ui.control.Button
        CloseVideoButton               matlab.ui.control.Button
        InfoIcon                       matlab.ui.control.Button
        QLabel                         matlab.ui.control.Label
        TutorialButton                 matlab.ui.control.StateButton
        CLabel                         matlab.ui.control.Label
        FLabel                         matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        SLabel                         matlab.ui.control.Label
        NotFinishedImagesLabel         matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        LupaReturnButton               matlab.ui.control.Button
        LupaButton                     matlab.ui.control.Button
        PintarButton                   matlab.ui.control.Button
        Image4                         matlab.ui.control.Image
        LastmodifLabel                 matlab.ui.control.Label
        WLabel                         matlab.ui.control.Label
        MinLengthTextArea              matlab.ui.control.NumericEditField
        MinWidthTextArea               matlab.ui.control.NumericEditField
        SelectionLabel                 matlab.ui.control.Label
        ToolsLabel                     matlab.ui.control.Label
        DrawLineButton                 matlab.ui.control.Button
        CutButton                      matlab.ui.control.Button
        MinWidthStateButton            matlab.ui.control.StateButton
        MinLengthStateButton           matlab.ui.control.StateButton
        Image3                         matlab.ui.control.Image
        postextareaTextArea            matlab.ui.control.TextArea
        dir_img_orig_sLabel            matlab.ui.control.Label
        FolderLabel                    matlab.ui.control.Label
        ofLabel                        matlab.ui.control.Label
        ImageLabel                     matlab.ui.control.Label
        ObjectnLabel                   matlab.ui.control.Label
        posUIAxes                      matlab.ui.control.TextArea
        ExitButton                     matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        max_valLabel                   matlab.ui.control.Label
        maxLabel                       matlab.ui.control.Label
        min_valLabel                   matlab.ui.control.Label
        minLabel                       matlab.ui.control.Label
        std_valLabel                   matlab.ui.control.Label
        stdLabel                       matlab.ui.control.Label
        mean_valLabel                  matlab.ui.control.Label
        meanLabel                      matlab.ui.control.Label
        nLabel                         matlab.ui.control.Label
        n_valLabel                     matlab.ui.control.Label
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        posSpinner                     matlab.ui.control.TextArea
        SpinnerRound                   matlab.ui.control.Spinner
        MaxCircularityButton           matlab.ui.control.StateButton
        posStateButtonsTextArea        matlab.ui.control.TextArea
        OriginalButton                 matlab.ui.control.StateButton
        n_objecteLabel                 matlab.ui.control.Label
        finalitzat_contLabel           matlab.ui.control.Label
        img_original_nomLabel          matlab.ui.control.Label
        Hyperlink                      matlab.ui.control.Hyperlink
        sizeFontTextArea               matlab.ui.control.TextArea
        posHyperlinkTextArea           matlab.ui.control.TextArea
        ReturnButton                   matlab.ui.control.Button
        CitationLabel                  matlab.ui.control.Label
        img_tot_nLabel                 matlab.ui.control.Label
        img_contLabel                  matlab.ui.control.Label
        ModificacinsguardadesListBoxLabel  matlab.ui.control.Label
        ModificacionstemporalsListBox  matlab.ui.control.ListBox
        ModificacionstemporalsListBoxLabel  matlab.ui.control.Label
        ModificacionsguardadesListBox  matlab.ui.control.ListBox
        FiltersLabel                   matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        Cross_Button                   matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Thick_Button                   matlab.ui.control.Button
        posCheckboxTextArea            matlab.ui.control.TextArea
        posImatgesTextArea             matlab.ui.control.TextArea
        StartupFcnContTextArea         matlab.ui.control.TextArea
        posListboxTextArea             matlab.ui.control.TextArea
        posButtonsTextArea             matlab.ui.control.TextArea
        posFigTextArea                 matlab.ui.control.TextArea
        posLabelTextArea               matlab.ui.control.TextArea
        posImgTextArea                 matlab.ui.control.TextArea
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    % Variables globals (són guardades en la app)
    properties (Access = private)


    end    

    % Públic, que no vol dir global (ho anomenarem semiglobal)
    properties (Access = public)

        % Upgrade_taula
        seg_inici_taula = true; % true si no s'ha obert una taula; false si sí que ja s'ha obert una.
        global_S_textscan_table = []; %         % Variable de la taula de dades
        
        % Variables generals
        theFiles_arxiu = []; % Arxiu theFiles provinent
        dir_imgs_orig = []; % Directori de les imatges originals
        appv_dir_output = []; % Ruta dels resultats
        appv_escala_img = []; % Escala


        % Valors amplada GUI (individuals de la imatge)
        global_amplada_values = [];

        % Valor resolucio imatge GUI
        resolucio_img = [];
        
        % _Variables filtre_
        width_mean_value = [];
        
        % Taules dades eines
        taula_scissors = [];
        taula_draw = [];
        taula_brush = [];

        % Taula Draw
        taula_draw_main = []; % Taula valors draw principals.
        

        imcrop_value = [];


        % _Imatges_mostrar_laterals_
        imatge_anterior_1 = [];

        % _Imatges mostra interiors_
        BW_img_cuted = []; % Imatge BW retallada, per a mostrar
        BW_img_extend = []; % Imatge BW, per a mostrar
        BW_img_temp = []; % Temporal guardar
        cont_canvi_img_1 = true;

        % _Imatge original_
        Img_current_app = [];
        
        
        % _contador return estadistica_
        finalitzat_a_estadistica = false;

        % Aturar video tutorial
        stop_video = false;        


        % Variables globals en app de temps
        global_control_temps_1 = [];
        global_control_temps_2 = [];
        global_control_temps_3 = [];
        global_control_temps_4 = [];
        global_control_temps_5 = [];        
        global_temps_total_inici = [];
        global_temps_total_final = [];

        taula_main = []; % Taula principal


        % _Contadors n modificacions_
        n_scissors = 0;
        n_drawing = 0;
        n_brush = 0;


    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, theFiles, dir_output, escala_img)
            % StartupFcn
            % app_nova_interfaz_nova_funcions(myFolder, carpeta_output)

            % proba_Josep_68

            % ____Lectura imatge per carpeta____
            % Variables input inicials:  dir_img_originals, dir_output, escala_img
            
            % Guardem la ruta:
            app.theFiles_arxiu = theFiles;
            app.dir_imgs_orig = theFiles(1).folder; % Ruta directori imatges origials
            app.appv_dir_output = dir_output; % Ruta resultats (carpeta_output)

            % Guardem escala
            app.appv_escala_img = escala_img;

            % StartupFcn
            app_interf_startupFcn(app)
            

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            
            % If per a que no peti al inici:
            if app.StartupFcnContTextArea.Value{1} == "StartupFcnCont"
                % Per a fer que no peti al principi
                
                app.StartupFcnContTextArea.Value = "";
            else
                
                
                % ____RE- ESCALAR LA IMATGE____
                
                % ÇÇÇ Possible determinació del temps de maximitzar o
                % minimitzar la figura, en funció al temps que transcorri
                % entre el que canvia la posició al maximitzar la figura.
                % seria emprar pause(0.001) aprox.
                
                % _Definim mesura figura inicial_
                pos_fig = double(split(string(app.posFigTextArea.Value{1}), ";"))';
    
                pause(1) % Pausa necessaria obrir finestra, i obtenir mesura bona
                
                % _Definim mesura figura actual_
                pos_fig2 = app.UIFigure.Position;
    

%                 
%                 % _Re- escalar imatge_
%                 pos_img = double(split(string(app.posImgTextArea.Value{1}), ";"))';                
%                 pos_img2 = rescale_values_img_app(pos_fig, pos_img, pos_fig2);
%                 app.Image.Position = pos_img2;
%                 drawnow()            
%     
                    
                
                % _Re- escalar botóns_
                % Lectura posicions inicials
                button_text = app.posButtonsTextArea.Value{1};
                but_values = app_resize_lectura_str(button_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','uibutton');
                [row_n, ~] = size(but_values);
                % Per cada botó
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);

                    % Si volem que no s'eixamplin els botóns:
                    pos_ini_but = but_values(n_but, :);
                    pos_boto_rescale = [pos_boto_rescale(1), pos_boto_rescale(2), pos_ini_but(3), pos_ini_but(4)];
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end


                % _Re- escalar State botóns_
                % Lectura posicions inicials
                button_text = app.posStateButtonsTextArea.Value{1};
                but_values = app_resize_lectura_str(button_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','uistatebutton');
                [row_n, ~] = size(but_values);
                % Per cada botó
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);

                    % Si volem que no s'eixamplin els botóns:
                    pos_ini_but = but_values(n_but, :);
                    pos_boto_rescale = [pos_boto_rescale(1), pos_boto_rescale(2), pos_ini_but(3), pos_ini_but(4)];
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end                


                % _Re- escalar listbox_
                % Lectura posicions inicials
                listbox_text = app.posListboxTextArea.Value{1};
                listbox_values = app_resize_lectura_str(listbox_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','uilistbox');
                [row_n, ~] = size(listbox_values);
                % Per cada botó
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, listbox_values(n_but, :), pos_fig2);
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end


                % _Re- escalar label_
                % Lectura posicions inicials
                button_text = app.posLabelTextArea.Value{1};
                but_values = app_resize_lectura_str(button_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','uilabel');
                [row_n, ~] = size(but_values);
                % Per cada botó
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end
                                
                
                % _Re- escalar imatges_
                % Lectura posicions inicials
                button_text = app.posImatgesTextArea.Value{1};
                but_values = app_resize_lectura_str(button_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','uiimage');
                [row_n, ~] = size(but_values);
                % Per cada botó
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end
                                        
                
%                 % _Re- escalar checkbox_
%                 % Lectura posicions inicials
%                 button_text = app.posCheckboxTextArea.Value{1};
%                 but_values = app_resize_lectura_str(button_text);
%                 
%                 % Posicions dels botóns
%                 h_determin_button = findobj(app.UIFigure,'Type','uicheckbox');
%                 [row_n, ~] = size(but_values);
%                 % Per cada botó
%                 for n_but = 1:row_n
%                     % Rescalem la posicio
%                     pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);
%                     
%                     % Cambiem posicio boto
%                    boto = h_determin_button(n_but);
%                    %boto.Position
%                    boto.Position = pos_boto_rescale;            
%                 end


                % _Re- escalar hiperlink_
                % Lectura posicions inicials
                button_text = app.posHyperlinkTextArea.Value{1};
                but_values = app_resize_lectura_str(button_text); %proba_app_resize_lectura_str(button_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','uihyperlink');
                [row_n, ~] = size(but_values);
                % Per cada botó
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end
                
               
                %_Re- escalar font size_
                fact_fontsize = str2double(app.sizeFontTextArea.Value);
                new_font_size = sqrt(pos_fig2(3) * pos_fig2(4)) * fact_fontsize;
                
                % Tamany de les fonts, per cada hyperlink
                h_determin_button = findobj(app.UIFigure,'Type','uihyperlink');
                for n_but = 1:length(h_determin_button)
                    % Obtenim el objecte:
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.FontSize = new_font_size;            
                end
                
                % Tamany de les fonts, NOMÈS UN LABEL (app.CitationLabel)
                h_determin_button = app.CitationLabel; %findobj(app.UIFigure,'Type','uilabel');
                for n_but = 1:length(h_determin_button)
                    % Obtenim el objecte:
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.FontSize = new_font_size;            
                end         
                
                
                % _Re- escalar listbox_
                % Lectura posicions inicials
                listbox_text = app.posSpinner.Value{1};
                listbox_values = app_resize_lectura_str(listbox_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','uispinner');
                [row_n, ~] = size(listbox_values);
                % Per cada botó 
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, listbox_values(n_but, :), pos_fig2);

                    % Si volem que no s'eixamplin els botóns:
                    pos_ini_but = listbox_values(n_but, :);
                    pos_boto_rescale = [pos_boto_rescale(1), pos_boto_rescale(2), pos_ini_but(3), pos_ini_but(4)];
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end                   


                % _Re- escalar Grafic_
                % Lectura posicions inicials
                button_text = app.posUIAxes.Value{1};
                but_values = app_resize_lectura_str(button_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','axes');
                [row_n, ~] = size(but_values);
                % Per cada botó
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end
                            

                % _Re- escalar Àrea de Text_
                % Lectura posicions inicials
                button_text = app.postextareaTextArea.Value{1};
                but_values = app_resize_lectura_str(button_text);
                
                % Posicions dels botóns
                h_determin_button = findobj(app.UIFigure,'Type','uitextarea');
                [row_n, ~] = size(but_values);
                % Per cada botó
                for n_but = 1:row_n
                    % Rescalem la posicio
                    pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);
                    
                    % Cambiem posicio boto
                   boto = h_determin_button(n_but);
                   %boto.Position
                   boto.Position = pos_boto_rescale;            
                end
                                            
                
%            
%                 % _Re- escalar botóns_
%                 % Lectura posicions inicials
%                 button_text = app.posButtonsTextArea.Value{1};
%                 but_values = app_resize_lectura_str(button_text);
%                 
%                 % Posicions dels botóns
%                 h_determin_button = findobj(app.UIFigure,'Type','uibutton');
%                 [row_n, ~] = size(but_values);
%                 % Per cada botó
%                 for n_but = 1:row_n
%                     % Rescalem la posicio
%                     pos_boto_rescale = rescale_values_img_app(pos_fig, but_values(n_but, :), pos_fig2);
% 
%                     % Si volem que no s'eixamplin els botóns:
%                     pos_ini_but = but_values(n_but, :);
%                     pos_boto_rescale = [pos_boto_rescale(1), pos_boto_rescale(2), pos_ini_but(3), pos_ini_but(4)];
%                     
%                     % Cambiem posicio boto
%                    boto = h_determin_button(n_but);
%                    %boto.Position
%                    boto.Position = pos_boto_rescale;            
%                 end


            end

            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
%             
%             pos_fig = app.UIFigure.Position
%                         
%             imshow(false([100, 100]))            
        end

        % Value changed function: ModificacionstemporalsListBox
        function ModificacionstemporalsListBoxValueChanged(app, event)
            % Modificacions temporals
            
%             % VISUALITZACIÓ DE LES MODIFICACIONS
%             % Agafem el text seleccionat del ListBox:
%             value_listbox = app.ModificacionstemporalsListBox.Value;
%             
%             % Llegir arxiu i mostrar en Label Modificacions
%             field_set = strcat("Apps\app_processament_imatge\Internal code files\Image processing settings\temporals\", string(value_listbox));
% 
%             % obtneim l'array de la lectura del document de text i el printem
%             [array_sortida_modifs, ~] = llegir_text_delimitadors(field_set, ";");
% 
%             % Operem
%             cell_cont = array_sortida_modifs(2:end, 2);
%             array_processaments = cell2array_josep(cell_cont);
%             
%             
%             % Modifiquem la imatge original i la mostrem:
%             imatge_original = imread(strcat(app.dir_imgs_orig, "\", app.img_original_nomLabel.Text));            
%              [BW_final, ~] = processament_imatge_llistat(imatge_original, array_processaments);
% 
%              % Mostrar la imatge en la sortida
%              [imatge_3D_sortida] = graficar_rgbgraybw_image(BW_final);
% 
%              app.Image.ImageSource = imatge_3D_sortida;   
                         
        end

        % Value changed function: ModificacionsguardadesListBox
        function ModificacionsguardadesListBoxValueChanged(app, event)
            % Modificacions guardades
%             
%             % ççç: Si es canvia la modificació, es borren les dades que
%             % s'habien guardat de la imatge i es torna a fer tots els
%             % worms.
% 
%             % Nota ççç: gUARDAR PUNT DE LA IMATGE on es fa la última operació,  o l'operació actual (el seleccionat 
%             % en un inici), perque? perque si es vol cambiar la modificació de la imatge que no alteri i es canvii l'objecte.
% 
%             % VISUALITZACIÓ DE LES MODIFICACIONS
%             % Agafem el text seleccionat del ListBox:
%             value = app.ModificacionsguardadesListBox.Value;
%             dir_output = app.appv_dir_output;
%             
%             % Llegir arxiu i mostrar en Label Modificacions
%             field_set = strcat("Apps\app_processament_imatge\Internal code files\Image processing settings\", string(value));
% 
%             % obtneim l'array de la lectura del document de text i el printem
%             [array_sortida_modifs, ~] = llegir_text_delimitadors(field_set, ";");
% 
%             % Operem
%             cell_cont = array_sortida_modifs(2:end, 2);
%             array_processaments = cell2array_josep(cell_cont);
%             
%             % Modifiquem la imatge original i la mostrem: 
%             imatge_original = imread(strcat(app.dir_imgs_orig, "\", app.img_original_nomLabel.Text));
%              [BW_final, ~] = processament_imatge_llistat(imatge_original, array_processaments);
%              imwrite(BW_final, strcat(dir_output, "\imgBWtemp.png"))
%              
%             % _Describim la dada de les modificacions_
%             app.Dada_Modificacions_genLabel.Text = strjoin(array_processaments, ";");
%             % Comprovat capacitat label en 224553 dígits.            
%             % % Lectura d'aquesta
%             % array_processaments_read = split(string(app.Dada_Modificacions_genLabel.Text), ";")';
%             % % Comprovació
%             % unique(array_processaments_read == array_processaments) % Funciona!
% 
%             % _Obtenim l'objecte binari_
%             [L_variable, ~] = bwlabel(BW_final);
%             BW_final_object = L_variable == 1;
% 
%             % _Describim la dada dels index de l'objecte binari_
%             indx_BWobj = find(BW_final_object);
%             fid = fopen(strcat(dir_output, "\temp_indx_BWobject.txt"),'w');
%             fprintf(fid, "%d;", indx_BWobj(:)); %.0 perquè no han de ser decimals
%             fclose(fid); % Elapsed time is 0.016547 seconds.
%             % % Per a llegir aquests:
%             % fid = fopen(strcat(dir_output, "\temp_indx_BWobject.txt"));
%             % indx_BWobj_textscan = textscan(fid, '%d', 'Delimiter', ";"); % Elapsed time is 0.016595 seconds.
%             % indx_BWobj_read = indx_BWobj_textscan{1};
%             % %Son iguals?
%             % unique(indx_BWobj == indx_BWobj_read) % Funciona!
%             
%             
%             % Retallem l'objecte
%             [imageArray_crop, BW_filt_crop, BB_values] = retallar_BWRGBimatge_BB_nou(imatge_original, BW_final_object, 5);
%             app.Image2.ImageSource =  graficar_rgbgraybw_image(BW_filt_crop);
% 
%             
%             % _Describim la dada del Bounding Box_
%             % Passem a string  i posem dades a al TextLabel de la app
%             app.Dada_BBLabel.Text = strjoin(string(BB_values), ";");
%             % Per a llegir el valor:
%             %BB_values_read = double(split(string(app.Dada_BBLabel.Text), ";"))';
%             % Comprovació
%             % unique(BB_values_read == BB_values) % Són iguals.
% 
%             % __Esqueletonització objecte__
%             [BW_skel, dades_imatge] = esqueletonitzacio_josep_optim(BW_final_object, 0.855, true, true);
% 
%             % _Describim la dada de la llargada_
%             % Posem dades a al TextLabel de la app
%             app.Dada_llargadaLabel.Text = string(dades_imatge);
%             % % Per a llegir el valor:
%             % dades_imatge_read = str2double(app.Dada_llargadaLabel.Text); %Elapsed time is 0.000585 seconds.
%             % % Comprovació
%             % unique(dades_imatge_read == dades_imatge) % Dona que no són iguals, però el valor sí ho és.
% 
%             % _Describim la dada dels index de la imatge esqueletonitzada_
%             indx_img = find(BW_skel);
%             fid = fopen(strcat(dir_output,"\temp_indx_BWskel.txt"),'w');
%             fprintf(fid, "%d;", indx_img(:)); %.0 perquè no han de ser decimals
%             fclose(fid); % Elapsed time is 0.003543 seconds.
%             % % Per a llegir aquests:
%             % fid = fopen(strcat(dir_output,"\temp_indx_BWskel.txt"))
%             % S_textscan = textscan(fid, '%d', 'Delimiter', ";") % Elapsed time is 0.010216 seconds.
%             % indx_img_read = S_textscan{1};
%             % %Son iguals?
%             % unique(indx_img_read == indx_img) % Funciona!
%             
%             
%             % _Describim la dada de la imatge original_
%             nom_img_origin_temp = split(app.dir_imgs_orig, "\");
%             nom_img_origin = nom_img_origin_temp{end}; % Funciona
%                         
%             % Fins aqui tenim les dades necessaries.
%             % Ara el que hem de fer és descriure aquestes en l'arxiu de
%             % text quan es seleccionin.
%             
% 
%             % Esqueletonitzem aquest 
%             % vaig per aqui
%             
%             % Passos:
%             % Objecte singular
%             % Obtenir el BB
%             % Esqueletonitzar aquest
%             % a imatge general:
%             % Mostrar BB
%             % Fer imoverlay
%             % Guardar dades
%             
%              % Mostrar la imatge en la sortida
%              [imatge_3D_sortida] = graficar_rgbgraybw_image(BW_final);
% 
%              app.Image.ImageSource = imatge_3D_sortida;
%                          
        end

        % Button pushed function: Thick_Button
        function Thick_ButtonPushed(app, event)
            % Botó thick
            app_interf_boto_thick(app)

        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            % Click en imatge 2
            % _Obrir imatge pantalla maximitzada_
            obrir_tancar_imatges(app.Image2.ImageSource)
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Click en imatge 
            % _Obrir imatge pantalla maximitzada_
            %obrir_tancar_imatges(app.Image.ImageSource)
            app_interf_boto_clickimage(app)

            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            % Botó return
            app_interf_boto_return(app) 

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Botó Done
        
            % Botó aplicació estadístic
            app_interf_boto_estadist(app)
            % Nota: es fa en funció externa per a poder executar des de
            % funció de MATLAB externa. Sino el buttonpushed no deixa.
        

            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)                       

            
        end

        % Button pushed function: Cross_Button
        function Cross_ButtonPushed(app, event)
            % Cross callback
            app_interf_boto_cross(app)


        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Keyshorts
            key = event.Key;
            
%             'space'  % espai
%             'return' % enter
%             
%             'leftarrow' % fletxa esquerra
%             'rightarrow' % fletcha dreta
%             'downarrow' % fletcha inferior
%             'uparrow' % fletcha amunt
%             
%             'escape' % esc
%             'tab' % tabulador

            switch key
                case 'a'
                    % Cross
                    if app.Cross_Button.Visible == 'on'
                        app.Cross_ButtonPushed;
                        
                    % Rigth button, si no hi ha el cross
                    elseif app.RightButton.Visible == 'on'
                        app.RightButtonPushed                        
                    end
                
                case 'd'
                    % Thick
                    if app.Thick_Button.Visible == 'on'
                        app.Thick_ButtonPushed;
                        
                    % Rigth button, si no hi ha el thick
                    elseif app.RightButton.Visible == 'on'
                        app.RightButtonPushed
                    end
                    
                    
                case 'w'
                    % Return
                    if app.ReturnButton.Visible == 'on'
                        app.ReturnButtonPushed;
                    end             
                    
                case 's'
                    % Botó imatge original
                    if app.OriginalButton.Visible == 'on'
                        if ~app.OriginalButton.Value
                            app.OriginalButton.Value = true;
                            app.OriginalButtonValueChanged;
                        else
                            app.OriginalButton.Value = false;
                            app.OriginalButtonValueChanged;                            
                        end
                        
                        
                    end
                    

                case 'q'
                    % Botó esquerra
                    if isequal(app.LeftButton.Visible, 'on')
                        app.LeftButtonPushed;
                    end

                case 'e'
                    % Botó dreta
                    if isequal(app.RightButton.Visible, 'on')
                        app.RightButtonPushed;
                    end


                case 'r'
                    % Botó tisores
                    if app.CutButton.Visible == 'on'
                        app.CutButtonPushed;
                    end

                case 'f'
                    % Botó línia
                    if app.DrawLineButton.Visible == 'on'
                        app.DrawLineButtonPushed;
                    end


                case 'c'
                    % Botó línia
                    if app.PintarButton.Visible == 'on'
                        app.PintarButtonPushed;
                    end


                case 'escape'
                    % Tancar tot
                    % app.DoneButtonPushed;
                    
                    % Posar missatge d'advertencia de tancar
                    
                    
                    % close force
                    
                    
                case 'space'
                    % Botó 'Done'
                    app_interf_boto_clickimage(app);
                    %app.DoneButtonPushed;
                    

            end
            
            
        end

        % Callback function
        function Auto_executeButtonPushed(app, event)
            % Length minima
            % Defineix la llargada mínima que tenen els objectes com per a
            % que es seleccionin.
            
            % En tècnic: es fa esqueletonització de tots els objectes i es
            % treuen aquells que tenen una mida inferior? es guarden les
            % dades d'aquells de mida inferior, doncs?
            

            % Mentre el programa no s'acabi, es va executant el botó thick
            % cada 2 segons
            while ~str2num(app.finalitzat_contLabel.Text)
                pause(0.7)

                if app.Thick_Button.Visible == 'on'

                    app.Thick_ButtonPushed
                % Si no hi ha el botó de thick, significa que hi ha el de
                % fletxa cap a la dreta:
                else
                    app.RightButtonPushed
                end
            end
                
            %[S_textscan_table] = app_interf_llegir_main_dades(dir_output)
    
            
            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)                       
            
        end

        % Value changed function: OriginalButton
        function OriginalButtonValueChanged(app, event)
            % Botó imatge Original
            
            % Passa la imatge a original
            app_interf_boto_original(app)
            
        end

        % Value changed function: MaxCircularityButton
        function MaxCircularityButtonValueChanged(app, event)
            % Botó canviar circuaritat Value Changed
            app_interf_boto_mincircularity(app)

        end

        % Callback function
        function TextArea_2ValueChanged(app, event)
            % Borrat
        end

        % Value changed function: SpinnerRound
        function SpinnerRoundValueChanged(app, event)
            % Botó spinner canviar circuaritat Value Changed
            app_interf_boto_spinner_maxcircu(app)
            
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            % Botó cap a la dreta
            app_interf_boto_dreta(app)

        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            % Botó cap a l'esquerra
            app_interf_boto_esquerra(app)
                        
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Botó exportar dades
            app_interf_button_export(app)
            
            
        end

        % Callback function
        function SpinnerWidthMeanValueChanged(app, event)
            % Cambiar valor percentatges
            
            % Si el botó filtre min no és seleccionat, es selecciona:
            if ~app.MinWidthRef.Value
                app.MinWidthRef.Value = true;                
            end
            
            % Si no és vuit el valor del TextArea, es modifica
            if ~isempty(app.MinWidthRefTextArea.Value{1})
                
                value_spinner_width = app.SpinnerWidthMean.Value;
                
                % Obtenim els valors de totes les amplades
                % Definim la taula:
                S_textscan_table = app.global_S_textscan_table;
                S_textscan_table_yesfilt = S_textscan_table(S_textscan_table.IsCeleg == "yes", :);
                
                width_total_values = appf_split_strindex(S_textscan_table_yesfilt.WidthValues);
                % Fem la mitja d'aquests valors
                width_total_mean = mean(width_total_values);
                
                % Obtenim el valor pel factor, i el mostrem:
                width_filter_threshold = width_total_mean * (value_spinner_width/100);
                
                app.MinWidthRefTextArea.Value = string(width_filter_threshold);

            end
            
            % ÇÇÇ Aqui hi hauria d'haber l'aplicació de filtres 
            % Fer-ho fent que hi hagi un push del button app.MinWidthRef
            
            
            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)                       
        end

        % Callback function
        function MinWidthRefValueChanged(app, event)
            value = app.MinWidthRef.Value;
            
            % Botó filtre minima amplada
            
            % Fer que es grafiqui segons les condicións descrites en el
            % filtre.


            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)                       
        end

        % Value changed function: MinLengthStateButton
        function MinLengthStateButtonValueChanged(app, event)
            % Min length button Value Changed
            app_interf_boto_minlength(app)
            
        end

        % Button pushed function: CutButton
        function CutButtonPushed(app, event)
            app_interf_boto_tisores(app)


        end

        % Button pushed function: DrawLineButton
        function DrawLineButtonPushed(app, event)
            % Botó drawline
            app_interf_boto_drawline(app)

        end

        % Value changed function: MinWidthStateButton
        function MinWidthStateButtonValueChanged(app, event)
            app_interf_boto_minwidth(app)

        end

        % Callback function
        function MinWidthRefTextAreaValueChanged(app, event)
            %value = app.MinWidthRefTextArea.Value;
            
            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)           
        end

        % Value changed function: MinWidthTextArea
        function MinWidthTextAreaValueChanged(app, event)
            %value = app.MinWidthTextArea.Value;
            
            app_interf_boto_textarea_minwidth(app)


        end

        % Value changed function: MinLengthTextArea
        function MinLengthTextAreaValueChanged(app, event)
            
            
            app_interf_boto_textarea_minlength(app)
            
                              
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)

            % Clicar imatge binaria

            app_interf_boto_clickimage_BW(app)

        end

        % Button pushed function: PintarButton
        function PintarButtonPushed(app, event)
            app_interf_boto_extend(app)               


        end

        % Button pushed function: LupaButton
        function LupaButtonPushed(app, event)
            % Botó lupa
            app_interf_boto_lupa(app)


        end

        % Button pushed function: LupaReturnButton
        function LupaReturnButtonPushed(app, event)
            % Botó retorn lupa
            app_interf_boto_lupa_return(app)


        end

        % Value changed function: TutorialButton
        function TutorialButtonValueChanged(app, event)
            value_tutorial = app.TutorialButton.Value;
            

            if value_tutorial

                % __Activació/Desactivacio botons__
                app_interf_activdesactiv_botons_tutorial(app, 'on')


                % Activacio/Desactivacio boto return
                if app.ReturnButton.Visible == 'off'
                    app.ReturnButton.Visible = 'on';
                    app.ReturnButton.Enable = 'off';
                end


                % __Descripció del color de les figures de la App__
                
                % Agafem tots els objectes:
                obj_h = findobj(app.UIFigure);
                figure_objects_names_filtr = unique(get(obj_h,'Type'));
    
                % Si volem filtar els objectes:
                %figure_objects_names_filtr_BackgroundColor = unique(figure_objects_names_filtr(ismember(figure_objects_names_filtr, ["uibutton"])));
                figure_objects_names_filtr_Color = unique(figure_objects_names_filtr(ismember(figure_objects_names_filtr, ["figure"])));            
                    
                % Per cada objecte:
                for n_figure_object = 1:length(figure_objects_names_filtr_Color)
                    figure_object = figure_objects_names_filtr_Color{n_figure_object};
    
                    % Obtenim les propietats del botó.
                    h_determin_button = findobj(app.UIFigure,'Type', figure_object);
                    h_determin_button.Color = h_determin_button.Color - 0.2;
    %                 [~, str_button_tot] = app_resize_obtenir_positions(h_determin_button);
    %                 app.figures_savedpos{end+1} = str_button_tot;
                end 


                % Cambi color botó tutorial:
                app.TutorialButton.BackgroundColor = [0.9290 0.6940 0.1250];
                        % Normal: [0.9600    0.9600    0.9600]
                        % Tronja fosc: [0.8500 0.3250 0.0980]
                        % Tronja clar: [0.9290 0.6940 0.1250]


            % Proba obrir video en compilat:
            % winopen("Imatges_logos_musica\Videos\Los Simpson - yogurlado (castellano).mp4");


                % Correccio que no surti ultim frame
                imshow("Apps\app_nova_interfaz_nova\Imatges_utilitat\imatge_background_fosc.png", 'Parent', app.UIAxes2);            


            % Si està activat el botó de Tutorial (sortir) = Com sempre
            else


                % __Descripció del color de les figures de la App__
                % Agafem tots els objectes:
                obj_h = findobj(app.UIFigure);
                figure_objects_names_filtr = unique(get(obj_h,'Type'));
    
                % Si volem filtar els objectes:
                %figure_objects_names_filtr_BackgroundColor = unique(figure_objects_names_filtr(ismember(figure_objects_names_filtr, ["uibutton"])));
                figure_objects_names_filtr_Color = unique(figure_objects_names_filtr(ismember(figure_objects_names_filtr, ["figure"])));            
                    
                % Per cada objecte:
                for n_figure_object = 1:length(figure_objects_names_filtr_Color)
                    figure_object = figure_objects_names_filtr_Color{n_figure_object};
    
                    % Obtenim les propietats del botó.
                    h_determin_button = findobj(app.UIFigure,'Type', figure_object);
                    h_determin_button.Color = [0.9400 0.9400 0.9400];

                end                       
                % [0.9400 0.9400 0.9400]


                % __Activació/Desactivacio botons__
                app_interf_activdesactiv_botons_tutorial(app, 'off')


                % Activacio/Desactivacio boto return
                if app.ReturnButton.Visible == 'on' && app.ReturnButton.Enable == 'off'
                    %app.ReturnButton.Visible = 'off';
                    %app.ReturnButton.Enable = 'off';
                end                

                % Cambi color botó tutorial:
                app.TutorialButton.BackgroundColor = [0.9600    0.9600    0.9600];
                        % Normal: [0.9600    0.9600    0.9600]
                        % Tronja fosc: [0.8500 0.3250 0.0980]
                        % Tronja clar: [0.9290 0.6940 0.1250]                


                % Correccio que no surti ultim frame
                imshow("Apps\app_nova_interfaz_nova\Imatges_utilitat\imatge_background_clar.png", 'Parent', app.UIAxes2);


            end


%             app.ReturnButton.Visible = 'on';

            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)        


        end

        % Button pushed function: InfoIcon
        function InfoIconPushed(app, event)
            % Info Tutorial 1


            % _Funció mostrar video_
            video_ruta =  "Images_resources\Tutorial_videos\WorMe_1_Scissors_final.mp4";
            
            if isfile(video_ruta)
                %app_interf_video_demostratiu(app, video_ruta)
                winopen(video_ruta);
            else
                print("Ruta vídeo no especificada.")
            end
          

            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)                


        end

        % Button pushed function: CloseVideoButton
        function CloseVideoButtonPushed(app, event)
            % Close video button

            % Definim la variable per a sortir del while.
            app.stop_video = true;

            % Correccio que no surti ultim frame
            imshow("Apps\app_nova_interfaz_nova\Imatges_utilitat\imatge_background_fosc.png", 'Parent', app.UIAxes2);

            app.Image.Visible = 'on';
            app.UIAxes2.Visible = 'off';
            app.CloseVideoButton.Visible = 'off';            


            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)     
            
        end

        % Button pushed function: InfoIcon_Draw
        function InfoIcon_DrawButtonPushed(app, event)
            % Info video Draw

            % _Funció mostrar video_
            video_ruta =  "Images_resources\Tutorial_videos\WorMe_Draw.mp4";

            if isfile(video_ruta)
                %app_interf_video_demostratiu(app, video_ruta)
                winopen(video_ruta);
            else
                print("Ruta vídeo no especificada.")
            end

            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)                
            
        end

        % Button pushed function: InfoIcon_Brush
        function InfoIcon_BrushButtonPushed(app, event)
            % Brush info button
            

            % _Funció mostrar video_
            video_ruta =  "Images_resources\Tutorial_videos\WorMe_Brush.mp4";

            if isfile(video_ruta)
                %app_interf_video_demostratiu(app, video_ruta)
                winopen(video_ruta);
            else
                print("Ruta vídeo no especificada.")
            end            

            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)


        end

        % Button pushed function: ExportIAdataButton
        function ExportIAdataButtonPushed(app, event)
            % Exporta les dades per a guardar per a models de Deep Learning

            app_interf_boto_exportia(app)


        end

        % Button pushed function: InfoIcon_2
        function InfoIcon_2ButtonPushed(app, event)
            % Botó info sobre exportacio dades sense correcció

            % Descripció sobre la correcció per selecció manual
            app_interf_boto_info_correccio(app)


        end

        % Button pushed function: InfoIcon_3
        function InfoIcon_3ButtonPushed(app, event)
            
            % Botó info exportació dades IA.
            app_interf_boto_info_exportIA(app)

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Botó exit
            app_interf_boto_exit()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Length')
            zlabel(app.UIAxes, 'Z')
            app.posImgTextArea = uitextarea(app.UIFigure);
            app.posImgTextArea.Editable = 'off';
            app.posImgTextArea.Visible = 'off';
            app.posImgTextArea.Value = {'posImg'};
            app.posLabelTextArea = uitextarea(app.UIFigure);
            app.posLabelTextArea.Editable = 'off';
            app.posLabelTextArea.Visible = 'off';
            app.posLabelTextArea.Value = {'posLabel'};
            app.posFigTextArea = uitextarea(app.UIFigure);
            app.posFigTextArea.Editable = 'off';
            app.posFigTextArea.Visible = 'off';
            app.posFigTextArea.Value = {'posFig'};
            app.posButtonsTextArea = uitextarea(app.UIFigure);
            app.posButtonsTextArea.Editable = 'off';
            app.posButtonsTextArea.Visible = 'off';
            app.posButtonsTextArea.Value = {'posButtons'};
            app.posListboxTextArea = uitextarea(app.UIFigure);
            app.posListboxTextArea.Editable = 'off';
            app.posListboxTextArea.Visible = 'off';
            app.posListboxTextArea.Value = {'posListbox'};
            app.StartupFcnContTextArea = uitextarea(app.UIFigure);
            app.StartupFcnContTextArea.Editable = 'off';
            app.StartupFcnContTextArea.Visible = 'off';
            app.StartupFcnContTextArea.Value = {'StartupFcnCont'};
            app.posImatgesTextArea = uitextarea(app.UIFigure);
            app.posImatgesTextArea.Editable = 'off';
            app.posImatgesTextArea.Visible = 'off';
            app.posImatgesTextArea.Value = {'posImatges'};
            app.posCheckboxTextArea = uitextarea(app.UIFigure);
            app.posCheckboxTextArea.Editable = 'off';
            app.posCheckboxTextArea.Visible = 'off';
            app.posCheckboxTextArea.Value = {'posCheckbox'};
            app.Thick_Button = uibutton(app.UIFigure, 'push');
            app.Thick_Button.ButtonPushedFcn = createCallbackFcn(app, @Thick_ButtonPushed, true);
            app.Thick_Button.Icon = 'greentick.png';
            app.Thick_Button.Tooltip = {'Select the current object as worm'; ''; 'keyshort: (d)'};
            app.Thick_Button.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Cross_Button = uibutton(app.UIFigure, 'push');
            app.Cross_Button.ButtonPushedFcn = createCallbackFcn(app, @Cross_ButtonPushed, true);
            app.Cross_Button.Icon = 'cross.png';
            app.Cross_Button.Tooltip = {'Select the current object as a non-worm'; ''; 'keyshort: (a)'};
            app.Cross_Button.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.Visible = 'off';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.FiltersLabel = uilabel(app.UIFigure);
            app.FiltersLabel.Text = 'Filters';
            app.ModificacionsguardadesListBox = uilistbox(app.UIFigure);
            app.ModificacionsguardadesListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionsguardadesListBoxValueChanged, true);
            app.ModificacionsguardadesListBox.Enable = 'off';
            app.ModificacionstemporalsListBoxLabel = uilabel(app.UIFigure);
            app.ModificacionstemporalsListBoxLabel.Text = {'Modificacions'; 'temporals'};
            app.ModificacionstemporalsListBox = uilistbox(app.UIFigure);
            app.ModificacionstemporalsListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionstemporalsListBoxValueChanged, true);
            app.ModificacionstemporalsListBox.Enable = 'off';
            app.ModificacinsguardadesListBoxLabel = uilabel(app.UIFigure);
            app.ModificacinsguardadesListBoxLabel.Text = {'Modificacións'; 'guardades'};
            app.img_contLabel = uilabel(app.UIFigure);
            app.img_contLabel.Text = 'img_cont';
            app.img_tot_nLabel = uilabel(app.UIFigure);
            app.img_tot_nLabel.Text = 'img_tot_n';
            app.CitationLabel = uilabel(app.UIFigure);
            app.CitationLabel.Visible = 'off';
            app.CitationLabel.Text = 'Please, cite us in: ("WorMe: C.  elegans morphological caracterization tool user-designed", 2022, Nature, DOI: 123.456)';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Icon = 'return_icon.png';
            app.ReturnButton.Tooltip = {'Undo the last selection.'; ''; 'keyshort: (w)'};
            app.ReturnButton.Text = 'Undo';
            app.posHyperlinkTextArea = uitextarea(app.UIFigure);
            app.posHyperlinkTextArea.Editable = 'off';
            app.posHyperlinkTextArea.Visible = 'off';
            app.posHyperlinkTextArea.Value = {'posHyperlink'};
            app.sizeFontTextArea = uitextarea(app.UIFigure);
            app.sizeFontTextArea.Editable = 'off';
            app.sizeFontTextArea.Visible = 'off';
            app.sizeFontTextArea.Value = {'sizeFont'};
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://www.springer.com/journal/11540/';
            app.Hyperlink.Visible = 'off';
            app.img_original_nomLabel = uilabel(app.UIFigure);
            app.img_original_nomLabel.Text = 'img_original_nom';
            app.finalitzat_contLabel = uilabel(app.UIFigure);
            app.finalitzat_contLabel.Text = 'finalitzat_cont';
            app.n_objecteLabel = uilabel(app.UIFigure);
            app.n_objecteLabel.Text = 'n_objecte';
            app.OriginalButton = uibutton(app.UIFigure, 'state');
            app.OriginalButton.ValueChangedFcn = createCallbackFcn(app, @OriginalButtonValueChanged, true);
            app.OriginalButton.Tooltip = {'keyshort: (s)'};
            app.OriginalButton.Text = 'Original';
            app.posStateButtonsTextArea = uitextarea(app.UIFigure);
            app.posStateButtonsTextArea.Editable = 'off';
            app.posStateButtonsTextArea.Visible = 'off';
            app.posStateButtonsTextArea.Value = {'posStateButton'};
            app.MaxCircularityButton = uibutton(app.UIFigure, 'state');
            app.MaxCircularityButton.ValueChangedFcn = createCallbackFcn(app, @MaxCircularityButtonValueChanged, true);
            app.MaxCircularityButton.Text = {'Max '; 'Circularity'};
            app.SpinnerRound = uispinner(app.UIFigure);
            app.SpinnerRound.Step = 0.1;
            app.SpinnerRound.ValueChangedFcn = createCallbackFcn(app, @SpinnerRoundValueChanged, true);
            app.SpinnerRound.Value = 1;
            app.posSpinner = uitextarea(app.UIFigure);
            app.posSpinner.Editable = 'off';
            app.posSpinner.Visible = 'off';
            app.posSpinner.Value = {'posSpinner'};
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Icon = 'Fletxa_apps_r.png';
            app.RightButton.Tooltip = {'Go to the forward image.'};
            app.RightButton.Text = '';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Icon = 'Fletxa_apps_l.png';
            app.LeftButton.Tooltip = {'Go to the previous image.'};
            app.LeftButton.Text = '';
            app.n_valLabel = uilabel(app.UIFigure);
            app.n_valLabel.Text = 'n_val';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n';
            app.meanLabel = uilabel(app.UIFigure);
            app.meanLabel.Text = 'mean';
            app.mean_valLabel = uilabel(app.UIFigure);
            app.mean_valLabel.Text = 'mean_val';
            app.stdLabel = uilabel(app.UIFigure);
            app.stdLabel.Text = 'std';
            app.std_valLabel = uilabel(app.UIFigure);
            app.std_valLabel.Text = 'std_val';
            app.minLabel = uilabel(app.UIFigure);
            app.minLabel.Text = 'min';
            app.min_valLabel = uilabel(app.UIFigure);
            app.min_valLabel.Text = 'min_val';
            app.maxLabel = uilabel(app.UIFigure);
            app.maxLabel.Text = 'max';
            app.max_valLabel = uilabel(app.UIFigure);
            app.max_valLabel.Text = 'max_val';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Icon = 'excel_icono.png';
            app.ExportButton.Text = 'Export';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'icon_out.png';
            app.ExitButton.Text = 'Exit';
            app.posUIAxes = uitextarea(app.UIFigure);
            app.posUIAxes.Editable = 'off';
            app.posUIAxes.Visible = 'off';
            app.posUIAxes.Value = {'posUIAxes'};
            app.ObjectnLabel = uilabel(app.UIFigure);
            app.ObjectnLabel.Text = 'Object nº:';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image:';
            app.ofLabel = uilabel(app.UIFigure);
            app.ofLabel.Text = 'of';
            app.FolderLabel = uilabel(app.UIFigure);
            app.FolderLabel.Text = 'Folder:';
            app.dir_img_orig_sLabel = uilabel(app.UIFigure);
            app.dir_img_orig_sLabel.Text = 'dir_img_orig_s';
            app.postextareaTextArea = uitextarea(app.UIFigure);
            app.postextareaTextArea.Editable = 'off';
            app.postextareaTextArea.Visible = 'off';
            app.postextareaTextArea.Value = {'posTextArea'};
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Visible = 'off';
            app.MinLengthStateButton = uibutton(app.UIFigure, 'state');
            app.MinLengthStateButton.ValueChangedFcn = createCallbackFcn(app, @MinLengthStateButtonValueChanged, true);
            app.MinLengthStateButton.Tooltip = {'Minimal length filter.'; 'Note: the lengh filter is aproximated to the real of the object.'};
            app.MinLengthStateButton.Text = 'Min length';
            app.MinWidthStateButton = uibutton(app.UIFigure, 'state');
            app.MinWidthStateButton.ValueChangedFcn = createCallbackFcn(app, @MinWidthStateButtonValueChanged, true);
            app.MinWidthStateButton.Text = 'Min area';
            app.CutButton = uibutton(app.UIFigure, 'push');
            app.CutButton.ButtonPushedFcn = createCallbackFcn(app, @CutButtonPushed, true);
            app.CutButton.Icon = 'scissors_icon.png';
            app.CutButton.Tooltip = {'Scisors tool:'; 'Tool for to cut two or more joined worms.'; ''; 'shortkey: (q)'};
            app.CutButton.Text = '';
            app.DrawLineButton = uibutton(app.UIFigure, 'push');
            app.DrawLineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawLineButtonPushed, true);
            app.DrawLineButton.Icon = 'click_image_bo_punts.png';
            app.DrawLineButton.Tooltip = {'Draw tool:'; 'Tool for to draw the line of the worm, in order to obtain the length of it. '; 'A new object will be created, which will be automatically accepted, and may be deleted.'; ''; 'shortkey: (e)'};
            app.DrawLineButton.Text = '';
            app.ToolsLabel = uilabel(app.UIFigure);
            app.ToolsLabel.Text = 'Tools';
            app.SelectionLabel = uilabel(app.UIFigure);
            app.SelectionLabel.Text = 'Selection';
            app.MinWidthTextArea = uieditfield(app.UIFigure, 'numeric');
            app.MinWidthTextArea.Limits = [0 Inf];
            app.MinWidthTextArea.ValueChangedFcn = createCallbackFcn(app, @MinWidthTextAreaValueChanged, true);
            app.MinLengthTextArea = uieditfield(app.UIFigure, 'numeric');
            app.MinLengthTextArea.Limits = [0 Inf];
            app.MinLengthTextArea.ValueChangedFcn = createCallbackFcn(app, @MinLengthTextAreaValueChanged, true);
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Visible = 'off';
            app.WLabel.Text = 'W';
            app.LastmodifLabel = uilabel(app.UIFigure);
            app.LastmodifLabel.Text = 'Last modif';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.PintarButton = uibutton(app.UIFigure, 'push');
            app.PintarButton.ButtonPushedFcn = createCallbackFcn(app, @PintarButtonPushed, true);
            app.PintarButton.Icon = 'Pintar_icon_3.png';
            app.PintarButton.Tooltip = {'Elongate tool:'; 'Tool for to elongate the selection of a worm. Useful for to elongate the tail or head of it, or for to improve the image object.'; ''; 'shortkey: (c)'; ''};
            app.PintarButton.Text = '';
            app.LupaButton = uibutton(app.UIFigure, 'push');
            app.LupaButton.ButtonPushedFcn = createCallbackFcn(app, @LupaButtonPushed, true);
            app.LupaButton.Icon = 'loupe.png';
            app.LupaButton.Text = '';
            app.LupaReturnButton = uibutton(app.UIFigure, 'push');
            app.LupaReturnButton.ButtonPushedFcn = createCallbackFcn(app, @LupaReturnButtonPushed, true);
            app.LupaReturnButton.Icon = 'loupe_feltcha_bo.png';
            app.LupaReturnButton.Visible = 'off';
            app.LupaReturnButton.Text = '';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = 'Finished_WM.png';
            app.NotFinishedImagesLabel = uilabel(app.UIFigure);
            app.NotFinishedImagesLabel.Visible = 'off';
            app.NotFinishedImagesLabel.Text = 'You have not already finished the selection of all the selected images.';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Visible = 'off';
            app.SLabel.Text = 'S';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Visible = 'off';
            app.RLabel.Text = 'R';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Visible = 'off';
            app.FLabel.Text = 'F';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Visible = 'off';
            app.CLabel.Text = 'C';
            app.TutorialButton = uibutton(app.UIFigure, 'state');
            app.TutorialButton.ValueChangedFcn = createCallbackFcn(app, @TutorialButtonValueChanged, true);
            app.TutorialButton.Text = 'Tutorial';
            app.QLabel = uilabel(app.UIFigure);
            app.QLabel.Visible = 'off';
            app.QLabel.Text = 'Q';
            app.InfoIcon = uibutton(app.UIFigure, 'push');
            app.InfoIcon.ButtonPushedFcn = createCallbackFcn(app, @InfoIconPushed, true);
            app.InfoIcon.Icon = 'info_icon.png';
            app.InfoIcon.Visible = 'off';
            app.InfoIcon.Text = '';
            app.CloseVideoButton = uibutton(app.UIFigure, 'push');
            app.CloseVideoButton.ButtonPushedFcn = createCallbackFcn(app, @CloseVideoButtonPushed, true);
            app.CloseVideoButton.Icon = 'close_video_2.png';
            app.CloseVideoButton.Visible = 'off';
            app.CloseVideoButton.Text = '';
            app.InfoIcon_Draw = uibutton(app.UIFigure, 'push');
            app.InfoIcon_Draw.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_DrawButtonPushed, true);
            app.InfoIcon_Draw.Icon = 'info_icon.png';
            app.InfoIcon_Draw.Visible = 'off';
            app.InfoIcon_Draw.Text = '';
            app.InfoIcon_Brush = uibutton(app.UIFigure, 'push');
            app.InfoIcon_Brush.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_BrushButtonPushed, true);
            app.InfoIcon_Brush.Icon = 'info_icon.png';
            app.InfoIcon_Brush.Visible = 'off';
            app.InfoIcon_Brush.Text = '';
            app.ExportIAdataButton = uibutton(app.UIFigure, 'push');
            app.ExportIAdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportIAdataButtonPushed, true);
            app.ExportIAdataButton.Icon = 'BW_celegans_3.png';
            app.ExportIAdataButton.Tooltip = {'Exports the binary and label images, and also the Pascal VOC files of the selection.'};
            app.ExportIAdataButton.Text = {'Export'; 'graphic'; 'data'};
            app.ImageLabel_2 = uilabel(app.UIFigure);
            app.ImageLabel_2.Text = 'Image:';
            app.ExportnoncorrectedmanualerrordataCheckBox = uicheckbox(app.UIFigure);
            app.ExportnoncorrectedmanualerrordataCheckBox.Text = 'Export manual error corrected data';
            app.InfoIcon_2 = uibutton(app.UIFigure, 'push');
            app.InfoIcon_2.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_2ButtonPushed, true);
            app.InfoIcon_2.Icon = 'info_icon.png';
            app.InfoIcon_2.Text = '';
            app.InfoIcon_3 = uibutton(app.UIFigure, 'push');
            app.InfoIcon_3.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_3ButtonPushed, true);
            app.InfoIcon_3.Icon = 'info_icon.png';
            app.InfoIcon_3.Text = '';
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Visible = 'off';
            app.ELabel.Text = 'E';
            app.spaceLabel = uilabel(app.UIFigure);
            app.spaceLabel.Visible = 'off';
            app.spaceLabel.Text = 'space';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = 'reloj_arena.jpg';
            app.n_yesLabel = uilabel(app.UIFigure);
            app.n_yesLabel.Text = '0';
            app.n_noLabel = uilabel(app.UIFigure);
            app.n_noLabel.Text = '0';
            app.acceptedLabel = uilabel(app.UIFigure);
            app.acceptedLabel.Text = 'accepted';
            app.refusedLabel = uilabel(app.UIFigure);
            app.refusedLabel.Text = 'refused';
            app.n_yesLabel2 = uilabel(app.UIFigure);
            app.n_yesLabel2.Text = '0';
            app.n_noLabel2 = uilabel(app.UIFigure);
            app.n_noLabel2.Text = '0';
            app.Thick_Button_little = uiimage(app.UIFigure);
            app.Thick_Button_little.ImageSource = 'greentick.png';
            app.Cross_Button_little = uiimage(app.UIFigure);
            app.Cross_Button_little.ImageSource = 'cross.png';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Visible = 'off';
            app.ALabel.Text = 'A';
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Visible = 'off';
            app.DLabel.Text = 'D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_nova_interfaz_nova_funcions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end