classdef app_processament_imatge_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        InfoIcon_imadjust              matlab.ui.control.Button
        InfoIcon                       matlab.ui.control.Button
        returnButton                   matlab.ui.control.Button
        FiltersLabel                   matlab.ui.control.Label
        BinarizebyvalueButton          matlab.ui.control.Button
        BinarizeSlider                 matlab.ui.control.Slider
        flipButton                     matlab.ui.control.Button
        imadjust_selectButton          matlab.ui.control.Button
        Spinner_3                      matlab.ui.control.Spinner
        CloseButton                    matlab.ui.control.Button
        Spinner_2                      matlab.ui.control.Spinner
        OpenButton                     matlab.ui.control.Button
        Spinner                        matlab.ui.control.Spinner
        imfillButton                   matlab.ui.control.Button
        imclearborderButton            matlab.ui.control.Button
        AreaOpenButton                 matlab.ui.control.Button
        BinarizeButton                 matlab.ui.control.Button
        imadjustButton                 matlab.ui.control.Button
        Im2greyButton                  matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        ImageprocessingpanelLabel      matlab.ui.control.Label
        RequirmentAnalysisText         matlab.ui.control.Label
        AnalyseButton                  matlab.ui.control.Button
        Button_11                      matlab.ui.control.Button
        Button_12                      matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        ReturnLupa                     matlab.ui.control.Button
        ModifiedButton                 matlab.ui.control.StateButton
        MaskedButton                   matlab.ui.control.StateButton
        OriginalButton                 matlab.ui.control.StateButton
        EsqueletonitzarButton          matlab.ui.control.StateButton
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ZoomButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        ButtonVideoTutorial            matlab.ui.control.Button
        ProcessingsLabel               matlab.ui.control.Label
        GuardarmodificaciButton        matlab.ui.control.Button
        RenameButton_2                 matlab.ui.control.Button
        TutorialButton                 matlab.ui.control.StateButton
        ImportButton                   matlab.ui.control.Button
        DelAll                         matlab.ui.control.Button
        ImagepropertiesLabel           matlab.ui.control.Label
        ImageLabel                     matlab.ui.control.Label
        num_objectes_binarisLabel      matlab.ui.control.Label
        NobjectsLabel                  matlab.ui.control.Label
        carpeta_imatges_textLabel      matlab.ui.control.Label
        FolderLabel                    matlab.ui.control.Label
        RenameButton                   matlab.ui.control.Button
        NovamodificaciButton           matlab.ui.control.Button
        ModificacionstemporalsListBox  matlab.ui.control.ListBox
        TemporalLabel                  matlab.ui.control.Label
        ModificacionsguardadesListBox  matlab.ui.control.ListBox
        SavedmodificationsLabel        matlab.ui.control.Label
        ImatgeLabel                    matlab.ui.control.Label
        ImagenLabel                    matlab.ui.control.Label
        Button_14                      matlab.ui.control.Button
        ModificarButton                matlab.ui.control.Button
        contador_numimLabel            matlab.ui.control.Label
        Button_13                      matlab.ui.control.Button
        Modificacions_actuals          matlab.ui.control.TextArea
        ModificationsLabel             matlab.ui.control.Label
        ForegroundPolarityDropDown     matlab.ui.control.DropDown
        ForegroundPolarityDropDownLabel  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Spinner_4                      matlab.ui.control.Spinner
        regionimopenButton             matlab.ui.control.Button
        edgeapproxcannyButton          matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        theFiles_arxiu = []; % Arxiu theFiles (estructura dades imatges) provinent variable inicial.

        vapp_carpeta_output = []; % Carpeta resultats.

        vapp_ruta_img_origin = []; % Ruta de la imatge original (cambiant)

        vapp_tancament_loop = []; % Variable per tancar app (aquesta és sempre en loop per poder re-dimensionar)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, theFiles, carpeta_output)
            
            % ## A FER ##
            % - Desactivar botóns entre imatges sense i binaritzades.
            % - 
                
            % _VARIABLES_
            %   theFiles : Estructura de dades de les imatges (name, folder, date, ...). Utilitari per a l'obtenció de les imatges.
            %
            %   carpeta_output : ruta carpeta resultats.
            %                     Guardat en: 
            %              ex (using MATLAB):  "C:\Users\jllobet\Desktop\Length determination v2_15\Results_out\20230124_1625_C_48"
            %              ex (compiled in Windows): 'C:\Users\Josep TOSHIBA\Documents\WorMe_Length_Results Results_out\20230407_1813_control'


            % __Tipologia dels processaments d'imatge__
            %
            % RGB a Grey
            % ----------
            % "Im2gray"
            % 
            % Grey Modificació
            % ----------------
            % "imadjust"
            % 
            % Grey a Binaria
            % --------------
            % "Imbinarize_adaptative_Foreground_dark"
            %  
            % Binaria Modificació
            % -------------------
            % "bwareaopen(valor)"
            % "Imclearborder"
            % "imfill"
            % "open_disc(valor)"
            % "close_disc(valor)"


            % Exempe escriptura i lectura de patróns de processament
            % % Variables
            % % field_set : carpeta on es guarda
            % field_set = strcat(pwd, "\asdf.txt");
            % txt_seg(field_set, "Im2gray", ";") % seguiment config.
            % % Lectura de patróns
            % [array_sortida_modifs, ~] = llegir_text_delimitadors(field_set, ";");
            % array_sortida_modifs
            % % delete(field_set)
            % [array_dades_2_c, array_dades_1_c] = lectura_array_col2(array_sortida_modifs)


            % ___Definició contadors variables inicials___
            % Carpeta de les imatges
            carpeta_imatges_t = split(theFiles(1).folder, "\");
            app.carpeta_imatges_textLabel.Text = carpeta_imatges_t{end};
            
            % Carpeta results
            app.vapp_carpeta_output = carpeta_output;

            % theFiles arxiu:
            app.theFiles_arxiu = theFiles;
            % Arxiu theFiles amb estructura de les dades, provinent de la
            % selecció de les mateixes.


            % ____Lectura imatge per carpeta____
            
            % LECTURA IMATGES .jpg i .png de la carpeta:
            % [theFiles] = lectura_imatges_carpeta_estr(carpeta_imatges);
            
            
            % Llargada total imatges
            %llargada_ims = length(theFiles); % Contador imatges totals
            
            % __Determinació escala__
            % Obtencio 1a imatge
            baseFileName = theFiles(1).name;
            %disp(baseFileName)
            fullFileName = fullfile(theFiles(1).folder, baseFileName);
            %imageArray_escala = imread(fullFileName); % Imatge inicial
            imatge_original = imread(fullFileName);
            app.Image.ImageSource = imatge_original;

            % Grafiquem la imatge original en petit
            app.Image3.ImageSource = imatge_original;               

            % _Guardem ruta de la imatge original_
            app.vapp_ruta_img_origin = fullFileName;


            % ____Definim les modificacions____
            % Definim la Box de Modificacions, a partir dels documents existents:
            
            % Llegir arxius Modificacions temporals
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
            [llistat_string_im_temp] = llegir_arxius_tipologia(myFolder_input, ".txt");          
            app.ModificacionstemporalsListBox.Items = llistat_string_im_temp;

            % Llegir arxius Modificacions guardades
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\"; 
            [llistat_string_im_guard] = llegir_arxius_tipologia(myFolder_input, ".txt");
            app.ModificacionsguardadesListBox.Items = llistat_string_im_guard;


            % _Preselecció modificació_
            % Fer que es guardi el nom de l'arxiu, de manera que la proxima
            % vegada que s'executi el programa, sigui aquesta modificació
            % la que es pre-seleccioni de foroma automàtica.
            % _Obtenció (o no) valor de la carpeat predefinit
            predefined_file_input = "Results_out\Internal_code_files\predefined_modif.txt";
            
            if isfile(predefined_file_input)
                % Si existeix un arxiu de text que contingui l'escala
                predef_modif_llegida = llegir_text_delimitadors(predefined_file_input, ";");
                predef_modif = predef_modif_llegida{2}; clear predef_modif_llegida  % es defineix la carpeat predefinida
                
                if ismember(predef_modif, app.ModificacionstemporalsListBox.Items)
                    app.ModificacionstemporalsListBox.Value = predef_modif;
                    app.ModificacionsguardadesListBox.Value = {};
                elseif ismember(predef_modif, app.ModificacionsguardadesListBox.Items)
                    app.ModificacionstemporalsListBox.Value = {};
                    app.ModificacionsguardadesListBox.Value = predef_modif;
                end


            % _No modificació preseleccionada_
            else
                
                % Si les modificacions gaurdades són vuides
                if isempty(app.ModificacionsguardadesListBox.Items)
                    
                    % Si també ho és vuida les modificacions temporals
                    if isempty(app.ModificacionstemporalsListBox.Items)
                        % Fem que es faci una nova modificació
                        % app.NovamodificaciButtonPushed;
    
                        % Es crea un arxiu nou de zero.
                        nom_arxiu_guardar = strcat("Results_out\Internal_code_files\Image_processing_settings\temporals\Modif_img_01.txt");
                        txt_seg(nom_arxiu_guardar, "", ";", "nou", "blanc") % seguiment config.
    
                        % _Actualitzem els ListBox_
                        % Modifs Temporals
                        myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
                        [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, ".txt");
                        % Actualitzem el listbox
                        app.ModificacionstemporalsListBox.Items = llistat_string_im;       
                        
                        
                        % Seleccionar el que té el nom cambiat:
                        app.ModificacionstemporalsListBox.Value = llistat_string_im(end);
                        
                    % Si hi ha modificacións temporals
                    else
                        % Sinó, que es seleccioni les modificacions
                        app.ModificacionsguardadesListBox.Items = llistat_string_im_temp;                    
                        app.ModificacionstemporalsListBox.Value = llistat_string_im_temp(1);
                    end
    
                % Si sí que hi ha modificacións guardades
                else
                    app.ModificacionsguardadesListBox.Items = llistat_string_im_guard;
                    app.ModificacionsguardadesListBox.Value = llistat_string_im_guard(end);
    
                    % Si hi ha modificacions temporals
                    if ~isempty(app.ModificacionstemporalsListBox.Items)
                        app.ModificacionstemporalsListBox.Value = {}; % llistat_string_im_temp(1);  
                    end
                    
                    
                end

            end


            % NOTES
            % - arquitectura modificacions -
            % Fer que les modificacions guardades no es puguin modificar,
            % sino que per a ser modificades d'hagin de copiar a
            % modificacions temporals, i tornar a ser guardades a
            % Modificacions guardades. D'aquesta manera s'evita que la
            % modificació sigui alterada de maner fàcil, sino que si es vol
            % alterar s'hagi de tornar a copiar i tornar a borrar, o
            % guardar amb un altre nom, lo qual promou la permanència de
            % les configuracións primeres.
            
            % - guardar imatges - 
            % Les imatges seràn guardades paral·lelament amb les
            % modificacións, en la mateixa carpeta (carpeta imatges, ja
            % siguin o no temporals). D'aquesta manera si s'agafa una u
            % altre modificació, es veurà amb la imatge amb que s'ha fet, i
            % si aquesta imatge es continua modificant, es veurà llavors en
            % tal modificació.
            
            
            % Definició variables i contadors
            
            % Valor contador numero imatge
            app.contador_numimLabel.Text = "0";

            
            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


            % DESACTIVACIÓ/ACTIVACIÓ BOTÓNS MODIFICACIÓ IMATGE SEGONS
            % TIPOLOGIA IMATGE
            
            if ~isempty(app.ModificacionsguardadesListBox.Value)
                
                % Descativació dels botons
                app.Im2greyButton.Enable = 'off';
                % gray
                app.imadjustButton.Enable = 'off';
                app.BinarizeButton.Enable = 'off';
                                
                % binary
                app.AreaOpenButton.Enable = 'off';
                app.imclearborderButton.Enable = 'off';
                app.imfillButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.Spinner.Enable = 'off';
                app.Spinner_2.Enable = 'off';
                app.Spinner_3.Enable = 'off';
                app.flipButton.Enable = 'off';
                app.edgeapproxcannyButton.Enable = 'off';
                app.returnButton.Enable = 'off';
                app.imadjust_selectButton.Enable = 'off';                

            end
            
            
            % __Activació/Desactivació altres botóns inicials__

            % Visió botons
            app.Button_12.Visible = 'off';
            app.Button_11.Visible = 'on';

            app.Image2.Visible = 'off';
            app.ModificarButton.Visible = 'off';
            app.num_objectes_binarisLabel.Text = "Not determined";
            
            % Contador numero imatge
            app.contador_numimLabel.Text = "1";
            app.Button_12.Visible = "off";
            
            
            % Text Requeriments
            app.RequirmentAnalysisText.Visible = 'off';


            % __Iniciació inicial del programa__
            % En aquest s'executa el tutorial i modificacions exemple,
            % entre d'altres.

            % _Iniciació tutorial_
            % D'inici que s'executi el tutorial.
            % ÑÑÑ app.TutorialButtonValueChanged
            % ÇÇÇ: Que el valor del tutorial es guardi, de manera que no
            % sempre surti com a inicial.

            
            % _Copia modificacions exemple_
            % Es copien les modificacions de app_processament_imatge\Internal code files\Modifications_examples a 
            file_porta_modifs = "Results_out\Internal_code_files\first_modifications.txt"; % Arxiu porta copia modifs exemple
            %waitfor(msgbox(file_porta_modifs))
            if ~isfile(file_porta_modifs)
                % Si no hi ha arxiu, significa que es la primera vegada que
                % s'inicia. Es copien les modif guardades temporals.

                % Per cada arxiu exemple, es copia:
                for n_va = 1:6
                    arxiu_copiar = which(strcat("Modif_Example_", string(n_va), ".txt"));
                    % NOTA: el nom de l'arxiu no s'especifica la ruta,
                    % perquè aquest és dins del programa innicial. Donat
                    % que s'ha canviat la ruta del programa, la obtenció
                    % dels arxius definits inicialment en el programa s'ha
                    % de realitzar a partir del seu nom directe, i no del
                    % seu directori, donat que es intern.
                    % Altrament, si no funcionés, es faria un which
                    % d'aquest per obtenir la ruta interna de l'arxiu.
                    directori_nou_copiar = strcat("Results_out\Internal_code_files\Image_processing_settings\Modif_Example_", string(n_va), ".txt");
                    copyfile(arxiu_copiar, directori_nou_copiar)
                end

                % Creem arxiu contador
                write_text_josep(file_porta_modifs, ["first_modif", "true"], ";");

            end


            % __Actualitzar modificacions__
            
            % Llegir arxius Modificacions guardades
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\";
            patro_tipus_image = ".txt";
            [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
            
            app.ModificacionsguardadesListBox.Items = llistat_string_im;
            
            
            % Automatització Drop Roll Modificacions temporals
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
            patro_tipus_image = ".txt";
            [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
                        
            app.ModificacionstemporalsListBox.Items = llistat_string_im;


            % Centrem la GUI
            % ..............
            center_the_app_GUI(app)


            % RE- ESCALAR LA IMATGE
            % ---------------------

            % __Posicions figura i imatges__
            % S'obtenen les posicions inicials, i es guarden
            pos_fig = app.UIFigure.Position;
            pos_img =  app.Image.Position;
            
            % Posicions imatge 2 i 3
            pos_img_3_1 = app.Image3.Position;     
            
            % Posicio imatge rellotge arena
            pos_rellotge = app.Image2.Position;
                        
            
            % __Posicions objectes__

            % Posicions dels objectes en Panel Left
            %h_determin = findobj(app.LeftPanel,'Type','uibutton');
            h_determin = findall(app.LeftPanel);
            array_positions_buttons = [];
            for cada = 1:length(h_determin)
               boto = h_determin(cada); % es defineix el botó
               % guardem les posicions
               if isempty(array_positions_buttons); array_positions_buttons = [boto.Position];
               else array_positions_buttons = [array_positions_buttons; boto.Position]; end
            end
            
            % Posicions dels objectes en Panel Mig
            h_determin_button = findobj(app.CenterPanel,'Type','uibutton');
            array_positions_buttons_centered = [];
            for cada = 1:length(h_determin_button)
               boto_centered = h_determin_button(cada); % es defineix el botó
               % guardem les posicions
               if isempty(array_positions_buttons_centered); array_positions_buttons_centered = [boto_centered.Position];
               else array_positions_buttons_centered = [array_positions_buttons_centered; boto_centered.Position]; end
            end
            

            % Posicions botons estat del mig
            % Posicions dels objectes en Panel Mig
            h_determin_buttonstate = findobj(app.CenterPanel,'Type','uistatebutton');
            array_positions_buttonsstate_centered = [];
            for cada = 1:length(h_determin_buttonstate)
               boto_centered = h_determin_buttonstate(cada); % es defineix el botó
               % guardem les posicions
               if isempty(array_positions_buttonsstate_centered); array_positions_buttonsstate_centered = [boto_centered.Position];
               else array_positions_buttonsstate_centered = [array_positions_buttonsstate_centered; boto_centered.Position]; end
            end            

            
            % _Estat finestra i contadors_
            % S'obté l'estat de la figura, en string.
            string_wind = string(app.UIFigure.WindowState);
            
            % Definició dels contadors d'entrada/sortida
            has_maximized = false;
            has_normaled = false;
            
            % _Entrada del loop_
            while_cond = true;
            while while_cond %app.close_stateLabel.Text ~= "close" % Del Callback UIFigureCloseRequest
                pause(0.001) % Pausa necessaria
                
                % Definim estat finestra
                string_wind = string(app.UIFigure.WindowState);
                
                % Si està maximitzada
                if string_wind == 'maximized' && ~has_maximized
                    pause(1) % Pausa necessaria obrir finestra, i obtenir mesura bona
                    
                    % Per la imatge present:
                    
                    % Definim mesura figura inicial
                    pos_fig2 = app.UIFigure.Position;

                    % Definim nova escala per la funció
                    % rescale_values_img_app
                    pos_img2 = rescale_values_img_app(pos_fig, pos_img, pos_fig2);
                    
                    % Modificació AUTOREFLOW:
                    % Volem que s'ajusti no al complert de la imatge, sino
                    % al complert del quadre, donat que amb autoreflow el
                    % quadre és variant, els tres varien de posicionsde
                    % manera que la estètica de les posicions no es igual
                    % que la inicial. Per això, ajustant la imatge al AMPLE
                    % de les posicions del cuadre central, s'obtindrà la
                    % imatge d'aquesta quan es faci l'escalat:
                    pos_centrp = app.CenterPanel.Position;
                    pos_img2(3) = pos_centrp(3);
                    app.Image.Position = round(pos_img2);
                    drawnow()


                    % Imatges 3_1 i 3_2
                    % Definim la posicio en base a la central de la app, és
                    % a dir, la mitat de la llargada, que serà el que es
                    % sumarà o restarà a la posició x.
                    pos_img_3_1_2 = rescale_values_img_app(pos_fig, pos_img_3_1, pos_fig2);
                    llargada_centre = pos_fig2(3)/2;
                    pos_img_3_1_2(1) = llargada_centre - pos_img_3_1_2(3)*2;

                    
                    app.Image3.Position = round(pos_img_3_1_2);

                    
                    drawnow()
                     
                    % Imatge rellotge arena
                    pos_rellotge_max = pos_rellotge; pos_rellotge_max(1) = pos_fig2(3)/2; pos_rellotge_max(2) = pos_fig2(4)/2; 
                    app.Image2.Position = pos_rellotge_max;

                    
                    % Modificacio botons pim pam
                     array_positions_buttons_new = [];
                     [n_botons, ~] = size(array_positions_buttons);
                     for cada_boto = 1:n_botons
                        pos_boto_old = array_positions_buttons(cada_boto,:); pos_boto_new = pos_boto_old;
                        pos_boto_rescale = rescale_values_img_app(pos_fig, pos_boto_new, pos_fig2);
                        pos_boto_new(2) = pos_boto_rescale(2); pos_boto_new(4) = pos_boto_rescale(4);

                        % Guardem en array
                        if isempty(array_positions_buttons_new); array_positions_buttons_new = [pos_boto_new];
                        else array_positions_buttons_new = [array_positions_buttons_new; pos_boto_new]; end
                     end

                    % Cambiem la posició dels botóns
                    % en base al array definit
                    for cada = 1:length(h_determin)
                       boto = h_determin(cada);
                       boto.Position = array_positions_buttons_new(cada, :);
                    end

                    
                    % Modificacio botons Panell central
                     array_positions_buttons_new_central = [];
                     [n_botons_central, ~] = size(array_positions_buttons_centered);
                     for cada_boto = 1:n_botons_central
                        pos_boto_old_central = array_positions_buttons_centered(cada_boto,:); pos_boto_new = pos_boto_old_central;
                        pos_boto_rescale = rescale_values_img_app(pos_fig, pos_boto_new, pos_fig2);
                        pos_boto_new(2) = pos_boto_rescale(2); pos_boto_new(4) = pos_boto_rescale(4);
                        %pos_boto_new = pos_boto_rescale;

                        % Guardem en array
                        if isempty(array_positions_buttons_new_central); array_positions_buttons_new_central = [pos_boto_new];
                        else array_positions_buttons_new_central = [array_positions_buttons_new_central; pos_boto_new]; end
                     end
                    % Cambiem la posició dels botóns
                    % en base al array definit
                    for cada = 1:length(h_determin_button)
                       boto = h_determin_button(cada);
                       boto.Position = array_positions_buttons_new_central(cada, :);
                    end


                    % Modificacio botons State Panell central
                     array_positions_buttons_new_central_state = [];
                     [n_botons_central, ~] = size(array_positions_buttonsstate_centered);
                     for cada_boto = 1:n_botons_central
                        pos_boto_old_central = array_positions_buttonsstate_centered(cada_boto,:); pos_boto_new = pos_boto_old_central;
                        pos_boto_rescale = rescale_values_img_app(pos_fig, pos_boto_new, pos_fig2);
                        pos_boto_new(2) = pos_boto_rescale(2); pos_boto_new(4) = pos_boto_rescale(4);
                        %pos_boto_new = pos_boto_rescale;

                        % Guardem en array
                        if isempty(array_positions_buttons_new_central_state); array_positions_buttons_new_central_state = [pos_boto_new];
                        else array_positions_buttons_new_central_state = [array_positions_buttons_new_central_state; pos_boto_new]; end
                     end
                    % Cambiem la posició dels botóns
                    % en base al array definit
                    for cada = 1:length(h_determin_buttonstate)
                       boto = h_determin_buttonstate(cada);
                       boto.Position = array_positions_buttons_new_central_state(cada, :);
                    end


                    % Re-definim contadors
                    has_maximized = true;
                    has_normaled = false;
                    
                % Si està normal
                elseif string_wind == 'normal' && ~has_normaled && has_maximized
                    pause(1) % Pausa necessaria tancar finestra, i obtenir mesura bona
                                %  çç editable a fins que no varii la llargada cada 0.1s.
                              
                    % Per la imatge present:
                    % Re-definim les mesures de la imatge segons les inicials.
                    app.Image.Position = pos_img;
                    drawnow()
                    app.Image3.Position = pos_img_3_1;

                    app.Image2.Position = pos_rellotge;

                    % Cambiem els botóns
                    % en base al array definit
                    for cada = 1:length(h_determin)
                       boto = h_determin(cada);
                       boto.Position = array_positions_buttons(cada, :);
                    end
                    
                    % Cambiem botons Centrals
                    for cada = 1:length(h_determin_button)
                       boto = h_determin_button(cada);
                       boto.Position = array_positions_buttons_centered(cada, :);
                    end
                    
                    % Re-definim contadors
                    has_normaled = true;
                    has_maximized = false;
                end
                %disp(string(app.UIFigure.WindowState))
                %disp("Figure")
                %app.UIFigure.Position
                %disp("image")
                %app.Image.Position
                
                %disp("Centerpanel")
                %app.CenterPanel.Position
                
                % Tancament del loop:
                if isequal(app.vapp_tancament_loop, "tancar")
                   while_cond = false; 
                end
                
            end
            % waitfor(msgbox("TANCAAR. El problema es aquii"))
            close force
            %  A PARTIR D'AQUI NO HI POT HABER CODI.
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {541, 541, 541};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {541, 541};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {195, '1x', 254};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: Im2greyButton
        function Im2greyButtonPushed(app, event)
            % Botó im2gray

            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);
            txt_seg(field_set, "Im2gray", ";") % seguiment config.


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            app.Image2.Visible = 'off';        

            
        end

        % Button pushed function: DelAll
        function DelAllPushed(app, event)
            % Delete all temporal modifications


            answer_del = questdlg('Delete ALL the temporal setting?', ...
                    'Delete settings', 'Yes','No', 'Yes');
            % Handle response
            switch answer_del
                case 'Yes'
                    answer_msgebox = 1;
                case 'No'
                    answer_msgebox = 0;
            end 


            % BORRAT DE TOTS
            % Si es diu que sí es vol eliminar, s'executa:
            if answer_msgebox
                
                % Es borren tant arxius com imatges temporals
                patrons_imatge = [".txt", ".png"];
                for patro_n = 1:length(patrons_imatge)
                    patro_tipus_image = patrons_imatge(patro_n);
                    
                    if patro_tipus_image == ".txt"
                        myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
                    elseif patro_tipus_image == ".png"
                        myFolder_input = "Apps\app_processament_imatge\Internal code files\Image processing settings\temporals\settings_imatges\";
                    end
                    [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
                    
                   
                    for cada_arxiu = 1:length(llistat_string_im)
                    
                        string_delete = strcat(myFolder_input, llistat_string_im(cada_arxiu) );
                        delete(string_delete)
                        
                    end
                    
                end
            end


            % Actualitzar els List de modificacions
            % Actualitzem elements:
            [llistat_string_im] = llegir_arxius_tipologia("Results_out\Internal_code_files\Image_processing_settings\temporals\", ".txt");
            app.ModificacionstemporalsListBox.Items = llistat_string_im;
            
%             % Es fa com si s'apretés el botó 'Original'
%             app.ButtonVideoTutorialPushed


            % Si és vuida modificacions temporals, però no guardades:
            if isempty(app.ModificacionstemporalsListBox.Items)
                if ~isempty(app.ModificacionsguardadesListBox.Items)
                    modif_guardades_items = app.ModificacionsguardadesListBox.Items;
                    app.ModificacionsguardadesListBox.Value = modif_guardades_items(1);
                else
                    app.NovamodificaciButtonPushed;
                end
            else

            end


        end

        % Button pushed function: NovamodificaciButton
        function NovamodificaciButtonPushed(app, event)
            % Afegir una modificació 
            
            % Contar nombre d'arxius de modificacions ('Modif_img') amb el mateix nom:
            total_items = [app.ModificacionstemporalsListBox.Items, app.ModificacionsguardadesListBox.Items];
            
            n_Modif_img = 1; % Nombre de noms que contenen 'n_Modif_img'.
            for cada_item = total_items
                item_n = cada_item{1}; item_n_nom = separar_puntfile(item_n);
                if contains(item_n_nom, 'Modif_img'); n_Modif_img = n_Modif_img +1; end % Si conté el nom de modificació
            end


            nom_correcte = true; % Contador while
            while nom_correcte
                
                % Obrir cuadre dialeg per entrada nom arxiu
                prompt = "Nom de l'arxiu:";
                dlgtitle = 'Input';
                dims = [1 35];
                % Definim el input. El nom canvia si el valor és inferior a
                % 10 (01, 02, 03...) per a aconseguir un ordre correcte.
                
                % Generem nom
                if n_Modif_img < 10
                        definput = {char(strcat("Modif_img", "_0", string(n_Modif_img)))};
                    else
                        definput = {char(strcat("Modif_img", "_", string(n_Modif_img)))};                    
                end
                
                % Si coincideix, suma 1
                % Nota: això es fa per si no hi ha Modif_img_01 i si
                % Modif_img_02, no es crei un Modif_img_02 i resulti que sí existeix (bastant engorrós).
                while ismember(strcat(definput, ".txt"), total_items)
                    n_Modif_img = n_Modif_img +1;
                    if n_Modif_img < 10
                        definput = {char(strcat("Modif_img", "_0", string(n_Modif_img)))};
                    else
                        definput = {char(strcat("Modif_img", "_", string(n_Modif_img)))};                    
                    end
                end
                answer_resposta = inputdlg(prompt,dlgtitle,dims,definput);

                % Si el quadre de diàleg no es tanca, o no és vuit:
                if ~isempty(answer_resposta) && answer_resposta ~= ""
                    % Si existeixen arxius amb el mateix nom
                    [llistat_string_im] = [...
                        llegir_arxius_tipologia("Results_out\Internal_code_files\Image_processing_settings\temporals\", ".txt"), ...
                        llegir_arxius_tipologia("Results_out\Internal_code_files\Image_processing_settings\", ".txt")];
                    
                    splitat_arxius = split(llistat_string_im, ".txt");
                    
    
                    si_no_son_iguals = true; % Si aquest no varia, significa que no hi ha arxius iguals.
                    for cada_arxiu = 1:length(llistat_string_im)
                        if splitat_arxius(cada_arxiu) == answer_resposta
                            si_no_son_iguals = false;
                        end
                    end
    
                    % Si existeixen o no
                    if si_no_son_iguals
                        % Es crea l'arxiu nou
                        nom_arxiu_guardar = strcat("Results_out\Internal_code_files\Image_processing_settings\temporals\",answer_resposta,".txt");
                        txt_seg(nom_arxiu_guardar, "", ";", "nou", "blanc") % seguiment config.
                        
                        nom_correcte = false;
                    else
                        waitfor(msgbox('El nom ja existeix', 'Error','error'));
                    end

                    % El nom de l'arxiu topologicament és correcte
                    write_arxiu_txt = true;

                % Si es tanca el quadre de diàleg o es dona un nom vuit:
                else
                    nom_correcte = false;

                    % El nom de l'arxiu topologicament és incorrecte, i no
                    % es guardarà
                    write_arxiu_txt = false;
                end
                
            
            end
            
            
            if write_arxiu_txt
                % Actualització List box
                [llistat_string_im] = llegir_arxius_tipologia("Results_out\Internal_code_files\Image_processing_settings\temporals\", ".txt");
                app.ModificacionstemporalsListBox.Items = llistat_string_im;
            
                resposta_answer_list = strcat(answer_resposta, ".txt");
                app.ModificacionstemporalsListBox.Value = resposta_answer_list;
        
                % Relatius:
                % Desseleccionem Modificacions guardades:
                app.ModificacionsguardadesListBox.Value = {};
                
                app.ImatgeLabel.Text = resposta_answer_list;
    
    
                % __Printar en Modificacions__
                app.Modificacions_actuals.Value = "";
                
                
                % MOSTREM IMATGE ORIGINAL
                fullFileName = app.vapp_ruta_img_origin;
                imatge_original = imread(fullFileName);
                app.Image.ImageSource = imatge_original;
    
    
                [~, tipus_im_modif] = app_proc_obtenir_array_modificacions(app);
                app_proc_desact_act_buttons(app, tipus_im_modif)
            end


            % Desactivació botóns específics:
            app.ModificarButton.Visible = 'off';


        end

        % Value changed function: ModificacionstemporalsListBox
        function ModificacionstemporalsListBoxValueChanged(app, event)
            
            % ACTUALITZACIÓ DE LES MODIFICACIÓNS
            % Agafem el text seleccionat del ListBox:
            value_listbox = app.ModificacionstemporalsListBox.Value;
            app.ImatgeLabel.Text = string(value_listbox);
            
            % Agafem el text seleccionat del ListBox:
            [array_processaments, ~] = app_proc_obtenir_array_modificacions(app);

            if ~isempty(array_processaments)
                app.Modificacions_actuals.Value = array_processaments';
            else
                app.Modificacions_actuals.Value = "";
            end
            
            % Desseleccionar modificacions guardades, al seleccionar
            % modificacions guardades.
            app.ModificacionsguardadesListBox.Value = {};
            app.ModificarButton.Visible = 'off';            
            

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)
            
            
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            % Botó borrar modificació temporal

            % Borra les modificacions temporals, i en crea una de nova en blanc
            
            % Condicional si hi ha valor agafat:
            if ~isempty(app.ModificacionstemporalsListBox.Value)            
                
                answer_del = questdlg('Delete the temporal setting?', ...
                        'Delete settings', 'Yes','No', 'Yes');
                % Handle response
                switch answer_del
                    case 'Yes'
                        answer_msgebox = 1;
                    case 'No'
                        answer_msgebox = 0;
                end 


                valor_modif_perm = separar_puntfile(app.ModificacionstemporalsListBox.Value); %Valor de la modificació permanent.
                
                
                % Si es diu que sí es vol eliminar, s'executa:
                if answer_msgebox
    
                    % Es borren tant arxius com imatges temporals
                    myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
                   
                    string_delete = strcat(myFolder_input, valor_modif_perm, ".txt");
                    delete(string_delete)
                        
                end
    
    
                % Actualitzem elements:
                [llistat_string_im] = llegir_arxius_tipologia("Results_out\Internal_code_files\Image_processing_settings\temporals\", ".txt");
                app.ModificacionstemporalsListBox.Items = llistat_string_im;
                
                % Es fa com si s'apretés el botó 'Original'
                app.OriginalButton
    
    
                % Si és vuida modificacions temporals, però no guardades:
                if isempty(app.ModificacionstemporalsListBox.Items)
                    if ~isempty(app.ModificacionsguardadesListBox.Items)
                        modif_guardades_items = app.ModificacionsguardadesListBox.Items;
                        app.ModificacionsguardadesListBox.Value = modif_guardades_items(1);
                    else
                        app.NovamodificaciButtonPushed;
                    end
                end
    
      
            end


%             % BORRAT DE TOTS
%             % Si es diu que sí es vol eliminar, s'executa:
%             if answer_msgebox
%                 
%                 % Es borren tant arxius com imatges temporals
%                 patrons_imatge = [".txt", ".png"];
%                 for patro_n = 1:length(patrons_imatge)
%                     patro_tipus_image = patrons_imatge(patro_n);
%                     
%                     if patro_tipus_image == ".txt"
%                         myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
%                     elseif patro_tipus_image == ".png"
%                         myFolder_input = "Apps\app_processament_imatge\Internal code files\Image processing settings\temporals\settings_imatges\";
%                     end
%                     [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
%                     
%                    
%                     for cada_arxiu = 1:length(llistat_string_im)
%                     
%                         string_delete = strcat(myFolder_input, llistat_string_im(cada_arxiu) );
%                         delete(string_delete)
%                         
%                     end
%                     
%                 end
%             end

            
        end

        % Button pushed function: GuardarmodificaciButton
        function GuardarmodificaciButtonPushed(app, event)
            % Guarda la configuració temporal
          
           
            arxiu_guardar_copiar = app.ModificacionstemporalsListBox.Value;
            arxiu_borrar = strcat("Results_out\Internal_code_files\Image_processing_settings\temporals\", arxiu_guardar_copiar);
            arxiu_nou_copiar = strcat("Results_out\Internal_code_files\Image_processing_settings\", arxiu_guardar_copiar);
            
            % Mirar si ja existeix. 
            % Si l'objecte no existeix:
            if ~ismember(arxiu_guardar_copiar, app.ModificacionsguardadesListBox.Items)
                movefile(arxiu_borrar, arxiu_nou_copiar);                
                
            else
                msgbox("Name already exist")
            end


            % Actualitzar modificacions
            
            % Llegir arxius Modificacions guardades
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\";
            patro_tipus_image = ".txt";
            [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
            
            app.ModificacionsguardadesListBox.Items = llistat_string_im;
            
            
            % Automatització Drop Roll Modificacions temporals
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
            patro_tipus_image = ".txt";
            [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
                        
            app.ModificacionstemporalsListBox.Items = llistat_string_im;
            
            
        end

        % Value changed function: ModificacionsguardadesListBox
        function ModificacionsguardadesListBoxValueChanged(app, event)
            % Modificacions guardades
            

            % ACTUALITZACIÓ DE LES MODIFICACIONS
            % Agafem el text seleccionat del ListBox:
            value = app.ModificacionsguardadesListBox.Value;
            app.ImatgeLabel.Text = string(value);
            
            % Agafem el text seleccionat del ListBox:
            [array_processaments, ~] = app_proc_obtenir_array_modificacions(app);
            
            if ~isempty(array_processaments)
                app.Modificacions_actuals.Value = array_processaments';
            else
                app.Modificacions_actuals.Value = "";
            end
            

            % Desseleccionar modificacions temporals, al seleccionar
            % modificacions guardades.
            app.ModificacionstemporalsListBox.Value = {};

            app.ModificarButton.Visible = 'on';            


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


            % LECTURA TIPOLOGIA MODIFICACIONS IMATGE            
            % Cambiem botóns, desactivem tots
                % rgb
                app.Im2greyButton.Enable = 'off';
                % gray
                app.imadjustButton.Enable = 'off';
                app.BinarizeButton.Enable = 'off';
                
                % binary
                app.AreaOpenButton.Enable = 'off';
                app.imclearborderButton.Enable = 'off';
                app.imfillButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.Spinner.Enable = 'off';
                app.Spinner_2.Enable = 'off';
                app.Spinner_3.Enable = 'off';

                app.returnButton.Enable = 'off';
                app.flipButton.Enable = 'off';     
                app.imadjust_selectButton.Enable = 'off';


        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Imatge principal


            % Es canvien les posicións de la imatge 1 per la imatge 3, i
            % viceversa.

            pos_img3 = app.Image3.Position;
            pos_img = app.Image.Position;

            app.Image3.Position = pos_img;
            app.Image.Position = pos_img3;


%             figure
%             imshow(app.Image.ImageSource);
%             s = get(0, 'ScreenSize');
%             set(gcf,'Units', 'pixels', 'Position',[0 0 s(3) s(4)])


        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            % Botó selecció dreta

            % Cambia la imatge a la imatge següent de la carpeta.
    
            app.Image2.Visible = 'on';
            

            % __Cambiem contador imatge__
            % Contador imatge
            numero_dreta = str2num(app.contador_numimLabel.Text);
            numero_dreta = numero_dreta + 1;
            
            % definim contador
            app.contador_numimLabel.Text = string(numero_dreta);


            % DEFINICIÓ DE LA IMATGE
            theFiles = app.theFiles_arxiu;
            
            % Obtencio imatge
            baseFileName = theFiles(numero_dreta).name;
            fullFileName = fullfile(theFiles(numero_dreta).folder, baseFileName);
            
            % CAMBIEM RUTA IMATGE
             app.vapp_ruta_img_origin = fullFileName;

           
            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


            % Grafiquem la imatge original nova
            app.Image3.ImageSource = app.vapp_ruta_img_origin;


            % _Aparició/amagat botóns_

            % Llargada imatges
            llargada_ims = length(theFiles); % Contador imatges totals
            
            if numero_dreta > 1
                app.Button_12.Visible = 'on';
                app.Button_11.Visible = 'on';
            end
            if numero_dreta == llargada_ims
                app.Button_12.Visible = 'on';
                app.Button_11.Visible = 'off';                
            end


            app.Image2.Visible = 'off';
            
            
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            % Botó cap a l'esquerra
            
            app.Image2.Visible = 'on';
            

            % __Cambiem contador imatge__
            % Contador imatge
            numero_dreta = str2num(app.contador_numimLabel.Text);
            numero_dreta = numero_dreta -1;
            
            % definim contador
            app.contador_numimLabel.Text = string(numero_dreta);

            % DEFINICIÓ DE LA IMATGE
            % Carpeta de les imatges
            theFiles = app.theFiles_arxiu;
            
            % Obtencio imatge
            baseFileName = theFiles(numero_dreta).name;
            fullFileName = fullfile(theFiles(numero_dreta).folder, baseFileName);
            
            % CAMBIEM RUTA IMATGE
            app.vapp_ruta_img_origin = fullFileName;

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


            % Grafiquem la imatge original nova
            app.Image3.ImageSource = app.vapp_ruta_img_origin;            


            % _Aparició/amagat botóns_
            app.Image2.Visible = 'off';
            if numero_dreta > 1
                app.Button_12.Visible = 'on';
                app.Button_11.Visible = 'on';
            elseif numero_dreta == 1
                app.Button_12.Visible = 'off';
                app.Button_11.Visible = 'on';                
            end

        end

        % Button pushed function: imadjustButton
        function imadjustButtonPushed(app, event)
            % Botó "imadjust"
            
            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);
            txt_seg(field_set, "imadjust", ";")

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            app.Image2.Visible = 'off';


        end

        % Button pushed function: BinarizeButton
        function BinarizeButtonPushed(app, event)
            % Botó binaritzar
            % "Imbinarize_adaptative_Foreground_dark"


            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);
            txt_seg(field_set, "Imbinarize_adaptative_Foreground_dark", ";") % seguiment config.


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            app.Image2.Visible = 'off';


        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'escape'
                    % Tancar tot
                    close force
                    
                case 'return'
                    % Apreta botó analitzar
                    app.AnalyseButtonPushed


            end


        end

        % Button pushed function: AreaOpenButton
        function AreaOpenButtonPushed(app, event)
            % BWareaopen button


            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);

            % Valor del bwareaopen
            valor_modif = app.Spinner.Value;
            
            % Afegim al text la modificació
            text_modific = strcat("bwareaopen(", string(valor_modif), ")");
            txt_seg(field_set, text_modific, ";") % seguiment config.

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            app.Image2.Visible = 'off'; 


        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Button Open


            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);

            % Valor del bwareaopen
            valor_modif = app.Spinner_2.Value;
            
            % Afegim al text la modificació
            text_modific = strcat("open_disc(", string(valor_modif), ")");
            txt_seg(field_set, text_modific, ";") % seguiment config.


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


            app.Image2.Visible = 'off';   


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % Button Close


            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);
            % Valor del bwareaopen
            valor_modif = app.Spinner_3.Value;
            % Afegim al text la modificació
            text_modific = strcat("close_disc(", string(valor_modif), ")");
            txt_seg(field_set, text_modific, ";") % seguiment config.


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


            app.Image2.Visible = 'off';   


        end

        % Button pushed function: imfillButton
        function imfillButtonPushed(app, event)
            % Button imfill

            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);
            % Afegim al text la modificació
            txt_seg(field_set, "imfill", ";") % seguiment config.


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)            


            app.Image2.Visible = 'off'; 


        end

        % Button pushed function: imclearborderButton
        function imclearborderButtonPushed(app, event)
            % Button imclearborder
            

            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);
            % Afegim al text la modificació
            txt_seg(field_set, "Imclearborder", ";") % seguiment config.
           
            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)
            

            app.Image2.Visible = 'off'; 


        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Import modification button
            % 
            % Copia a guardats la modificació des d'un arxiu definit.


            [filename, pathname, porta_uigetfile] = uigetfile({'*.txt'}, 'MultiSelect','on'); % pathname: ruta. filename: cells amb els noms (ex:  {'200120GrowC.txt'}    {'Nota.txt'})


            if porta_uigetfile

                % Si en la selecció hi ha varis arxius:
                if iscell(filename)                
                    % Per cada arxiu seleccionat
                    for n_cada_file = 1:length(filename)
        
                        % Arxiu a copiar
                        arxiu_copiar = strcat(pathname, filename{n_cada_file});
                        directori_nou_copiar = strcat("Results_out\Internal_code_files\Image_processing_settings\", filename{n_cada_file});
        
                        % Si l'objecte no existeix:
                        if ~ismember(arxiu_copiar, app.ModificacionsguardadesListBox.Items)
        
                            % Mirem si l'arxiu és una modificació.
                            [porta_lect_correct] = app_proc_probar_modifs(app, arxiu_copiar);
        
                            if porta_lect_correct
                                % Copiem l'arxiu
                                copyfile(arxiu_copiar, directori_nou_copiar)
                            else
                                msgbox(strcat("The file", filename{n_cada_file}, ", have not correct modifications."))
                            end
                        else
                            msgbox(strcat("The name of the file", filename{n_cada_file}, ", already exist"))
                        end
                    end

                % Si en la selecció sols hi ha un arxiu:
                elseif ischar(filename)
        
                    % Arxiu a copiar
                    arxiu_copiar = strcat(pathname, filename);
                    directori_nou_copiar = strcat("Results_out\Internal_code_files\Image_processing_settings\", filename);
    
                    % Si l'objecte no existeix:
                    if ~ismember(arxiu_copiar, app.ModificacionsguardadesListBox.Items)
    
                        % Mirem si l'arxiu és una modificació.
                        [porta_lect_correct] = app_proc_probar_modifs(app, arxiu_copiar);
    
                        if porta_lect_correct
                            % Copiem l'arxiu
                            copyfile(arxiu_copiar, directori_nou_copiar)
                        else
                            msgbox(strcat("The file", filename, ", have not correct modifications."))
                        end
                    else
                        msgbox(strcat("The name of the file", filename, ", already exist"))
                    end


                % Fallo
                else
                    msgbox("Error in import modification", "Error", "error")
                end


            else
                    disp("UIGetfile cancelled in Import modification")
            end

            % Actualitzar modificacions
            
            % Llegir arxius Modificacions guardades
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\";
            patro_tipus_image = ".txt";
            [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
            
            app.ModificacionsguardadesListBox.Items = llistat_string_im;
            
            
            % Automatització Drop Roll Modificacions temporals
            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
            patro_tipus_image = ".txt";
            [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
                        
            app.ModificacionstemporalsListBox.Items = llistat_string_im;
        end

        % Button pushed function: ButtonVideoTutorial
        function ButtonVideoTutorialPushed(app, event)
            % Botó app video tutorial
            app_tutorial_alfarel()
    
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            % Botó per a modificar una modificació guardada.
            % La torna a posar en modificacións temporals, per a que pugui
            % ser operada.
            
            %item_modificar = app.ModificacionsguardadesListBox.Value
            
            % Simplement obrim els botóns per a que sigui operable la seva
            % modificació.
            
            value_listbox = app.ModificacionsguardadesListBox.Value;
            app.ImatgeLabel.Text = string(value_listbox);
            
            % Llegir arxiu i mostrar en Label Modificacions
            field_set = strcat("Results_out\Internal_code_files\Image_processing_settings\", string(value_listbox));

            % obtneim l'array de la lectura del document de text i el printem
            [array_sortida_modifs, ~] = llegir_text_delimitadors(field_set, ";");


            % LECTURA TIPOLOGIA MODIFICACIONS IMATGE
            cell_cont = array_sortida_modifs(2:end, 2);
            array_processaments = cell2array_josep(cell_cont);

            % Obtenim tipologia de la imatge
            if isempty(array_processaments)
                % La imatge és rgb
                tipus_im_modif = "rgb";
            else
                % Obtenim la última modificació
                ultima_modificacio = array_processaments(end);
                
                % Obtenim el nom d'aquesta
                [tipus_im_modif, ~] = obtencio_tipologia_llistat(ultima_modificacio);
            end
            
            % Cambiem botóns
            app_proc_desact_act_buttons(app, tipus_im_modif) 


            app.ModificarButton.Visible = 'off';
            
        end

        % Button pushed function: ReturnLupa
        function ReturnLupaPushed(app, event)
            % Retornar a la imatge original

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            app.Image2.Visible = 'off';
            app.ReturnLupa.Visible = 'off';
            
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            % Borrar modificacions temporals
            
            % Borra les modificacions temporals, i en crea una de nova en blanc
            

            % Condicional si hi ha valor agafat:
            if ~isempty(app.ModificacionsguardadesListBox.Value)
                
                answer_del = questdlg('Delete the setting?', ...
                        'Delete settings', 'Yes','No', 'No');
                % Handle response
                switch answer_del
                    case 'Yes'
                        answer_msgebox = 1;
                    case 'No'
                        answer_msgebox = 0;
                end 
                
                valor_modif_perm = separar_puntfile(app.ModificacionsguardadesListBox.Value); %Valor de la modificació permanent.
                
                
                % Si es diu que sí es vol eliminar, s'executa:
                if answer_msgebox
                    
                    
                    % Es borren tant arxius com imatges temporals
                    patrons_imatge = [".txt", ".png"];
                    for patro_n = 1:length(patrons_imatge)
                        patro_tipus_image = patrons_imatge(patro_n);
                        
                        if patro_tipus_image == ".txt"
                            myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\";
                        elseif patro_tipus_image == ".png"
                            myFolder_input = "Apps\app_processament_imatge\Internal code files\Image processing settings\settings_imatges\";
                        end
                       
                        string_delete = strcat(myFolder_input, valor_modif_perm, patro_tipus_image);
                        delete(string_delete)
                        
                        
                    end
                    
    %                 % Fem un nou arxiu en blanc
    %                 nom_arxiu_guardar = "Apps\app_processament_imatge\Internal code files\Image processing settings\temporals\setting0.txt";
    %                 txt_seg(nom_arxiu_guardar, "", ";", "nou", "blanc") % seguiment config.
    %     
    %                 
                    % Actualitzem elements:
                    [llistat_string_im] = llegir_arxius_tipologia("Results_out\Internal_code_files\Image_processing_settings\", ".txt");
                    app.ModificacionsguardadesListBox.Items = llistat_string_im;
                    
                    % Es fa com si s'apretés el botó 'Original'
                    app.ButtonVideoTutorialPushed
    
                end


            end
            
        end

        % Button pushed function: returnButton
        function returnButtonPushed(app, event)
            % Botó "back", sobre que fa un return de la modificació.

            % Veure botó % Botó "imadjust"
            % Guardar en arxiu de text i printar en pantalla
            % Agafem el text seleccionat del ListBox, segons el tipus de modificació seleccionat (temporal o guardat)
            if ~isempty(app.ModificacionstemporalsListBox.Value)
                value_listbox = app.ModificacionstemporalsListBox.Value;
                field_set = strcat("Results_out\Internal_code_files\Image_processing_settings\temporals\", string(value_listbox));
            % Si es pre-selecciona en guardats
            elseif ~isempty(app.ModificacionsguardadesListBox.Value)
                value_listbox = app.ModificacionsguardadesListBox.Value;
                % Llegir arxiu i mostrar en Label Modificacions
                field_set = strcat("Results_out\Internal_code_files\Image_processing_settings\", string(value_listbox));
            end

            % Borrem la última línia del text:
            % Nota: Codi adaptat exclusivament per a combinació amb f'txt_seg'
            [array_processos, ~] = llegir_text_delimitadors(field_set, ";");
            
            [files_ar, ~] = size(array_processos);
            
            delete(field_set)
            txt_seg(field_set, "", ";", "nou", "blanc") % seguiment config.
            for cada_proces = 2:(files_ar-1)
                % Obtenim cada procés
                proces_print = array_processos{cada_proces, 2};
                txt_seg(field_set, proces_print, ";")
            end 
            
            
            % LECTURA IMATGE BOTÓ
            fullFileName = app.vapp_ruta_img_origin;
            imatge_original = imread(fullFileName);            
            

            % __Printar en Modificacions__
            % Obtenir array lectura del document de text i printar aquest            
            [array_sortida_modifs, ~] = llegir_text_delimitadors(field_set, ";");
            [x_rows_arrayproc, ~] = size(array_sortida_modifs);
            % Si no hi ha modificacions:
            if x_rows_arrayproc < 2
                % Graficació
                app.Image.ImageSource = imatge_original;
                
                % Obtenir num objectes binaris:
                app.num_objectes_binarisLabel.Text = "Indef.";
                
                app.Modificacions_actuals.Value = "";
                array_processaments = {};
            % Si hi ha modificacions
            
            else
                cell_cont = array_sortida_modifs(2:end, 2);
                array_processaments = cell2array_josep(cell_cont);
                % Mostrem les modificacións
                app.Modificacions_actuals.Value = array_processaments';
                
                % MODIFICACIÓ DE LA IMATGE
                % Processament imatge
                [BW_final, ~] = processament_imatge_llistat(imatge_original, array_processaments);
                
                % Graficació
                [imatge_3D_sortida] = graficar_rgbgraybw_image(BW_final);
                app.Image.ImageSource = imatge_3D_sortida;
                
                % Obtenir num objectes binaris:
                app.num_objectes_binarisLabel.Text = obtenir_num_BW(BW_final);                        
            
            end

            % OBTENIM TIPOLOGIA DE LA IMATGE
            if isempty(array_processaments)
                % La imatge és rgb
                tipus_im_modif = "rgb";
                % 
            else
                % Obtenim la última modificació
                ultima_modificacio = array_processaments(end);
                
                % Obtenim el nom d'aquesta
                [tipus_im_modif, ~] = obtencio_tipologia_llistat(ultima_modificacio);
            end

            
            % _Mostrar imatge rellotge_
            app.Image2.Visible = 'off';


            % DESACTIVACIÓ/ACTIVACIÓ BOTÓNS MODIFICACIÓ IMATGE SEGONS
            % TIPOLOGIA IMATGE
            app_proc_desact_act_buttons(app, tipus_im_modif)


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            % Cambiar opcions
            
            % Opcions: "Global", "Adaptative"
            
            
            if value == "Adaptative"
                app.ForegroundPolarityDropDown.Visible = 'on';
            else
                app.ForegroundPolarityDropDown.Visible = 'off';
                app.ForegroundPolarityDropDownLabel.Visible = 'off';
            end
            
        end

        % Button pushed function: imadjust_selectButton
        function imadjust_selectButtonPushed(app, event)
            % Botó retallar per configurar ajustament
            % Imadjust by cropping
            
            % Retalla una part de la imatge (C. elegans) i aplica el valor
            % de filtre a tota la imatge.  Binaritza la imatge
            
            % COPIA botó imadjust
            % Botó "imadjust"
            
            % Botó modificació imatge 1: Binarització
            
            app.Image2.Visible = 'on';


            % __Modificació imcrop:__
            % Crop de la imatge modificada:
            % Obtenir array lectura del document de text i printar aquest            
            [array_processaments, ~] = app_proc_obtenir_array_modificacions(app);
            fullFileName = app.vapp_ruta_img_origin;
            imatge_original = imread(fullFileName);
            % Processament imatge
            [BW_final, ~] = processament_imatge_llistat(imatge_original, array_processaments);

            %Crop
            img_example_crop = imcrop(BW_final); close
            
            % Processament, per a obtenció de paràmetres
            img_example_crop_imgray = im2gray(img_example_crop);
    
            % Obtencio parametres
            Idouble = im2double(img_example_crop_imgray); 
            param_average = mean2(Idouble);
            param_sigma = std2(Idouble);
            
            % Afegim al text la modificació
            [field_set] = app_proc_obten_fieldset(app);            
            txt_seg(field_set, strcat("imadjust_croped","(", string(param_average), ", ", string(param_sigma),")"), ";") % seguiment config.
           

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            app.Image2.Visible = 'off';       
            
            
        end

        % Button pushed function: flipButton
        function flipButtonPushed(app, event)
            % Flip: dona la volta al valor de la imatge
            

            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);
            % Afegim al text la modificació
            txt_seg(field_set, "Flip", ";") % seguiment config.

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            app.Image2.Visible = 'off';


        end

        % Button pushed function: edgeapproxcannyButton
        function edgeapproxcannyButtonPushed(app, event)
            % Edge approxcannin


            % Afegim al text la modificació
            txt_seg(field_set, "edge_approxcanny", ";") % seguiment config.


        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            % Analyse

            % Tancament del loop
            app.vapp_tancament_loop = "tancar";
            
            % Fa una copia de la modificacio a la ubicacio en es llegirà en
            % el programa.
            

            dir_save_txt = app.vapp_carpeta_output;
            
            if ~isempty(app.ModificacionstemporalsListBox.Value)
                value_listbox = app.ModificacionstemporalsListBox.Value;
                field_set = strcat("Results_out\Internal_code_files\Image_processing_settings\temporals\", string(value_listbox));
            % Si es pre-selecciona en guardats
            elseif ~isempty(app.ModificacionsguardadesListBox.Value)
                value_listbox = app.ModificacionsguardadesListBox.Value;
                % Llegir arxiu i mostrar en Label Modificacions
                field_set = strcat("Results_out\Internal_code_files\Image_processing_settings\", string(value_listbox));
            end
            
            
            copyfile(field_set, strcat(dir_save_txt, "\Processment_parameters\", value_listbox)); % <- temporal el strcat

            
            % _Preselecció modificació_
            % Fer que es guardi el nom de l'arxiu, de manera que la proxima
            % vegada que s'executi el programa, sigui aquesta modificació
            % la que es pre-seleccioni de foroma automàtica.
            % value_listbox % '48_C.txt'

            % _Write arxiu predefinit_
            % Guardem el valor de la modificació en un txt, per a llegir-lo
            % quan es torni a obrir el programa
            array_write_folder = ["Folder", value_listbox];
            write_text_josep("Results_out\Internal_code_files\predefined_modif.txt", array_write_folder, ";");


            %close force 
            % En teoria el close force hauria d'anar aqui, però el loop del
            % StartupFcn (while) si es fa tancar la GUI aquest 'peta' i fa petar el prorgama.
            % D'aquesta manera, es posa close force fora del while, i es
            % surt d'aquest amb el botó analyse.
            
        end

        % Button pushed function: regionimopenButton
        function regionimopenButtonPushed(app, event)
            % Region imopen
            
            % COPIA adaptada de imopen:
            
            % Button Open
            
            
            % "open_disc(valor)"
            % "close_disc(valor)"


            % Valor del imopen regio
            valor_modif = app.Spinner_4.Value;
            
            % Afegim al text la modificació
            text_modific = strcat("region_imopen(", string(valor_modif), ")");
            txt_seg(field_set, text_modific, ";") % seguiment config.


        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)
            % Rename button
            
            % Modifica el nom de la modificació seleccionada.
            
            % _Obtenim el nom nou_
            
            % Modificacions guardades és vuit, es modificarà la carpeta de modificacións temporals
            if isempty(app.ModificacionsguardadesListBox.Value)
                rename_cont = "temporals";
            % Modificacions temporals és vuit, es modificarà la carpeta de modificacións guardades
            elseif isempty(app.ModificacionstemporalsListBox.Value)   
                rename_cont = "guardades";
            else
                msgbox('Error select folder', 'Error','error')
            end
            
            if ~isempty(app.ModificacionsguardadesListBox.Value)
                definput = string(app.ModificacionsguardadesListBox.Value);
            elseif ~isempty(app.ModificacionstemporalsListBox.Value)
                definput = string(app.ModificacionstemporalsListBox.Value); 
            else
                disp("No modifications?")
                definput = "";
            end
            if ~isempty(definput)
                definput_t = split(definput, ".txt");
                definput = definput_t(1);
            end
        
            % Obrir cuadre dialeg per entrada nom arxiu
            prompt = "Nom de l'arxiu:";
            dlgtitle = 'Input';
            dims = [1 35];
            answer_resposta = inputdlg(prompt,dlgtitle,dims,definput);

            % Si no es cancela
            if ~isempty(answer_resposta)
                
                % Si conté ".txt" es torna a fer.
                if contains(answer_resposta{1}, ".txt")
                    waitfor(msgbox("File cannot have '.txt' "))
                    while contains(answer_resposta{1}, ".txt")
                        prompt = "Nom de l'arxiu:";
                        dlgtitle = 'Input';
                        dims = [1 35];
                        answer_resposta = inputdlg(prompt,dlgtitle,dims,definput);
                        
                        % si es cancela
                        if isempty(answer_resposta)
                            break
                        end
                    end
                end
            end
            
            
            % Si hi ha text en la resposta
            if ~isempty(answer_resposta)
                
                % Si existeixen arxius amb el mateix nom
                [llistat_string_im] = [...
                    llegir_arxius_tipologia("Results_out\Internal_code_files\Image_processing_settings\temporals\", ".txt"), ...
                    llegir_arxius_tipologia("Results_out\Internal_code_files\Image_processing_settings\", ".txt")];
                
                splitat_arxius = split(llistat_string_im, ".txt");
                
    
                si_no_son_iguals = true; % Si aquest no varia, significa que no hi ha arxius iguals.
                for cada_arxiu = 1:length(llistat_string_im)
                    if splitat_arxius(cada_arxiu) == answer_resposta
                        si_no_son_iguals = false;
                    end
                end
    
                % Si existeixen o no
                if si_no_son_iguals
                    % Es crea l'arxiu nou
                    % answer_resposta : Resposta del nou nom, que no existeix.
                    
                    if rename_cont == "temporals"
                        nom_arxiu_anterior = strcat("Results_out\Internal_code_files\Image_processing_settings\temporals\",app.ModificacionstemporalsListBox.Value);
                        nom_arxiu_guardar = strcat("Results_out\Internal_code_files\Image_processing_settings\temporals\",answer_resposta,".txt");
                    elseif rename_cont == "guardades"
                        nom_arxiu_anterior = strcat("Results_out\Internal_code_files\Image_processing_settings\",app.ModificacionsguardadesListBox.Value);
                        nom_arxiu_guardar = strcat("Results_out\Internal_code_files\Image_processing_settings\",answer_resposta,".txt");                        
                    end
                    
                    % Creem un arxiu nou i borrem l'anterior
                    movefile(nom_arxiu_anterior, nom_arxiu_guardar);
                    % copyfile(nom_arxiu_anterior, nom_arxiu_guardar);
                    
    
                    % _Actualitzem els ListBox_
                    if rename_cont == "temporals"
                        % Actualitzem les llsites:
                        % Llegir arxius Modificacions guardades
                        myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\temporals\";
                        patro_tipus_image = ".txt";
                        [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
        
                        % Actualitzem el listbox
                        app.ModificacionstemporalsListBox.Items = llistat_string_im;    
        
                        % Seleccionar el que té el nom cambiat:
                        app.ModificacionstemporalsListBox.Value = strcat(answer_resposta, ".txt");
                    elseif rename_cont == "guardades"
                        % Actualitzem les llsites:
                        % Llegir arxius Modificacions guardades
                        myFolder_input = "Results_out\Internal_code_files\Image_processing_settings\";
                        patro_tipus_image = ".txt";
                        [llistat_string_im] = llegir_arxius_tipologia(myFolder_input, patro_tipus_image);
        
                        % Actualitzem el listbox
                        app.ModificacionsguardadesListBox.Items = llistat_string_im;    
        
                        % Seleccionar el que té el nom cambiat:
                        app.ModificacionsguardadesListBox.Value = strcat(answer_resposta, ".txt");                    
                    end
                
                
                else
                    waitfor(msgbox('Name already exist', 'Error','error'));
                end                
                
            else
                waitfor(msgbox('No name defined', 'Error','error'));
            end


        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            % Botó Zoom
            
            imatge_principal = app.Image.ImageSource;
            
            % Retallem la imatge en fer zoom
            [~, xy_punts_a_retall] = imcrop(imatge_principal);
            
            %Si s'ha realitzat una selecció
            if ~isempty(xy_punts_a_retall)
                close
                xy_punts_a_retall = floor(xy_punts_a_retall);
                
                [imatge_primera_retallada] = retallar_imatges_punts(imatge_principal, xy_punts_a_retall);
                %imshow(imatge_primera_retallada)
                
                app.Image.ImageSource = imatge_primera_retallada;
                                
                
                % _Mostrar la imatge en un UIAxes_
                % ÇÇÇ AUGMENTA MOLT LA QUALITAT, ÉS LO SEU.
                % imshow("PNG_BW.png", 'Parent', app.UIAxes); 
                
                % Fem visible el botó de return de la imatge:
                %app.LupaButton_return.Visible = 'on';
            end            
            
            
            app.ReturnLupa.Visible = 'on';
            
        end

        % Button pushed function: BinarizebyvalueButton
        function BinarizebyvalueButtonPushed(app, event)
            % Binarize by value Button
            
            % Valor del Slider:
            binarize_value = app.BinarizeSlider.Value;


            app.Image2.Visible = 'on';
            
            % _Afegim la modificació_
            [field_set] = app_proc_obten_fieldset(app);
            % Afegim al text la modificació
            text_modific = strcat("binarize_by_value(", string(binarize_value), ")");
            txt_seg(field_set, text_modific, ";") % seguiment config.           


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            app.Image2.Visible = 'off';
                        
            
        end

        % Value changed function: EsqueletonitzarButton
        function EsqueletonitzarButtonValueChanged(app, event)
            %value = app.EsqueletonitzarButton.Value;
            
            % Esqueletonitzar la imatge, per a veure cóm es amb el filtre.

            if app.EsqueletonitzarButton.Value
                app.EsqueletonitzarButton.BackgroundColor = 'y';
            else
                app.EsqueletonitzarButton.BackgroundColor = [0.96,0.96,0.96];
            end
                            
            app.Image2.Visible = 'on';

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


            app.Image2.Visible = 'off';        

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            
            
%               % DIÀLEG PER A "ESTAS SEGUR DE TANCAR?
%
%             answer_del = questdlg("Did you not selected an image processment configuration..." + newline + "Do you want to close?", ...
% 	            'Close', ...
% 	            'Yes','No', 'No');
% 
%             switch answer_del
%                 case 'Yes'
%                     % delete(app)
%                     app.vapp_tancament_loop = "tancar";
%                     %close force
% 
%                     % S'executaria ara la la iniciació del programa.
% 
%                 case 'No'
%                     %
%             end

            close force


        end

        % Value changed function: OriginalButton
        function OriginalButtonValueChanged(app, event)
            value_OriginalButton = app.OriginalButton.Value;
            
            % Desseleccionem botons
            if value_OriginalButton
                app.MaskedButton.Value = false;
                app.ModifiedButton.Value = false;
            end


            % Cambiem color botó segons seleccionat
            if app.OriginalButton.Value
                app.OriginalButton.BackgroundColor = 'y';
            else
                app.OriginalButton.BackgroundColor = [0.96,0.96,0.96];
            end

                        
            % LECTURA IMATGE BOTÓ
            fullFileName = app.vapp_ruta_img_origin;
            imatge_original = imread(fullFileName);
            app.Image.ImageSource = imatge_original;
            
            
        end

        % Value changed function: MaskedButton
        function MaskedButtonValueChanged(app, event)
            % Mostra la imatge amb màscara

            value_MaskedButton = app.MaskedButton.Value;

            if value_MaskedButton
                app.OriginalButton.Value = false;
                app.ModifiedButton.Value = false;
            end

            % Cambiem color botó segons seleccionat
            if app.OriginalButton.Value
                app.OriginalButton.BackgroundColor = 'y';
            else
                app.OriginalButton.BackgroundColor = [0.96,0.96,0.96];
            end


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)

            
        end

        % Value changed function: ModifiedButton
        function ModifiedButtonValueChanged(app, event)
            value_ModifiedButton = app.ModifiedButton.Value;

            if value_ModifiedButton
                app.OriginalButton.Value = false;
                app.MaskedButton.Value = false;
            end


            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            % Apretar imatge

            % Es canvien les posicións de la imatge 1 per la imatge 3, i
            % viceversa.

            pos_img3 = app.Image3.Position;
            pos_img = app.Image.Position;

            app.Image3.Position = pos_img;
            app.Image.Position = pos_img3;
            

        end

        % Value changed function: TutorialButton
        function TutorialButtonValueChanged(app, event)
            % Tutorial de la app
             
            value_tutorial = app.TutorialButton.Value;


            % COPIA de APP_NOVA_INTERFAZ


            if value_tutorial

                % __Activació/Desactivacio botons__
                % #Funcio activacio desactivacio botons
                app_proc_activardesactivar_botons_tutorial(app, 'on')

                % __Descripció del color de les figures de la App__
                % Agafem tots els objectes:
                obj_h = findobj(app.UIFigure);
                % Obtenim les propietats del botó.
                h_determin_button = findobj(app.UIFigure,'Type', 'figure');
                h_determin_button.Color = h_determin_button.Color - 0.2;
                % ÇÇ No canvia el color...


                % Cambi color botó tutorial:
                app.TutorialButton.BackgroundColor = [0.9290 0.6940 0.1250];
                        % Normal: [0.9600    0.9600    0.9600]
                        % Tronja fosc: [0.8500 0.3250 0.0980]
                        % Tronja clar: [0.9290 0.6940 0.1250]


                % Correccio que no surti ultim frame
                %imshow("Apps\app_nova_interfaz_nova\Imatges_utilitat\imatge_background_fosc.png", 'Parent', app.UIAxes2);            


            % Si està activat el botó de Tutorial (sortir) = Com sempre
            else


                % _Activem/Desactivem botóns tutorial_
                app_proc_activardesactivar_botons_tutorial(app, 'off')


                % __Descripció del color de les figures de la App__
                % Agafem tots els objectes:
                obj_h = findobj(app.UIFigure);
                figure_objects_names_filtr = unique(get(obj_h,'Type'));
    
                % Si volem filtar els objectes:
                %figure_objects_names_filtr_BackgroundColor = unique(figure_objects_names_filtr(ismember(figure_objects_names_filtr, ["uibutton"])));
                figure_objects_names_filtr_Color = unique(figure_objects_names_filtr(ismember(figure_objects_names_filtr, ["figure"])));            
                    
                % Per cada objecte:
                for n_figure_object = 1:length(figure_objects_names_filtr_Color)
                    figure_object = figure_objects_names_filtr_Color{n_figure_object};
    
                    % Obtenim les propietats del botó.
                    h_determin_button = findobj(app.UIFigure,'Type', figure_object);
                    h_determin_button.Color = [0.9400 0.9400 0.9400];

                end                       
                % [0.9400 0.9400 0.9400]


                % Cambi color botó tutorial:
                app.TutorialButton.BackgroundColor = [0.9600    0.9600    0.9600];
                        % Normal: [0.9600    0.9600    0.9600]
                        % Tronja fosc: [0.8500 0.3250 0.0980]
                        % Tronja clar: [0.9290 0.6940 0.1250]                

            end


            % Actualitzem figura per capacitar shortkey
            figure(app.UIFigure)        
        end

        % Button pushed function: InfoIcon
        function InfoIconButtonPushed(app, event)
            % Mosrta la imatge de tutorial.


            % Cambiem la imatge per la imatge del tutorial:
            imatge_ex_tutorial = imread("Apps\app_processament_imatge\Internal code files\Images_tutorial\im2grey.png");
            app.Image.ImageSource = imatge_ex_tutorial;

            pause(3)

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


        end

        % Button pushed function: InfoIcon_imadjust
        function InfoIcon_imadjustButtonPushed(app, event)
            % Icon info imadjust

            % Mosrta la imatge de tutorial.


            % Cambiem la imatge per la imatge del tutorial:
            imatge_ex_tutorial = imread("Apps\app_processament_imatge\Internal code files\Images_tutorial\imadjust.png");
            app.Image.ImageSource = imatge_ex_tutorial;

            pause(3)

            % VISUALIZATION OF THE MODIFICATIONS
            app_proc_visual_modif(app)


        end

        % Button pushed function: RenameButton_2
        function RenameButton_2Pushed(app, event)
            % Rename

            % Fa el mateix que rename: apreta el botó Rename 1.

            app.RenameButtonPushed;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Im2greyButton = uibutton(app.LeftPanel, 'push');
            app.Im2greyButton.ButtonPushedFcn = createCallbackFcn(app, @Im2greyButtonPushed, true);
            app.Im2greyButton.Tooltip = {'Convert RGB image to grayscale'};
            app.Im2greyButton.Text = 'Im2grey';
            app.imadjustButton = uibutton(app.LeftPanel, 'push');
            app.imadjustButton.ButtonPushedFcn = createCallbackFcn(app, @imadjustButtonPushed, true);
            app.imadjustButton.Tooltip = {'Adjust image intensity values or colormap'};
            app.imadjustButton.Text = 'imadjust';
            app.BinarizeButton = uibutton(app.LeftPanel, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Tooltip = {'Binarize grayscale image'};
            app.BinarizeButton.Text = 'Binarize';
            app.AreaOpenButton = uibutton(app.LeftPanel, 'push');
            app.AreaOpenButton.ButtonPushedFcn = createCallbackFcn(app, @AreaOpenButtonPushed, true);
            app.AreaOpenButton.Tooltip = {'Remove small objects from binary image'};
            app.AreaOpenButton.Text = 'AreaOpen';
            app.imclearborderButton = uibutton(app.LeftPanel, 'push');
            app.imclearborderButton.ButtonPushedFcn = createCallbackFcn(app, @imclearborderButtonPushed, true);
            app.imclearborderButton.Tooltip = {'Suppress light structures connected to image border'};
            app.imclearborderButton.Text = 'imclearborder';
            app.imfillButton = uibutton(app.LeftPanel, 'push');
            app.imfillButton.ButtonPushedFcn = createCallbackFcn(app, @imfillButtonPushed, true);
            app.imfillButton.Tooltip = {'Fill image regions and holes'};
            app.imfillButton.Text = 'imfill';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Step = 0.5;
            app.Spinner.Limits = [0.5 Inf];
            app.Spinner.Value = 0.5;
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Morphologically open image'};
            app.OpenButton.Text = 'Open';
            app.Spinner_2 = uispinner(app.LeftPanel);
            app.Spinner_2.Limits = [1 Inf];
            app.Spinner_2.Value = 1;
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Morphologically close image'};
            app.CloseButton.Text = 'Close';
            app.Spinner_3 = uispinner(app.LeftPanel);
            app.Spinner_3.Limits = [1 Inf];
            app.Spinner_3.Value = 1;
            app.imadjust_selectButton = uibutton(app.LeftPanel, 'push');
            app.imadjust_selectButton.ButtonPushedFcn = createCallbackFcn(app, @imadjust_selectButtonPushed, true);
            app.imadjust_selectButton.Tooltip = {'Adjust image intensity values by mean and sd selected region'};
            app.imadjust_selectButton.Text = 'imadjust_select';
            app.flipButton = uibutton(app.LeftPanel, 'push');
            app.flipButton.ButtonPushedFcn = createCallbackFcn(app, @flipButtonPushed, true);
            app.flipButton.Tooltip = {'Flip the logical values of the binary image'};
            app.flipButton.Text = 'flip ~';
            app.BinarizeSlider = uislider(app.LeftPanel);
            app.BinarizeSlider.Limits = [0 255];
            app.BinarizeSlider.Value = 255;
            app.BinarizebyvalueButton = uibutton(app.LeftPanel, 'push');
            app.BinarizebyvalueButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizebyvalueButtonPushed, true);
            app.BinarizebyvalueButton.Tooltip = {'Binarize the grayscale by a threshold value'};
            app.BinarizebyvalueButton.Text = {'Binarize'; 'by value'};
            app.FiltersLabel = uilabel(app.LeftPanel);
            app.FiltersLabel.Text = 'Filters';
            app.returnButton = uibutton(app.LeftPanel, 'push');
            app.returnButton.ButtonPushedFcn = createCallbackFcn(app, @returnButtonPushed, true);
            app.returnButton.Icon = 'return_yellow.png';
            app.returnButton.Text = 'undo filter';
            app.InfoIcon = uibutton(app.LeftPanel, 'push');
            app.InfoIcon.ButtonPushedFcn = createCallbackFcn(app, @InfoIconButtonPushed, true);
            app.InfoIcon.Icon = 'info_icon.png';
            app.InfoIcon.Visible = 'off';
            app.InfoIcon.Text = '';
            app.InfoIcon_imadjust = uibutton(app.LeftPanel, 'push');
            app.InfoIcon_imadjust.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_imadjustButtonPushed, true);
            app.InfoIcon_imadjust.Icon = 'info_icon.png';
            app.InfoIcon_imadjust.Visible = 'off';
            app.InfoIcon_imadjust.Text = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ZoomButton = uibutton(app.CenterPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Icon = 'loupe.png';
            app.ZoomButton.Tooltip = {'Zoom to the image'};
            app.ZoomButton.Text = '';
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image2 = uiimage(app.CenterPanel);
            app.Image2.ImageSource = 'reloj_arena.jpg';
            app.EsqueletonitzarButton = uibutton(app.CenterPanel, 'state');
            app.EsqueletonitzarButton.ValueChangedFcn = createCallbackFcn(app, @EsqueletonitzarButtonValueChanged, true);
            app.EsqueletonitzarButton.Tooltip = {'Draw the skeleton of the binary images'};
            app.EsqueletonitzarButton.Text = 'Skeletonize';
            app.OriginalButton = uibutton(app.CenterPanel, 'state');
            app.OriginalButton.ValueChangedFcn = createCallbackFcn(app, @OriginalButtonValueChanged, true);
            app.OriginalButton.Tooltip = {'Show the original images'};
            app.OriginalButton.Text = 'Original';
            app.MaskedButton = uibutton(app.CenterPanel, 'state');
            app.MaskedButton.ValueChangedFcn = createCallbackFcn(app, @MaskedButtonValueChanged, true);
            app.MaskedButton.Tooltip = {'Show the mask of the images'};
            app.MaskedButton.Text = 'Masked';
            app.ModifiedButton = uibutton(app.CenterPanel, 'state');
            app.ModifiedButton.ValueChangedFcn = createCallbackFcn(app, @ModifiedButtonValueChanged, true);
            app.ModifiedButton.Tooltip = {'Show the modifyied images'};
            app.ModifiedButton.Text = 'Modified';
            app.ModifiedButton.Value = true;
            app.ReturnLupa = uibutton(app.CenterPanel, 'push');
            app.ReturnLupa.ButtonPushedFcn = createCallbackFcn(app, @ReturnLupaPushed, true);
            app.ReturnLupa.Icon = 'loupe_feltcha_bo.png';
            app.ReturnLupa.Visible = 'off';
            app.ReturnLupa.Text = '';
            app.Image3 = uiimage(app.CenterPanel);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Button_12 = uibutton(app.CenterPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '<';
            app.Button_11 = uibutton(app.CenterPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '>';
            app.AnalyseButton = uibutton(app.CenterPanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Tooltip = {'Run the image analysis!'};
            app.AnalyseButton.Text = 'Analyse';
            app.RequirmentAnalysisText = uilabel(app.CenterPanel);
            app.RequirmentAnalysisText.Text = {'Image must be binarized'; 'to execute the analysis'};
            app.ImageprocessingpanelLabel = uilabel(app.CenterPanel);
            app.ImageprocessingpanelLabel.Text = 'Image processing panel';
            app.RightPanel = uipanel(app.GridLayout);
            app.ModificationsLabel = uilabel(app.RightPanel);
            app.ModificationsLabel.Text = 'Modifications';
            app.Modificacions_actuals = uitextarea(app.RightPanel);
            app.Modificacions_actuals.Editable = 'off';
            app.Modificacions_actuals.Enable = 'off';
            app.Button_13 = uibutton(app.RightPanel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Icon = 'delete_icon.png';
            app.Button_13.Text = '';
            app.contador_numimLabel = uilabel(app.RightPanel);
            app.contador_numimLabel.Text = 'contador_numim';
            app.ModificarButton = uibutton(app.RightPanel, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Tooltip = {'Modify the saved configuration'};
            app.ModificarButton.Text = 'Modify';
            app.Button_14 = uibutton(app.RightPanel, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Icon = 'delete_icon.png';
            app.Button_14.Text = '';
            app.ImagenLabel = uilabel(app.RightPanel);
            app.ImagenLabel.Text = 'Image nº:';
            app.ImatgeLabel = uilabel(app.RightPanel);
            app.ImatgeLabel.Text = 'Imatge';
            app.SavedmodificationsLabel = uilabel(app.RightPanel);
            app.SavedmodificationsLabel.Text = 'Saved';
            app.ModificacionsguardadesListBox = uilistbox(app.RightPanel);
            app.ModificacionsguardadesListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionsguardadesListBoxValueChanged, true);
            app.TemporalLabel = uilabel(app.RightPanel);
            app.TemporalLabel.Text = 'Temporal';
            app.ModificacionstemporalsListBox = uilistbox(app.RightPanel);
            app.ModificacionstemporalsListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionstemporalsListBoxValueChanged, true);
            app.NovamodificaciButton = uibutton(app.RightPanel, 'push');
            app.NovamodificaciButton.ButtonPushedFcn = createCallbackFcn(app, @NovamodificaciButtonPushed, true);
            app.NovamodificaciButton.Tooltip = {'Creates a new image processing configuration'};
            app.NovamodificaciButton.Text = {'New'; 'processment'};
            app.RenameButton = uibutton(app.RightPanel, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Tooltip = {'Rename the selected configuration'};
            app.RenameButton.Text = 'Rename';
            app.FolderLabel = uilabel(app.RightPanel);
            app.FolderLabel.Text = 'Folder:';
            app.carpeta_imatges_textLabel = uilabel(app.RightPanel);
            app.carpeta_imatges_textLabel.Text = 'carpeta_imatges_text';
            app.NobjectsLabel = uilabel(app.RightPanel);
            app.NobjectsLabel.Text = 'Nª objects:';
            app.num_objectes_binarisLabel = uilabel(app.RightPanel);
            app.num_objectes_binarisLabel.Text = 'num_objectes_binaris';
            app.ImageLabel = uilabel(app.RightPanel);
            app.ImageLabel.Text = 'Image:';
            app.ImagepropertiesLabel = uilabel(app.RightPanel);
            app.ImagepropertiesLabel.Text = 'Image properties';
            app.DelAll = uibutton(app.RightPanel, 'push');
            app.DelAll.ButtonPushedFcn = createCallbackFcn(app, @DelAllPushed, true);
            app.DelAll.Icon = 'delete_icon.png';
            app.DelAll.Text = 'All';
            app.ImportButton = uibutton(app.RightPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.TutorialButton = uibutton(app.RightPanel, 'state');
            app.TutorialButton.ValueChangedFcn = createCallbackFcn(app, @TutorialButtonValueChanged, true);
            app.TutorialButton.Text = 'Tutorial';
            app.RenameButton_2 = uibutton(app.RightPanel, 'push');
            app.RenameButton_2.ButtonPushedFcn = createCallbackFcn(app, @RenameButton_2Pushed, true);
            app.RenameButton_2.Tooltip = {'Rename the selected configuration'};
            app.RenameButton_2.Text = 'Rename';
            app.GuardarmodificaciButton = uibutton(app.RightPanel, 'push');
            app.GuardarmodificaciButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarmodificaciButtonPushed, true);
            app.GuardarmodificaciButton.Tooltip = {'Save the current configuration to the saved modifications'};
            app.GuardarmodificaciButton.Text = 'Save';
            app.ProcessingsLabel = uilabel(app.RightPanel);
            app.ProcessingsLabel.Text = 'Processings';
            app.ButtonVideoTutorial = uibutton(app.RightPanel, 'push');
            app.ButtonVideoTutorial.ButtonPushedFcn = createCallbackFcn(app, @ButtonVideoTutorialPushed, true);
            app.ButtonVideoTutorial.Text = 'Video Tutorial';
            app.edgeapproxcannyButton = uibutton(app.UIFigure, 'push');
            app.edgeapproxcannyButton.ButtonPushedFcn = createCallbackFcn(app, @edgeapproxcannyButtonPushed, true);
            app.edgeapproxcannyButton.Text = 'edge approxcanny';
            app.regionimopenButton = uibutton(app.UIFigure, 'push');
            app.regionimopenButton.ButtonPushedFcn = createCallbackFcn(app, @regionimopenButtonPushed, true);
            app.regionimopenButton.Text = 'region imopen';
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.Limits = [1 Inf];
            app.Spinner_4.Value = 2;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Global', 'Adaptative Dark', 'Adaptatibe Bright sumatori', '<255'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Global';
            app.ForegroundPolarityDropDownLabel = uilabel(app.UIFigure);
            app.ForegroundPolarityDropDownLabel.Visible = 'off';
            app.ForegroundPolarityDropDownLabel.Text = 'ForegroundPolarity';
            app.ForegroundPolarityDropDown = uidropdown(app.UIFigure);
            app.ForegroundPolarityDropDown.Items = {'Dark', 'Bright'};
            app.ForegroundPolarityDropDown.Visible = 'off';
            app.ForegroundPolarityDropDown.Value = 'Dark';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_processament_imatge_autoreflow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end