classdef app_selection_pannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DLabel                         matlab.ui.control.Label
        ALabel                         matlab.ui.control.Label
        Cross_Button_little            matlab.ui.control.Image
        Thick_Button_little            matlab.ui.control.Image
        n_noLabel2                     matlab.ui.control.Label
        n_yesLabel2                    matlab.ui.control.Label
        refusedLabel                   matlab.ui.control.Label
        acceptedLabel                  matlab.ui.control.Label
        n_noLabel                      matlab.ui.control.Label
        n_yesLabel                     matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        spaceLabel                     matlab.ui.control.Label
        ELabel                         matlab.ui.control.Label
        InfoIcon_3                     matlab.ui.control.Button
        InfoIcon_2                     matlab.ui.control.Button
        ExportnoncorrectedmanualerrordataCheckBox  matlab.ui.control.CheckBox
        ImageLabel_2                   matlab.ui.control.Label
        ExportIAdataButton             matlab.ui.control.Button
        InfoIcon_Brush                 matlab.ui.control.Button
        InfoIcon_Draw                  matlab.ui.control.Button
        InfoIcon                       matlab.ui.control.Button
        QLabel                         matlab.ui.control.Label
        TutorialButton                 matlab.ui.control.StateButton
        CLabel                         matlab.ui.control.Label
        FLabel                         matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        SLabel                         matlab.ui.control.Label
        NotFinishedImagesLabel         matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        LupaReturnButton               matlab.ui.control.Button
        LupaButton                     matlab.ui.control.Button
        PintarButton                   matlab.ui.control.Button
        Image4                         matlab.ui.control.Image
        LastmodifLabel                 matlab.ui.control.Label
        WLabel                         matlab.ui.control.Label
        MinLengthTextArea              matlab.ui.control.NumericEditField
        MinWidthTextArea               matlab.ui.control.NumericEditField
        SelectionLabel                 matlab.ui.control.Label
        ToolsLabel                     matlab.ui.control.Label
        DrawLineButton                 matlab.ui.control.Button
        CutButton                      matlab.ui.control.Button
        MinWidthStateButton            matlab.ui.control.StateButton
        MinLengthStateButton           matlab.ui.control.StateButton
        Image3                         matlab.ui.control.Image
        dir_img_orig_sLabel            matlab.ui.control.Label
        FolderLabel                    matlab.ui.control.Label
        ofLabel                        matlab.ui.control.Label
        ImageLabel                     matlab.ui.control.Label
        ObjectnLabel                   matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        max_valLabel                   matlab.ui.control.Label
        maxLabel                       matlab.ui.control.Label
        min_valLabel                   matlab.ui.control.Label
        minLabel                       matlab.ui.control.Label
        std_valLabel                   matlab.ui.control.Label
        stdLabel                       matlab.ui.control.Label
        mean_valLabel                  matlab.ui.control.Label
        meanLabel                      matlab.ui.control.Label
        nLabel                         matlab.ui.control.Label
        n_valLabel                     matlab.ui.control.Label
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        SpinnerRound                   matlab.ui.control.Spinner
        MaxCircularityButton           matlab.ui.control.StateButton
        OriginalButton                 matlab.ui.control.StateButton
        n_objecteLabel                 matlab.ui.control.Label
        img_original_nomLabel          matlab.ui.control.Label
        Hyperlink                      matlab.ui.control.Hyperlink
        ReturnButton                   matlab.ui.control.Button
        CitationLabel                  matlab.ui.control.Label
        img_tot_nLabel                 matlab.ui.control.Label
        img_contLabel                  matlab.ui.control.Label
        ModificacinsguardadesListBoxLabel  matlab.ui.control.Label
        ModificacionstemporalsListBox  matlab.ui.control.ListBox
        ModificacionstemporalsListBoxLabel  matlab.ui.control.Label
        ModificacionsguardadesListBox  matlab.ui.control.ListBox
        FiltersLabel                   matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        Cross_Button                   matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Thick_Button                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    % Variables globals (són guardades en la app)
    properties (Access = private)


    end    

    % Públic, que no vol dir global (ho anomenarem semiglobal)
    properties (Access = public)

        % ÇÇÇ Fer app_selection_pannel:
        % - Veure botóns sense utilitat
        % - Veure variables locals de l'app sense utilitat
        % - Traduïr funcions

        % StartupFcn variables (app's input variables)
        theFiles_arxiu = []; % theFiles app input data
        dir_imgs_orig = []; % Directory of original images app input data
        appv_dir_output = []; % Results rute app input data
        appv_escala_img = []; % Scale app input data


        % _Table variables_
        seg_inici_taula = true; % true si no s'ha obert una taula; false si sí que ja s'ha obert una.
        global_S_textscan_table = []; %         % Variable de la taula de dades
        taula_draw = []; % Counter of drawed objects
        taula_draw_main = []; % Main drawing values
        
        % _Image variables_
        resolucio_img = []; % Resolution of the image.
        imcrop_value = []; % Crop of the image

        % _Showing image variables_
        imatge_anterior_1 = []; % Showing lateral image
        BW_img_cuted = []; % BW image cutted, for to show
        BW_img_extend = []; % BW image for to show
        BW_img_temp = []; % Temporal save
        cont_canvi_img_1 = true; % Counter
       
        
        % _Counters_
        finalitzat_a_estadistica = false;


        % _MAIN TABLE_
        % Main table for to save the objects characteristics and selection information.
        table_main_sel = [];


        % _GUI operation variables_
        posImgTextArea = [];
        posFigTextArea = [];

        posButtonsTextArea = [];
        posListboxTextArea = [];
        posLabelTextArea = [];
        posImatgesTextArea = [];
        posHyperlinkTextArea = [];
        posStateButtonsTextArea = [];
        posSpinner = [];
        posUIAxes = [];
        posEditfield = [];
        posCheckBox = [];
        sizeFontTextArea = [];
        StartupFcnContTextArea = "StartupFcnCont"; % Counter start
        finalitzat_contLabel = []; % Counter


    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, theFiles, dir_output, escala_img)
           % Start of the app (startupFcn)
            
            % ____Saving in app the input variables from function____
            % Input start variables: 
            % theFiles - Data structure of the images (name, folder, date, ...). Utility for obtaining images.
            % dir_output - directory of results
            % escala_img - scale of the images
            
            % Saving variables
            app.theFiles_arxiu = theFiles;
            app.dir_imgs_orig = theFiles(1).folder; % Directory of the original images
            app.appv_dir_output = dir_output; % Results directory (carpeta_output)
            app.appv_escala_img = escala_img; % Scale

            % __StartupFcn__
            app_interf_startupFcn(app)
            

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % Windows changing of size (automatic function)
            app_interf_UIFigureSizeChanged(app)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            % When click in empty regions of the GUI (unactive) 

        end

        % Value changed function: ModificacionstemporalsListBox
        function ModificacionstemporalsListBoxValueChanged(app, event)
            % Temporal modification change of value (unactive)
                                     
        end

        % Value changed function: ModificacionsguardadesListBox
        function ModificacionsguardadesListBoxValueChanged(app, event)
            % Saved modifications change of value (unactive)
                          
        end

        % Button pushed function: Thick_Button
        function Thick_ButtonPushed(app, event)
            % Thick button
            app_interf_boto_thick(app)

        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            % Click in imatge 2 (unactive)
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Click in main imatge 
            app_interf_boto_clickimage(app)
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            % Return button
            app_interf_boto_return(app) 

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Done button
            app_interf_boto_estadist(app) % Apply the statistic showing part
            
        end

        % Button pushed function: Cross_Button
        function Cross_ButtonPushed(app, event)
            % Cross button
            app_interf_boto_cross(app)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Keyshorts
            key = event.Key;
            
            % _Keyshorts_
            % 'space' -> 'Done'
            %
            % 'a' -> Cross button
            %     -> (Right button if there is no object)
            %
            % 'd' -> Thick button
            %     -> (Right button if there is no object)
            % 
            % 'w' -> Return button
            %
            % 's' -> Original image button
            %
            % 'q' -> Left button
            % 'e' -> Right button
            %
            % 'r' -> Scissors button
            % 'f' -> Draw line button
            % 'c' -> Brush button (extend line)
            %
            % 'Esc' -> Exit

            
            switch key
                case 'a'
                    % Cross
                    if app.Cross_Button.Visible == 'on'
                        app.Cross_ButtonPushed;
                        
                    % Right button, if there is no cross button.
                    elseif app.RightButton.Visible == 'on'
                        app.RightButtonPushed;
                    end
                
                case 'd'
                    % Thick
                    if app.Thick_Button.Visible == 'on'
                        app.Thick_ButtonPushed;
                        
                    % Rigth button, if there is no thick button
                    elseif app.RightButton.Visible == 'on'
                        app.RightButtonPushed
                    end
                    
                case 'w'
                    % Return
                    if app.ReturnButton.Visible == 'on'
                        app.ReturnButtonPushed;
                    end             
                    
                case 's'
                    % Original image button
                    if app.OriginalButton.Visible == 'on'
                        if ~app.OriginalButton.Value
                            app.OriginalButton.Value = true;
                            app.OriginalButtonValueChanged;
                        else
                            app.OriginalButton.Value = false;
                            app.OriginalButtonValueChanged;                            
                        end
                    end
                    

                case 'q'
                    % Left button
                    if isequal(app.LeftButton.Visible, 'on')
                        app.LeftButtonPushed;
                    end

                case 'e'
                    % Rigth button
                    if isequal(app.RightButton.Visible, 'on')
                        app.RightButtonPushed;
                    end                    

                case 'r'
                    % Scissors button
                    if app.CutButton.Visible == 'on'
                        app.CutButtonPushed;
                    end

                case 'f'
                    % Draw line button
                    if app.DrawLineButton.Visible == 'on'
                        app.DrawLineButtonPushed;
                    end

                case 'c'
                    % Brush button (extend line)
                    if app.PintarButton.Visible == 'on'
                        app.PintarButtonPushed;
                    end

                case 'escape'
                    % Esc. button
                    app.ExitButtonPushed;
                    % close force
                    
                case 'space'
                    % Botó 'Done'
                    app.DoneButtonPushed(app);
            end
            

        end

        % Callback function
        function Auto_executeButtonPushed(app, event)
            % Button auto-execution (deleted - obsolete)
                        
        end

        % Value changed function: OriginalButton
        function OriginalButtonValueChanged(app, event)
            % Original image button
            app_interf_boto_original(app)
            
        end

        % Value changed function: MaxCircularityButton
        function MaxCircularityButtonValueChanged(app, event)
            % Value Changed circularity button
            app_interf_boto_mincircularity(app)

        end

        % Callback function
        function TextArea_2ValueChanged(app, event)
            % Deleted

        end

        % Value changed function: SpinnerRound
        function SpinnerRoundValueChanged(app, event)
            % Value Changed of spinner button of circularity change
            app_interf_boto_spinner_maxcircu(app)
            
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            % To right button
            app_interf_boto_dreta(app)

        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            % To left button
            app_interf_boto_esquerra(app)
                        
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Export length data button
            app_interf_button_export(app)
            
        end

        % Callback function
        function SpinnerWidthMeanValueChanged(app, event)
             % Deleted (spinner of width percentage)
            
        end

        % Callback function
        function MinWidthRefValueChanged(app, event)
            % Min width value changed (inactive)
            % value = app.MinWidthRef.Value;
                              
        end

        % Value changed function: MinLengthStateButton
        function MinLengthStateButtonValueChanged(app, event)
            % Min length button
            app_interf_boto_minlength(app)
            
        end

        % Button pushed function: CutButton
        function CutButtonPushed(app, event)
            % Scissors button
            app_interf_boto_tisores(app)

        end

        % Button pushed function: DrawLineButton
        function DrawLineButtonPushed(app, event)
            % Draw line button
            app_interf_boto_drawline(app)

        end

        % Value changed function: MinWidthStateButton
        function MinWidthStateButtonValueChanged(app, event)
            % Minimal area - value changed
            app_interf_boto_minwidth(app)

        end

        % Callback function
        function MinWidthRefTextAreaValueChanged(app, event)
            % deleted
            
        end

        % Value changed function: MinWidthTextArea
        function MinWidthTextAreaValueChanged(app, event)
            % Min area value changed
            %value = app.MinWidthTextArea.Value;
            app_interf_boto_textarea_minwidth(app)


        end

        % Value changed function: MinLengthTextArea
        function MinLengthTextAreaValueChanged(app, event)
            % Min length text changed            
            app_interf_boto_textarea_minlength(app)
                           
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            % Click in binary image
            app_interf_boto_clickimage_BW(app)

        end

        % Button pushed function: PintarButton
        function PintarButtonPushed(app, event)
            % Extend line button
            app_interf_boto_extend(app)               

        end

        % Button pushed function: LupaButton
        function LupaButtonPushed(app, event)
            % Loupe button
            app_interf_boto_lupa(app)

        end

        % Button pushed function: LupaReturnButton
        function LupaReturnButtonPushed(app, event)
            % Return loupe button
            app_interf_boto_lupa_return(app)            

        end

        % Value changed function: TutorialButton
        function TutorialButtonValueChanged(app, event)
            % Tutorial button
            app_interf_button_tutorial(app)

        end

        % Button pushed function: InfoIcon
        function InfoIconPushed(app, event)
            % Info button - Scisors information
            app_interf_button_info_scissors(app)

        end

        % Callback function
        function CloseVideoButtonPushed(app, event)
            % deleted    

        end

        % Button pushed function: InfoIcon_Draw
        function InfoIcon_DrawButtonPushed(app, event)
            % Info button - Scisors information
            app_interf_button_info_draw(app)
            
        end

        % Button pushed function: InfoIcon_Brush
        function InfoIcon_BrushButtonPushed(app, event)
            % Brush info button
            app_interf_button_info_brush(app)

        end

        % Button pushed function: ExportIAdataButton
        function ExportIAdataButtonPushed(app, event)
            % IA export data
            app_interf_boto_exportia(app)

        end

        % Button pushed function: InfoIcon_2
        function InfoIcon_2ButtonPushed(app, event)
            % Manual bias error info button
            app_interf_boto_info_correccio(app)

        end

        % Button pushed function: InfoIcon_3
        function InfoIcon_3ButtonPushed(app, event)
            % IA data info button
            app_interf_boto_info_exportIA(app)

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Exit button
            app_interf_boto_exit()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Length')
            zlabel(app.UIAxes, 'Z')
            app.Thick_Button = uibutton(app.UIFigure, 'push');
            app.Thick_Button.ButtonPushedFcn = createCallbackFcn(app, @Thick_ButtonPushed, true);
            app.Thick_Button.Icon = 'greentick.png';
            app.Thick_Button.Tooltip = {'Select the current object as worm'; ''; 'keyshort: (d)'};
            app.Thick_Button.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Cross_Button = uibutton(app.UIFigure, 'push');
            app.Cross_Button.ButtonPushedFcn = createCallbackFcn(app, @Cross_ButtonPushed, true);
            app.Cross_Button.Icon = 'cross.png';
            app.Cross_Button.Tooltip = {'Select the current object as a non-worm'; ''; 'keyshort: (a)'};
            app.Cross_Button.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.Visible = 'off';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.FiltersLabel = uilabel(app.UIFigure);
            app.FiltersLabel.Text = 'Filters';
            app.ModificacionsguardadesListBox = uilistbox(app.UIFigure);
            app.ModificacionsguardadesListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionsguardadesListBoxValueChanged, true);
            app.ModificacionsguardadesListBox.Enable = 'off';
            app.ModificacionstemporalsListBoxLabel = uilabel(app.UIFigure);
            app.ModificacionstemporalsListBoxLabel.Text = {'Modificacions'; 'temporals'};
            app.ModificacionstemporalsListBox = uilistbox(app.UIFigure);
            app.ModificacionstemporalsListBox.ValueChangedFcn = createCallbackFcn(app, @ModificacionstemporalsListBoxValueChanged, true);
            app.ModificacionstemporalsListBox.Enable = 'off';
            app.ModificacinsguardadesListBoxLabel = uilabel(app.UIFigure);
            app.ModificacinsguardadesListBoxLabel.Text = {'Modificacións'; 'guardades'};
            app.img_contLabel = uilabel(app.UIFigure);
            app.img_contLabel.Text = 'img_cont';
            app.img_tot_nLabel = uilabel(app.UIFigure);
            app.img_tot_nLabel.Text = 'img_tot_n';
            app.CitationLabel = uilabel(app.UIFigure);
            app.CitationLabel.Visible = 'off';
            app.CitationLabel.Text = 'Please, cite us in: ("WorMe: C.  elegans morphological caracterization tool user-designed", 2022, Nature, DOI: 123.456)';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Icon = 'return_icon.png';
            app.ReturnButton.Tooltip = {'Undo the last selection.'; ''; 'keyshort: (w)'};
            app.ReturnButton.Text = 'Undo';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://www.springer.com/journal/11540/';
            app.Hyperlink.Visible = 'off';
            app.img_original_nomLabel = uilabel(app.UIFigure);
            app.img_original_nomLabel.Text = 'img_original_nom';
            app.n_objecteLabel = uilabel(app.UIFigure);
            app.n_objecteLabel.Text = 'n_objecte';
            app.OriginalButton = uibutton(app.UIFigure, 'state');
            app.OriginalButton.ValueChangedFcn = createCallbackFcn(app, @OriginalButtonValueChanged, true);
            app.OriginalButton.Tooltip = {'keyshort: (s)'};
            app.OriginalButton.Text = 'Original';
            app.MaxCircularityButton = uibutton(app.UIFigure, 'state');
            app.MaxCircularityButton.ValueChangedFcn = createCallbackFcn(app, @MaxCircularityButtonValueChanged, true);
            app.MaxCircularityButton.Text = {'Max '; 'Circularity'};
            app.SpinnerRound = uispinner(app.UIFigure);
            app.SpinnerRound.Step = 0.1;
            app.SpinnerRound.ValueChangedFcn = createCallbackFcn(app, @SpinnerRoundValueChanged, true);
            app.SpinnerRound.Value = 1;
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Icon = 'Fletxa_apps_r.png';
            app.RightButton.Tooltip = {'Go to the forward image.'};
            app.RightButton.Text = '';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Icon = 'Fletxa_apps_l.png';
            app.LeftButton.Tooltip = {'Go to the previous image.'};
            app.LeftButton.Text = '';
            app.n_valLabel = uilabel(app.UIFigure);
            app.n_valLabel.Text = 'n_val';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n';
            app.meanLabel = uilabel(app.UIFigure);
            app.meanLabel.Text = 'mean';
            app.mean_valLabel = uilabel(app.UIFigure);
            app.mean_valLabel.Text = 'mean_val';
            app.stdLabel = uilabel(app.UIFigure);
            app.stdLabel.Text = 'std';
            app.std_valLabel = uilabel(app.UIFigure);
            app.std_valLabel.Text = 'std_val';
            app.minLabel = uilabel(app.UIFigure);
            app.minLabel.Text = 'min';
            app.min_valLabel = uilabel(app.UIFigure);
            app.min_valLabel.Text = 'min_val';
            app.maxLabel = uilabel(app.UIFigure);
            app.maxLabel.Text = 'max';
            app.max_valLabel = uilabel(app.UIFigure);
            app.max_valLabel.Text = 'max_val';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Icon = 'excel_icono.png';
            app.ExportButton.Text = 'Export';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'icon_out.png';
            app.ExitButton.Text = 'Exit';
            app.ObjectnLabel = uilabel(app.UIFigure);
            app.ObjectnLabel.Text = 'Object nº:';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image:';
            app.ofLabel = uilabel(app.UIFigure);
            app.ofLabel.Text = 'of';
            app.FolderLabel = uilabel(app.UIFigure);
            app.FolderLabel.Text = 'Folder:';
            app.dir_img_orig_sLabel = uilabel(app.UIFigure);
            app.dir_img_orig_sLabel.Text = 'dir_img_orig_s';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Visible = 'off';
            app.MinLengthStateButton = uibutton(app.UIFigure, 'state');
            app.MinLengthStateButton.ValueChangedFcn = createCallbackFcn(app, @MinLengthStateButtonValueChanged, true);
            app.MinLengthStateButton.Tooltip = {'Minimal length filter.'; 'Note: the lengh filter is aproximated to the real of the object.'};
            app.MinLengthStateButton.Text = 'Min length';
            app.MinWidthStateButton = uibutton(app.UIFigure, 'state');
            app.MinWidthStateButton.ValueChangedFcn = createCallbackFcn(app, @MinWidthStateButtonValueChanged, true);
            app.MinWidthStateButton.Text = 'Min area';
            app.CutButton = uibutton(app.UIFigure, 'push');
            app.CutButton.ButtonPushedFcn = createCallbackFcn(app, @CutButtonPushed, true);
            app.CutButton.Icon = 'scissors_icon.png';
            app.CutButton.Tooltip = {'Scisors tool:'; 'Tool for to cut two or more joined worms.'; ''; 'shortkey: (q)'};
            app.CutButton.Text = '';
            app.DrawLineButton = uibutton(app.UIFigure, 'push');
            app.DrawLineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawLineButtonPushed, true);
            app.DrawLineButton.Icon = 'click_image_bo_punts.png';
            app.DrawLineButton.Tooltip = {'Draw tool:'; 'Tool for to draw the line of the worm, in order to obtain the length of it. '; 'A new object will be created, which will be automatically accepted, and may be deleted.'; ''; 'shortkey: (e)'};
            app.DrawLineButton.Text = '';
            app.ToolsLabel = uilabel(app.UIFigure);
            app.ToolsLabel.Text = 'Tools';
            app.SelectionLabel = uilabel(app.UIFigure);
            app.SelectionLabel.Text = 'Selection';
            app.MinWidthTextArea = uieditfield(app.UIFigure, 'numeric');
            app.MinWidthTextArea.Limits = [0 Inf];
            app.MinWidthTextArea.ValueChangedFcn = createCallbackFcn(app, @MinWidthTextAreaValueChanged, true);
            app.MinLengthTextArea = uieditfield(app.UIFigure, 'numeric');
            app.MinLengthTextArea.Limits = [0 Inf];
            app.MinLengthTextArea.ValueChangedFcn = createCallbackFcn(app, @MinLengthTextAreaValueChanged, true);
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Visible = 'off';
            app.WLabel.Text = 'W';
            app.LastmodifLabel = uilabel(app.UIFigure);
            app.LastmodifLabel.Text = 'Last modif';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.PintarButton = uibutton(app.UIFigure, 'push');
            app.PintarButton.ButtonPushedFcn = createCallbackFcn(app, @PintarButtonPushed, true);
            app.PintarButton.Icon = 'Pintar_icon_3.png';
            app.PintarButton.Tooltip = {'Elongate tool:'; 'Tool for to elongate the selection of a worm. Useful for to elongate the tail or head of it, or for to improve the image object.'; ''; 'shortkey: (c)'; ''};
            app.PintarButton.Text = '';
            app.LupaButton = uibutton(app.UIFigure, 'push');
            app.LupaButton.ButtonPushedFcn = createCallbackFcn(app, @LupaButtonPushed, true);
            app.LupaButton.Icon = 'loupe.png';
            app.LupaButton.Text = '';
            app.LupaReturnButton = uibutton(app.UIFigure, 'push');
            app.LupaReturnButton.ButtonPushedFcn = createCallbackFcn(app, @LupaReturnButtonPushed, true);
            app.LupaReturnButton.Icon = 'loupe_feltcha_bo.png';
            app.LupaReturnButton.Visible = 'off';
            app.LupaReturnButton.Text = '';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = 'Finished_WM.png';
            app.NotFinishedImagesLabel = uilabel(app.UIFigure);
            app.NotFinishedImagesLabel.Visible = 'off';
            app.NotFinishedImagesLabel.Text = 'You have not already finished the selection of all the selected images.';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Visible = 'off';
            app.SLabel.Text = 'S';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Visible = 'off';
            app.RLabel.Text = 'R';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Visible = 'off';
            app.FLabel.Text = 'F';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Visible = 'off';
            app.CLabel.Text = 'C';
            app.TutorialButton = uibutton(app.UIFigure, 'state');
            app.TutorialButton.ValueChangedFcn = createCallbackFcn(app, @TutorialButtonValueChanged, true);
            app.TutorialButton.Text = 'Tutorial';
            app.QLabel = uilabel(app.UIFigure);
            app.QLabel.Visible = 'off';
            app.QLabel.Text = 'Q';
            app.InfoIcon = uibutton(app.UIFigure, 'push');
            app.InfoIcon.ButtonPushedFcn = createCallbackFcn(app, @InfoIconPushed, true);
            app.InfoIcon.Icon = 'info_icon.png';
            app.InfoIcon.Visible = 'off';
            app.InfoIcon.Text = '';
            app.InfoIcon_Draw = uibutton(app.UIFigure, 'push');
            app.InfoIcon_Draw.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_DrawButtonPushed, true);
            app.InfoIcon_Draw.Icon = 'info_icon.png';
            app.InfoIcon_Draw.Visible = 'off';
            app.InfoIcon_Draw.Text = '';
            app.InfoIcon_Brush = uibutton(app.UIFigure, 'push');
            app.InfoIcon_Brush.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_BrushButtonPushed, true);
            app.InfoIcon_Brush.Icon = 'info_icon.png';
            app.InfoIcon_Brush.Visible = 'off';
            app.InfoIcon_Brush.Text = '';
            app.ExportIAdataButton = uibutton(app.UIFigure, 'push');
            app.ExportIAdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportIAdataButtonPushed, true);
            app.ExportIAdataButton.Icon = 'BW_celegans_3.png';
            app.ExportIAdataButton.Tooltip = {'Exports the binary and label images, and also the Pascal VOC files of the selection.'};
            app.ExportIAdataButton.Text = {'Export'; 'graphic'; 'data'};
            app.ImageLabel_2 = uilabel(app.UIFigure);
            app.ImageLabel_2.Text = 'Image:';
            app.ExportnoncorrectedmanualerrordataCheckBox = uicheckbox(app.UIFigure);
            app.ExportnoncorrectedmanualerrordataCheckBox.Text = 'Export manual error corrected data';
            app.InfoIcon_2 = uibutton(app.UIFigure, 'push');
            app.InfoIcon_2.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_2ButtonPushed, true);
            app.InfoIcon_2.Icon = 'info_icon.png';
            app.InfoIcon_2.Text = '';
            app.InfoIcon_3 = uibutton(app.UIFigure, 'push');
            app.InfoIcon_3.ButtonPushedFcn = createCallbackFcn(app, @InfoIcon_3ButtonPushed, true);
            app.InfoIcon_3.Icon = 'info_icon.png';
            app.InfoIcon_3.Text = '';
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Visible = 'off';
            app.ELabel.Text = 'E';
            app.spaceLabel = uilabel(app.UIFigure);
            app.spaceLabel.Visible = 'off';
            app.spaceLabel.Text = 'space';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = 'reloj_arena.jpg';
            app.n_yesLabel = uilabel(app.UIFigure);
            app.n_yesLabel.Text = '0';
            app.n_noLabel = uilabel(app.UIFigure);
            app.n_noLabel.Text = '0';
            app.acceptedLabel = uilabel(app.UIFigure);
            app.acceptedLabel.Text = 'accepted';
            app.refusedLabel = uilabel(app.UIFigure);
            app.refusedLabel.Text = 'refused';
            app.n_yesLabel2 = uilabel(app.UIFigure);
            app.n_yesLabel2.Text = '0';
            app.n_noLabel2 = uilabel(app.UIFigure);
            app.n_noLabel2.Text = '0';
            app.Thick_Button_little = uiimage(app.UIFigure);
            app.Thick_Button_little.ImageSource = 'greentick.png';
            app.Cross_Button_little = uiimage(app.UIFigure);
            app.Cross_Button_little.ImageSource = 'cross.png';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Visible = 'off';
            app.ALabel.Text = 'A';
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Visible = 'off';
            app.DLabel.Text = 'D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_selection_pannel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end