classdef box_manual_correction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image4                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        HereisthedescriptionLabel   matlab.ui.control.Label
        CorrectionexplanationLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Centrem la GUI
            % ..............
            UIFigure_position = app.UIFigure.Position; %100   100   602   389
                                                      % inici final amplada  llargada
            screensize_position = get(0, 'ScreenSize'); %         1           1        1920        1080
            [UIFigure_position_new] = centered_GUI_screen(UIFigure_position, screensize_position);
            app.UIFigure.Position = UIFigure_position_new;
            
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Correction box info';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'All_joined_horiz_bo.png';
            app.CorrectionexplanationLabel = uilabel(app.UIFigure);
            app.CorrectionexplanationLabel.Text = 'Correction explanation';
            app.HereisthedescriptionLabel = uilabel(app.UIFigure);
            app.HereisthedescriptionLabel.Text = {'The distance obtained from pixel-to-pixel lines are not the same than these obtained from few drawed points lines, which is obtained usually in manual selections. There is difference because the lack of curve singular distances between pixels.'; ''; 'For this reason, the program offers the correction of the pixel-to-pixel data in order to adapt it to the manual-like data. This is made just obtaining the distance of every 5 pixels and not for every pixel-to-pixel, allowing the comparison between manual and automated data because the error is mainly corrected.'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'icon_48.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Comparison.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'Slide3_modif.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = box_manual_correction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end