classdef box_IA_explanation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GraphicdataextractionLabel  matlab.ui.control.Label
        BinaryimagecanbeusedforexportintodiferentLabel_3  matlab.ui.control.Label
        BinaryimagecanbeusedforexportintodiferentLabel_2  matlab.ui.control.Label
        BinaryimagecanbeusedforexportintodiferentLabel  matlab.ui.control.Label
        PascalVOCfileLabel          matlab.ui.control.Label
        IndexedimageLabel           matlab.ui.control.Label
        BinaryimageLabel            matlab.ui.control.Label
        Image4                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        HereisthedescriptionLabel   matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
                        % Centrem la GUI
            % ..............
            UIFigure_position = app.UIFigure.Position; %100   100   602   389
                                                      % inici final amplada  llargada
            screensize_position = get(0, 'ScreenSize'); %         1           1        1920        1080
            [UIFigure_position_new] = centered_GUI_screen(UIFigure_position, screensize_position);
            app.UIFigure.Position = UIFigure_position_new;
            
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Graphical data information';
            app.UIFigure.Icon = 'icon_48.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Image_967_BW.png';
            app.HereisthedescriptionLabel = uilabel(app.UIFigure);
            app.HereisthedescriptionLabel.Text = {'Exports the graphical data of the selected and not selected objects.'; 'This data can be useful for third uses for exmaple the regions of interest of the images, or for the Deep Learning model development.'; 'The formats of the images anotations are: binary images, labeled images, and Pascal VOC annotations.'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'icon_48.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Image_967.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'pascal_VOC.png';
            app.BinaryimageLabel = uilabel(app.UIFigure);
            app.BinaryimageLabel.Text = 'Binary image';
            app.IndexedimageLabel = uilabel(app.UIFigure);
            app.IndexedimageLabel.Text = 'Indexed image';
            app.PascalVOCfileLabel = uilabel(app.UIFigure);
            app.PascalVOCfileLabel.Text = 'Pascal VOC file';
            app.BinaryimagecanbeusedforexportintodiferentLabel = uilabel(app.UIFigure);
            app.BinaryimagecanbeusedforexportintodiferentLabel.Text = {'In binary images the objects have a value of 1 and non objects a value of 0.'; 'Binary image can be used for extract the properties of the defined regions of interest (ROI).'};
            app.BinaryimagecanbeusedforexportintodiferentLabel_2 = uilabel(app.UIFigure);
            app.BinaryimagecanbeusedforexportintodiferentLabel_2.Text = {'In an indexed image, every element have all a unique value or a diferent value between them.'; 'Indexed images are useful in the development of semantic segmentation models on deep learning.'};
            app.BinaryimagecanbeusedforexportintodiferentLabel_3 = uilabel(app.UIFigure);
            app.BinaryimagecanbeusedforexportintodiferentLabel_3.Text = {'Pascal VOC is the xml notation which indicate the x,y coordinates of the box of an object'; 'It is useful for the development of object identification models.'};
            app.GraphicdataextractionLabel = uilabel(app.UIFigure);
            app.GraphicdataextractionLabel.Text = 'Graphic data extraction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = box_IA_explanation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end